classdef A_FaultGeom_Apr2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Fault_ListBox                   matlab.ui.control.ListBox
        AddFault_Button                 matlab.ui.control.Button
        DeleteFault_Button              matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        FaultName_EditField             matlab.ui.control.EditField
        EastingtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultEasting_EditField          matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultNorthing_EditField         matlab.ui.control.NumericEditField
        DepthtopcenterkmEditFieldLabel  matlab.ui.control.Label
        FaultDepth_EditField            matlab.ui.control.NumericEditField
        LengthkmEditFieldLabel          matlab.ui.control.Label
        FaultLength_EditField           matlab.ui.control.NumericEditField
        WidthkmEditFieldLabel           matlab.ui.control.Label
        FaultWidth_EditField            matlab.ui.control.NumericEditField
        StrikedegEditFieldLabel         matlab.ui.control.Label
        FaultStrike_EditField           matlab.ui.control.NumericEditField
        DipdegEditFieldLabel            matlab.ui.control.Label
        FaultDip_EditField              matlab.ui.control.NumericEditField
        FaultIDEditFieldLabel           matlab.ui.control.Label
        FaultID_EditField               matlab.ui.control.NumericEditField
        slipratemmyrEditFieldLabel      matlab.ui.control.Label
        FaultSlipRate_EditField         matlab.ui.control.NumericEditField
        sliprakedegEditFieldLabel       matlab.ui.control.Label
        FaultSlipRake_EditField         matlab.ui.control.NumericEditField
        stressingrateMPayrEditFieldLabel  matlab.ui.control.Label
        FaultStressRate_EditField       matlab.ui.control.NumericEditField
        shiftvertexDropDownLabel        matlab.ui.control.Label
        FaultVertexPos_DropDown         matlab.ui.control.DropDown
        instrikeEditFieldLabel          matlab.ui.control.Label
        FaultVertexPos_Strike_EditField  matlab.ui.control.NumericEditField
        indipEditFieldLabel             matlab.ui.control.Label
        FaultVertexPos_Dip_EditField    matlab.ui.control.NumericEditField
        outofplaneEditFieldLabel        matlab.ui.control.Label
        FaultVertexPos_Norm_EditField   matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        FigNumFaultModel_EditField      matlab.ui.control.NumericEditField
        PlotFaultSetup_Button           matlab.ui.control.Button
        ViewangleEditFieldLabel         matlab.ui.control.Label
        ViewAngle_EditField             matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        Bound_ListBox                   matlab.ui.control.ListBox
        NameEditField_2Label            matlab.ui.control.Label
        BoundName_EditField             matlab.ui.control.EditField
        EastingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundEasting_EditField          matlab.ui.control.NumericEditField
        NorthingtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundNorthing_EditField         matlab.ui.control.NumericEditField
        DepthtopcenterkmEditField_2Label  matlab.ui.control.Label
        BoundDepth_EditField            matlab.ui.control.NumericEditField
        LengthkmEditField_2Label        matlab.ui.control.Label
        BoundLength_EditField           matlab.ui.control.NumericEditField
        WidthkmEditField_2Label         matlab.ui.control.Label
        BoundWidth_EditField            matlab.ui.control.NumericEditField
        StrikedegEditField_2Label       matlab.ui.control.Label
        BoundStrike_EditField           matlab.ui.control.NumericEditField
        DipdegEditField_2Label          matlab.ui.control.Label
        BoundDip_EditField              matlab.ui.control.NumericEditField
        DeleteBound_Button              matlab.ui.control.Button
        AddBound_Button                 matlab.ui.control.Button
        LoadPara_Button                 matlab.ui.control.Button
        PlotPara_Button                 matlab.ui.control.Button
        B2RCheckBox                     matlab.ui.control.CheckBox
        SlipCheckBox                    matlab.ui.control.CheckBox
        PreCatalogParameter2Plot_DropDown  matlab.ui.control.DropDown
        loadingprerundatafirstloadfaultmodelLabel  matlab.ui.control.Label
        ActualtrigListBoxLabel          matlab.ui.control.Label
        FaultActualTrig_ListBox         matlab.ui.control.ListBox
        Triangulate_Button              matlab.ui.control.Button
        PlotMesh_Button                 matlab.ui.control.Button
        FigEditField_2Label             matlab.ui.control.Label
        FigNumFaultMesh_EditField       matlab.ui.control.NumericEditField
        ShowTrigStats_CheckBox          matlab.ui.control.CheckBox
        SaveMesh_Button                 matlab.ui.control.Button
        LoadMeshAndModel_Button         matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        Move_KeepCenterFixed_CheckBox   matlab.ui.control.CheckBox
        ShifVertex_tButton              matlab.ui.control.Button
        ofDropDown_2Label               matlab.ui.control.Label
        Move2_2ndFault_DropDown         matlab.ui.control.DropDown
        MoveLabel                       matlab.ui.control.Label
        Move2_2ndCorner_DropDown        matlab.ui.control.DropDown
        toLabel                         matlab.ui.control.Label
        Move2_1stCorner_DropDown        matlab.ui.control.DropDown
        ofDropDownLabel                 matlab.ui.control.Label
        Move2_1stFault_DropDown         matlab.ui.control.DropDown
        ShiftingthisoneLabel            matlab.ui.control.Label
        IntendedtrigfaultsEditFieldLabel  matlab.ui.control.Label
        IntendedTrigFaults_EditField    matlab.ui.control.EditField
        IntendedtrigboundLabel          matlab.ui.control.Label
        IntendedTrigBound_EditField     matlab.ui.control.NumericEditField
        ActualtrigboundLabel            matlab.ui.control.Label
        ActualTrigBound_EditField       matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        FaultNames      = {'Fault_1'};
        %              is Ecentpos, Ncentpos, Zcentpos, length, width, strike, dip; will be extended by offset of vertices out of fault plane (3 values for eachof 
        FaultValues     = [  0,         0,        0,      150,     18,     0,    90, zeros(1,12), 1, 10, 0, 0]; %the last three are ID, slip-rate, rake
        
        BoundaryNames   = {'Boundary_Bottom'};
        BoundaryValues  = [ -100,         0,      -20,    400,    200,     0,  0.01];
        FaultWasGridded = 0;
        GriddedFault    = struct;
        GriddedBound    = struct;
        ExtraParamter   = []; % Description
        ExtraParamter2  = [];
  
        BlueWhiteRed =  [ 0	0	0.5000;     0	0	0.5313;     0	0	0.5625;     0	0	0.5938;         0	0	0.6250;     0	0	0.6563;     0	0	0.6875;     0	0	0.7188;...
                          0	0	0.7500;     0	0	0.7813;     0	0	0.8125;     0	0	0.8438;         0	0	0.8750;     0	0	0.9063;     0	0	0.9375;     0	0	0.9688;...
                          0	0	1;          0.02580	0.02580	1;  0.05160	0.05160	1;  0.07740	0.07740	1;      0.1032	0.1032	1;  0.1290	0.1290	1;  0.1548	0.1548	1;  0.1806	0.1806	1;...
                          0.2065	0.2065	1;  0.2323	0.2323	1;  0.2581	0.2581	1;  0.2839	0.2839	1;  0.3097	0.3097	1;  0.3355	0.3355	1;  0.3613	0.3613	1;  0.3871	0.3871	1;...
                          0.4129	0.4129	1;  0.4387	0.4387	1;  0.4645	0.4645	1;  0.4903	0.4903	1;  0.5161	0.5161	1;  0.5419	0.5419	1;  0.5677	0.5677	1;  0.5935	0.5935	1;...
                          0.6194	0.6194	1;  0.6452	0.6452	1;  0.6710	0.6710	1;  0.6968	0.6968	1;  0.7226	0.7226	1;  0.7484	0.7484	1;  0.7742	0.7742	1;  0.8000	0.8000	1;...
                          1	0.8000	0.8000; 1	0.7742	0.7742; 1	0.7484	0.7484; 1	0.7226	0.7226;     1	0.6968	0.6968; 1	0.6710	0.6710; 1	0.6452	0.6452; 1	0.6194	0.6194;...
                          1	0.5935	0.5935; 1	0.5677	0.5677; 1	0.5419	0.5419; 1	0.5161	0.5161;     1	0.4903	0.4903; 1	0.4645	0.4645; 1	0.4387	0.4387; 1	0.4129	0.4129;...
                          1	0.3871	0.3871; 1	0.3613	0.3613; 1	0.3355	0.3355; 1	0.3097	0.3097;     1	0.2839	0.2839; 1	0.2581	0.2581; 1	0.2323	0.2323; 1	0.2065	0.2065;...
                          1	0.1806	0.1806; 1	0.1548	0.1548; 1	0.1290	0.1290; 1	0.1032	0.1032;     1	0.07740	0.0774; 1	0.05160	0.05160;1	0.02580	0.02580;    1	0	0;...
                          0.9688	0	0;      0.9375	0	0;      0.9063	0	0;      0.8750	0	0;      0.8438	0	0;      0.8125	0	0;      0.7813	0	0;      0.7500	0	0;...
                          0.7188	0	0;      0.6875	0	0;      0.6563	0	0;      0.6250	0	0;      0.5938	0	0;      0.5625	0	0;      0.5313	0	0;      0.5000	0	0];
           
       
        Cols4Slip    = [1.0000    1.0000    1.0000;  0.8890    0.9670    1.0000; 0.7780    0.9330    1.0000; 0.6670    0.9000    1.0000;  0.5560    0.8670    1.0000; 0.4440    0.8330    1.0000;...
                        0.3330    0.8000    1.0000;  0.2220    0.7670    1.0000; 0.1110    0.7330    1.0000
                        0         0.7000    1.0000;  0         0.7110    0.8890; 0         0.7220    0.7780; 0         0.7330    0.6670;  0         0.7440    0.5560; 0         0.7560    0.4440;...
                        0         0.7670    0.3330;  0         0.7780    0.2220; 0         0.7890    0.1110; 0         0.8000         0;  0.0560    0.8220         0; 0.1110    0.8440    0;...
                        0.1670    0.8670         0;  0.2220    0.8890         0; 0.2780    0.9110         0; 0.3330    0.9330         0;  0.3890    0.9560         0; 0.4440    0.9780    0;...
                        0.5000    1.0000         0;  0.5560    1.0000         0; 0.6110    1.0000         0; 0.6670    1.0000         0;  0.7220    1.0000         0; 0.7780    1.0000    0;...
                        0.8330    1.0000         0;  0.8890    1.0000         0; 0.9440    1.0000         0; 1.0000    1.0000         0;  1.0000    0.9440         0; 1.0000    0.8890    0;...
                        1.0000    0.8330         0;  1.0000    0.7780         0; 1.0000    0.7220         0; 1.0000    0.6670         0;  1.0000    0.6110         0; 1.0000    0.5560    0;...
                        1.0000    0.5000         0;  1.0000    0.4440         0; 1.0000    0.3890         0; 1.0000    0.3330         0;  1.0000    0.2780         0; 1.0000    0.2220    0;...
                        1.0000    0.1670         0;  1.0000    0.1110         0; 1.0000    0.0560         0; 1.0000         0         0;  0.9440         0         0; 0.8890         0    0;...
                        0.8330         0         0;  0.7780         0         0; 0.7220         0         0; 0.6670         0         0;  0.6110         0         0; 0.5560         0    0;...
                        0.5000         0         0];   
    end
    
    methods (Access = private)
        
        function ShiftedVerts = ShiftVertices(app, VertexList, PolygonVerts)
            %I'm skipping the actual "derivation" of the equation used below -only
            %takes 30min and piece of paper plus the website mentioned above...
            %bring die beschreibung der ebene von normalenform in koordinaten form
            %http://www.mathematik-oberstufe.de/vektoren/a/abstand-punkt-ebene-lot.html
            %only need to get the dot product of normal and "start punkt"
            
            VertexNum    = size(VertexList,1);
            ShiftedVerts = nan(VertexNum,1);
            %----------------------------------
            Trig1_x   = [PolygonVerts(1,1), PolygonVerts(2,1), PolygonVerts(3,1),PolygonVerts(1,1)];
            Trig1_y   = [PolygonVerts(1,2), PolygonVerts(2,2), PolygonVerts(3,2),PolygonVerts(1,2)];
            Trig1_z   = [PolygonVerts(1,3), PolygonVerts(2,3), PolygonVerts(3,3),PolygonVerts(1,3)];
            Vect1A    = [(Trig1_x(1,2)-Trig1_x(1,1)); (Trig1_y(1,2)-Trig1_y(1,1)); (Trig1_z(1,2)-Trig1_z(1,1))];    Vect1A= Vect1A./norm(Vect1A); 
            Vect1B    = [(Trig1_x(1,3)-Trig1_x(1,1)); (Trig1_y(1,3)-Trig1_y(1,1)); (Trig1_z(1,3)-Trig1_z(1,1))];    Vect1B= Vect1B./norm(Vect1B);
            Trig1Norm = cross(Vect1A,Vect1B);
            Trig1Dot  = dot(Trig1Norm,[Trig1_x(1,1);Trig1_y(1,1);Trig1_z(1,1)]);
            %----------------------------------
            Trig2_x   = [PolygonVerts(2,1), PolygonVerts(3,1), PolygonVerts(4,1),PolygonVerts(2,1)];
            Trig2_y   = [PolygonVerts(2,2), PolygonVerts(3,2), PolygonVerts(4,2),PolygonVerts(2,2)];
            Trig2_z   = [PolygonVerts(2,3), PolygonVerts(3,3), PolygonVerts(4,3),PolygonVerts(2,3)];
            Vect2A    = [(Trig2_x(1,2)-Trig2_x(1,1)); (Trig2_y(1,2)-Trig2_y(1,1)); (Trig2_z(1,2)-Trig2_z(1,1))];    Vect2A= Vect2A./norm(Vect2A); 
            Vect2B    = [(Trig2_x(1,3)-Trig2_x(1,1)); (Trig2_y(1,3)-Trig2_y(1,1)); (Trig2_z(1,3)-Trig2_z(1,1))];    Vect2B= Vect2B./norm(Vect2B);
            Trig2Norm = cross(Vect2A,Vect2B);
            Trig2Dot  = dot(Trig2Norm,[Trig2_x(1,1);Trig2_y(1,1);Trig2_z(1,1)]);
            %----------------------------------
            Trig3_x   = [PolygonVerts(3,1), PolygonVerts(4,1), PolygonVerts(1,1),PolygonVerts(3,1)];
            Trig3_y   = [PolygonVerts(3,2), PolygonVerts(4,2), PolygonVerts(1,2),PolygonVerts(3,2)];
            Trig3_z   = [PolygonVerts(3,3), PolygonVerts(4,3), PolygonVerts(1,3),PolygonVerts(3,3)];
            Vect3A    = [(Trig3_x(1,2)-Trig3_x(1,1)); (Trig3_y(1,2)-Trig3_y(1,1)); (Trig3_z(1,2)-Trig3_z(1,1))];    Vect3A= Vect3A./norm(Vect3A); 
            Vect3B    = [(Trig3_x(1,3)-Trig3_x(1,1)); (Trig3_y(1,3)-Trig3_y(1,1)); (Trig3_z(1,3)-Trig3_z(1,1))];    Vect3B= Vect3B./norm(Vect3B);
            Trig3Norm = cross(Vect3A,Vect3B);
            Trig3Dot  = dot(Trig3Norm,[Trig3_x(1,1);Trig3_y(1,1);Trig3_z(1,1)]);
            %----------------------------------
            Trig4_x   = [PolygonVerts(4,1), PolygonVerts(1,1), PolygonVerts(2,1),PolygonVerts(4,1)];
            Trig4_y   = [PolygonVerts(4,2), PolygonVerts(1,2), PolygonVerts(2,2),PolygonVerts(4,2)];
            Trig4_z   = [PolygonVerts(4,3), PolygonVerts(1,3), PolygonVerts(2,3),PolygonVerts(4,3)];
            Vect4A    = [(Trig4_x(1,2)-Trig4_x(1,1)); (Trig4_y(1,2)-Trig4_y(1,1)); (Trig4_z(1,2)-Trig4_z(1,1))];    Vect4A= Vect4A./norm(Vect4A); 
            Vect4B    = [(Trig4_x(1,3)-Trig4_x(1,1)); (Trig4_y(1,3)-Trig4_y(1,1)); (Trig4_z(1,3)-Trig4_z(1,1))];    Vect4B= Vect4B./norm(Vect4B);
            Trig4Norm = cross(Vect4A,Vect4B);
            Trig4Dot  = dot(Trig4Norm,[Trig4_x(1,1);Trig4_y(1,1);Trig4_z(1,1)]);
            %-----------------------------------
            IN_1      = inpolygon(VertexList(:,1),VertexList(:,2),Trig1_x,Trig1_y);
            IN_2      = inpolygon(VertexList(:,1),VertexList(:,2),Trig2_x,Trig2_y);
            IN_3      = inpolygon(VertexList(:,1),VertexList(:,2),Trig3_x,Trig3_y);
            IN_4      = inpolygon(VertexList(:,1),VertexList(:,2),Trig4_x,Trig4_y);
            IN_Matrix = [IN_1, IN_2, IN_3, IN_4];
            
            %-----------------------------------
            for i = 1 : VertexNum
                NextLocation   = [VertexList(i,1), VertexList(i,2), 0];
                LocationHeight = 0;
            
                if IN_Matrix(i,1) == 1
                    NextHeight     = (Trig1Dot -Trig1Norm(1,1)*NextLocation(1,1) -Trig1Norm(2,1)*NextLocation(1,2))/Trig1Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,2) == 1
                    NextHeight     = (Trig2Dot -Trig2Norm(1,1)*NextLocation(1,1) -Trig2Norm(2,1)*NextLocation(1,2))/Trig2Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,3) == 1
                    NextHeight     = (Trig3Dot -Trig3Norm(1,1)*NextLocation(1,1) -Trig3Norm(2,1)*NextLocation(1,2))/Trig3Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                if IN_Matrix(i,4) == 1
                    NextHeight     = (Trig4Dot -Trig4Norm(1,1)*NextLocation(1,1) -Trig4Norm(2,1)*NextLocation(1,2))/Trig4Norm(3,1);
                    LocationHeight = LocationHeight +NextHeight;
                end
                %-----------------------------------
            
                if (sum(IN_Matrix(i,:)) == 0)
                    dist_1 = Point2Line(app,NextLocation(1,1:2), [Trig1_x(1,1), Trig1_y(1,1)], [Trig1_x(1,2), Trig1_y(1,2)]);
                    dist_2 = Point2Line(app,NextLocation(1,1:2), [Trig2_x(1,1), Trig2_y(1,1)], [Trig2_x(1,2), Trig2_y(1,2)]);
                    dist_3 = Point2Line(app,NextLocation(1,1:2), [Trig3_x(1,1), Trig3_y(1,1)], [Trig3_x(1,2), Trig3_y(1,2)]);   
                    dist_4 = Point2Line(app,NextLocation(1,1:2), [Trig4_x(1,1), Trig4_y(1,1)], [Trig4_x(1,2), Trig4_y(1,2)]);
                   
                    dist_1 = [dist_1, Trig1_x(1,1), Trig1_x(1,2), Trig1_y(1,1), Trig1_y(1,2), Trig1_z(1,1), Trig1_z(1,2)];
                    dist_2 = [dist_2, Trig2_x(1,1), Trig2_x(1,2), Trig2_y(1,1), Trig2_y(1,2), Trig2_z(1,1), Trig2_z(1,2)];
                    dist_3 = [dist_3, Trig3_x(1,1), Trig3_x(1,2), Trig3_y(1,1), Trig3_y(1,2), Trig3_z(1,1), Trig3_z(1,2)];
                    dist_4 = [dist_4, Trig4_x(1,1), Trig4_x(1,2), Trig4_y(1,1), Trig4_y(1,2), Trig4_z(1,1), Trig4_z(1,2)];
                     
                    Dists  = [dist_1;dist_2;dist_3;dist_4];
                    MinDisP= find(Dists(:,1) == min(Dists(:,1)));
                    MinDisP= MinDisP(1,1);
                    
                    dist_a = sqrt((Dists(MinDisP,2)-NextLocation(1,1)).^2 +(Dists(MinDisP,4)-NextLocation(1,2)).^2);
                    dist_b = sqrt((Dists(MinDisP,2)-Dists(MinDisP,3)).^2  +(Dists(MinDisP,4)-Dists(MinDisP,5)).^2);
                    ratio  = dist_a/dist_b;
                   ShiftedVerts(i,1) = Dists(MinDisP,6) +ratio* (Dists(MinDisP,7)-Dists(MinDisP,6)); 
                else
                    ShiftedVerts(i,1) = LocationHeight./sum(IN_Matrix(i,:));
                end
            end
            clear Trig1_x Trig1_y Trig1_z Vect1A Vect1B IN_1;
            clear Trig2_x Trig2_y Trig2_z Vect2A Vect2B IN_2;
            clear Trig3_x Trig3_y Trig3_z Vect3A Vect3B IN_3;
            clear Trig4_x Trig4_y Trig4_z Vect4A Vect4B IN_4;
            
        end
        
        function [HistFaultArea_N,HistFaultArea_X,HistBoundArea_N,HistBoundArea_X,HistFaultLgth_N,HistFaultLgth_X,HistBoundLgth_N,HistBoundLgth_X] = ComputeTrigStats(app)
            %-----------------------------------------
            HistFaultArea_N = 0;                        HistFaultArea_X = 0;
            HistBoundArea_N = 0;                        HistBoundArea_X = 0;
            HistFaultLgth_N = 0;                        HistFaultLgth_X = 0;
            HistBoundLgth_N = 0;                        HistBoundLgth_X = 0;
            %-----------------------------------------
            if (app.FaultWasGridded == 0),              return;                                 end
            
            SelGridFault = app.FaultActualTrig_ListBox.Value;
            if (~isa(SelGridFault,'double')),     SelGridFault = str2double(SelGridFault);          end  
            %-----------------------------------------
            LegLengthsFault = zeros(size(app.GriddedFault(SelGridFault).TriangleList,1),3);
            PatchAreasFault = zeros(size(app.GriddedFault(SelGridFault).TriangleList,1),1);
            %-----------------------------------------
            for i = 1 : size(app.GriddedFault(SelGridFault).TriangleList,1)
                v1    = app.GriddedFault(SelGridFault).TriangleList(i,1);
                v2    = app.GriddedFault(SelGridFault).TriangleList(i,2);
                v3    = app.GriddedFault(SelGridFault).TriangleList(i,3);
        
                a     = sqrt((app.GriddedFault(SelGridFault).VertexList(v1,1)-app.GriddedFault(SelGridFault).VertexList(v2,1))^2 + (app.GriddedFault(SelGridFault).VertexList(v1,2)-app.GriddedFault(SelGridFault).VertexList(v2,2))^2);
                b     = sqrt((app.GriddedFault(SelGridFault).VertexList(v2,1)-app.GriddedFault(SelGridFault).VertexList(v3,1))^2 + (app.GriddedFault(SelGridFault).VertexList(v2,2)-app.GriddedFault(SelGridFault).VertexList(v3,2))^2);
                c     = sqrt((app.GriddedFault(SelGridFault).VertexList(v3,1)-app.GriddedFault(SelGridFault).VertexList(v1,1))^2 + (app.GriddedFault(SelGridFault).VertexList(v3,2)-app.GriddedFault(SelGridFault).VertexList(v1,2))^2);
                %---------------------------
                LegLengthsFault(i,1:3) = [a,  b,  c];  
                PatchAreasFault(i,1)   = polyarea([app.GriddedFault(SelGridFault).VertexList(v1,1), app.GriddedFault(SelGridFault).VertexList(v2,1), app.GriddedFault(SelGridFault).VertexList(v3,1)],[app.GriddedFault(SelGridFault).VertexList(v1,2), app.GriddedFault(SelGridFault).VertexList(v2,2), app.GriddedFault(SelGridFault).VertexList(v3,2)]);
                %---------------------------
            end
            %-----------------------------------------%-----------------------------------------
            [HistFaultArea_N,HistFaultArea_X] = histcounts(PatchAreasFault(:),50);
            [HistFaultLgth_N,HistFaultLgth_X] = histcounts(LegLengthsFault(:),50);
            %-----------------------------------------%-----------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                LegLengthsBound = zeros(size(app.GriddedBound.TriangleList,1),3);
                PatchAreasBound = zeros(size(app.GriddedBound.TriangleList,1),1);
                %-----------------------------------------
                for i = 1 : size(app.GriddedBound.TriangleList,1)
                    v1    = app.GriddedBound.TriangleList(i,1);
                    v2    = app.GriddedBound.TriangleList(i,2);
                    v3    = app.GriddedBound.TriangleList(i,3);
            
                    a     = sqrt((app.GriddedBound.VertexList(v1,1)-app.GriddedBound.VertexList(v2,1))^2 + (app.GriddedBound.VertexList(v1,2)-app.GriddedBound.VertexList(v2,2))^2);
                    b     = sqrt((app.GriddedBound.VertexList(v2,1)-app.GriddedBound.VertexList(v3,1))^2 + (app.GriddedBound.VertexList(v2,2)-app.GriddedBound.VertexList(v3,2))^2);
                    c     = sqrt((app.GriddedBound.VertexList(v3,1)-app.GriddedBound.VertexList(v1,1))^2 + (app.GriddedBound.VertexList(v3,2)-app.GriddedBound.VertexList(v1,2))^2);
                    %---------------------------
                    LegLengthsBound(i,1:3) = [a,  b,  c];  
                    PatchAreasBound(i,1)   = polyarea([app.GriddedBound.VertexList(v1,1), app.GriddedBound.VertexList(v2,1), app.GriddedBound.VertexList(v3,1)],[app.GriddedBound.VertexList(v1,2), app.GriddedBound.VertexList(v2,2), app.GriddedBound.VertexList(v3,2)]);
                    %---------------------------
                end
                %-----------------------------------------%-----------------------------------------
                [HistBoundArea_N,HistBoundArea_X] = histcounts(PatchAreasBound(:),50);
                [HistBoundLgth_N,HistBoundLgth_X] = histcounts(LegLengthsBound(:),50);
                %-----------------------------------------%-----------------------------------------
            end
        end
        
        function dist = Point2Line(app, x, a, b)
            %x = [0,0]; %some point
            %a = [0,2]; %segment points a,b
            %b = [0,3];
            
            d_ab = norm(a-b);
            d_ax = norm(a-x);
            d_bx = norm(b-x);
            
            if dot(a-b,x-b)*dot(b-a,x-a)>=0
                A = [a,1;b,1;x,1];
                dist = abs(det(A))/d_ab;     
            else
                dist = min(d_ax, d_bx);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FaultName_EditField
        function FaultName_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultNames{SelFaultPos} = app.FaultName_EditField.Value;
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = 1;
        end

        % Value changed function: FaultEasting_EditField
        function FaultEasting_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,1) = app.FaultEasting_EditField.Value;
        end

        % Value changed function: FaultNorthing_EditField
        function FaultNorthing_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,2) = app.FaultNorthing_EditField.Value;          
        end

        % Value changed function: FaultDepth_EditField
        function FaultDepth_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,3) = app.FaultDepth_EditField.Value;   
        end

        % Value changed function: FaultLength_EditField
        function FaultLength_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,4) = app.FaultLength_EditField.Value;               
        end

        % Value changed function: FaultWidth_EditField
        function FaultWidth_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,5) = app.FaultWidth_EditField.Value;              
        end

        % Value changed function: FaultStrike_EditField
        function FaultStrike_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end           
            app.FaultValues(SelFaultPos,6) = app.FaultStrike_EditField.Value;              
        end

        % Value changed function: FaultDip_EditField
        function FaultDip_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,7) = app.FaultDip_EditField.Value;             
        end

        % Value changed function: FaultID_EditField
        function FaultID_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,20) = app.FaultID_EditField.Value; 
        end

        % Value changed function: FaultSlipRate_EditField
        function FaultSlipRate_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end          
            app.FaultValues(SelFaultPos,21) = app.FaultSlipRate_EditField.Value;             
        end

        % Value changed function: FaultSlipRake_EditField
        function FaultSlipRake_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end       
            app.FaultValues(SelFaultPos,22) = app.FaultSlipRake_EditField.Value;            
        end

        % Value changed function: FaultStressRate_EditField
        function FaultStressRate_EditFieldValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end        
            app.FaultValues(SelFaultPos,23) = app.FaultStressRate_EditField.Value;             
        end

        % Value changed function: FaultVertexPos_DropDown
        function FaultVertexPos_DropDownValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(SelFaultPos,(8 +(value-1)*3));
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(SelFaultPos,(9 +(value-1)*3));
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(SelFaultPos,(10+(value-1)*3));
        end

        % Value changed function: FaultVertexPos_Strike_EditField
        function FaultVertexPos_Strike_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end         
            app.FaultValues(SelFaultPos,(8 +(value-1)*3)) = app.FaultVertexPos_Strike_EditField.Value;
        end

        % Value changed function: FaultVertexPos_Dip_EditField
        function FaultVertexPos_Dip_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultValues(SelFaultPos,(9 +(value-1)*3)) = app.FaultVertexPos_Dip_EditField.Value;            
        end

        % Value changed function: FaultVertexPos_Norm_EditField
        function FaultVertexPos_Norm_EditFieldValueChanged(app, event)
            value        = str2double(app.FaultVertexPos_DropDown.Value);
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            app.FaultValues(SelFaultPos,(10 +(value-1)*3)) = app.FaultVertexPos_Norm_EditField.Value;     
        end

        % Value changed function: BoundName_EditField
        function BoundName_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
            app.BoundaryNames(SelBoundPos) = {app.BoundName_EditField.Value};
            app.Bound_ListBox.Items        = app.BoundaryNames;
            app.Bound_ListBox.ItemsData    = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value        = 1;     
        end

        % Value changed function: BoundEasting_EditField
        function BoundEasting_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,1) = app.BoundEasting_EditField.Value;
        end

        % Value changed function: BoundNorthing_EditField
        function BoundNorthing_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,2) = app.BoundNorthing_EditField.Value;
        end

        % Value changed function: BoundDepth_EditField
        function BoundDepth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end           
            app.BoundaryValues(SelBoundPos,3) = app.BoundDepth_EditField.Value;   
        end

        % Value changed function: BoundLength_EditField
        function BoundLength_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,4) = app.BoundLength_EditField.Value;  
        end

        % Value changed function: BoundWidth_EditField
        function BoundWidth_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,5) = app.BoundWidth_EditField.Value;   
        end

        % Value changed function: BoundStrike_EditField
        function BoundStrike_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end          
            app.BoundaryValues(SelBoundPos,6) = app.BoundStrike_EditField.Value;  
        end

        % Value changed function: BoundDip_EditField
        function BoundDip_EditFieldValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end         
            app.BoundaryValues(SelBoundPos,7) = app.BoundDip_EditField.Value;  
        end

        % Value changed function: Fault_ListBox
        function Fault_ListBoxValueChanged(app, event)
            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);          end
            SelFaultName = app.FaultNames{SelFaultPos};
            SelVertex    = str2double(app.FaultVertexPos_DropDown.Value);
            
            app.FaultName_EditField.Value       = SelFaultName;
            app.FaultEasting_EditField.Value    = app.FaultValues(SelFaultPos,1);
            app.FaultNorthing_EditField.Value   = app.FaultValues(SelFaultPos,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(SelFaultPos,3);
            app.FaultLength_EditField.Value     = app.FaultValues(SelFaultPos,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(SelFaultPos,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(SelFaultPos,6);
            app.FaultDip_EditField.Value        = app.FaultValues(SelFaultPos,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(SelFaultPos,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(SelFaultPos,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(SelFaultPos,10+(SelVertex-1)*3);
            
            app.FaultID_EditField.Value         = app.FaultValues(SelFaultPos,20);
            app.FaultSlipRate_EditField.Value   = app.FaultValues(SelFaultPos,21);
            app.FaultSlipRake_EditField.Value   = app.FaultValues(SelFaultPos,22);
            app.FaultStressRate_EditField.Value = app.FaultValues(SelFaultPos,23);           
        end

        % Value changed function: Bound_ListBox
        function Bound_ListBoxValueChanged(app, event)
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);          end
    
            SelBoundName = app.BoundaryNames{SelBoundPos};

            app.BoundName_EditField.Value       = SelBoundName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(SelBoundPos,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(SelBoundPos,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(SelBoundPos,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(SelBoundPos,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(SelBoundPos,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(SelBoundPos,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(SelBoundPos,7);             
        end

        % Button pushed function: PlotFaultSetup_Button
        function PlotFaultSetup_ButtonPushed(app, event)
            
            FigNum      = app.FigNumFaultModel_EditField.Value;
            ViewAngle   = app.ViewAngle_EditField.Value;        ViewAngle = str2num(ViewAngle);
            Faults2Plot = size(app.FaultValues,1);
            Bounds2Plot = size(app.BoundaryValues,1);

            SelFaultPos                 = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);         end
            SelBoundPos                    = app.Bound_ListBox.Value;
            if (~isa(SelBoundPos,'double')),     SelBoundPos = str2double(SelBoundPos);         end

            SelVertex1 = app.Move2_1stCorner_DropDown.Value;
            if (~isa(SelVertex1,'double')),      SelVertex1 = str2double(SelVertex1);           end
            SelVertex2 = app.Move2_2ndCorner_DropDown.Value;
            if (~isa(SelVertex2,'double')),      SelVertex2 = str2double(SelVertex2);           end
            SelFault1  = app.Move2_1stFault_DropDown.Value;
            if (~isa(SelFault1,'double')),       SelFault1   = str2double(SelFault1);           end
            SelFault2  = app.Move2_2ndFault_DropDown.Value;
            if (~isa(SelFault2,'double')),       SelFault2 = str2double(SelFault2);             end
            
            %  8        9       10       11      12      13     14     15       16      17     18      19 => this is the shift value!.
            %LL_stk, LL_dip, LL_nrm, LR_stk, LR_dip, LR_nrm,UR_stk, UR_dip, UR_nrm, UL_stk, UL_dip, UL_nrm
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            view(ViewAngle(1,1),ViewAngle(1,2));
            %-------------------- %-------------------- %-------------------- 
            for i = 1 : Faults2Plot
                Ecent  = app.FaultValues(i,1);
                Ncent  = app.FaultValues(i,2);
                Zcent  = app.FaultValues(i,3);
                Length = app.FaultValues(i,4);
                Width  = app.FaultValues(i,5);
                Strike = app.FaultValues(i,6).*pi./180;
                Dip    = app.FaultValues(i,7).*pi./180;
                
                UL_stk = -Length/2 +app.FaultValues(i,17);      UR_stk =  Length/2 +app.FaultValues(i,14);
                LL_stk = -Length/2 +app.FaultValues(i,8);       LR_stk =  Length/2 +app.FaultValues(i,11);
                UL_dip = 0         +app.FaultValues(i,18);      UR_dip =  0        +app.FaultValues(i,15);
                LL_dip = -Width    +app.FaultValues(i,9);       LR_dip =  -Width   +app.FaultValues(i,12);
                UL_nrm = 0         +app.FaultValues(i,19);      UR_nrm =  0        +app.FaultValues(i,16);
                LL_nrm = 0         +app.FaultValues(i,10);      LR_nrm =  0        +app.FaultValues(i,13);
                     
                stkA   = [sin(Strike), cos(Strike), 0];
                nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                dipA   = cross(nrmA,stkA);
                
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent;Ncent;Zcent];
                l_right = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent;Ncent;Zcent];
                u_right = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent;Ncent;Zcent];
                u_left  = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent;Ncent;Zcent];
                
                the_corner = [l_left'; l_right'; u_right'; u_left'];   
                %-------------------- 
                R       = rand(1);
                G       = rand(1);
                B       = rand(1);
                transp  = 0.5;   
                
                fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','k');
                
                if (i == SelFault1)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','b','LineWidth',2);
                    plot3(the_corner(SelVertex1,1),the_corner(SelVertex1,2),the_corner(SelVertex1,3),'o','MarkerFaceColor','blue','MarkerSize',10,'MarkerEdgeColor','black');
                end
                if (i == SelFault2)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','g','LineWidth',2);
                    plot3(the_corner(SelVertex2,1),the_corner(SelVertex2,2),the_corner(SelVertex2,3),'square','MarkerFaceColor','green','MarkerSize',10,'MarkerEdgeColor','black');
                end
                
                if (i == SelFaultPos)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','r','LineWidth',2);
                end
            end
            %-------------------- %-------------------- %--------------------      
            for i = 1 : Bounds2Plot
                Ecent  = app.BoundaryValues(i,1);
                Ncent  = app.BoundaryValues(i,2);
                Zcent  = app.BoundaryValues(i,3);
                Length = app.BoundaryValues(i,4);
                Width  = app.BoundaryValues(i,5);
                Strike = app.BoundaryValues(i,6).*pi./180;
                Dip    = app.BoundaryValues(i,7).*pi./180;
                
                UL_stk = -Length/2 ;        UR_stk =  Length/2;
                LL_stk = -Length/2 ;        LR_stk =  Length/2;
                UL_dip = 0;                 UR_dip =  0;
                LL_dip = -Width;            LR_dip =  -Width;
                UL_nrm = 0;                 UR_nrm =  0;
                LL_nrm = 0;                 LR_nrm =  0;
                     
                stkA   = [sin(Strike), cos(Strike), 0];
                nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                dipA   = cross(nrmA,stkA);
                
                RotMat = [stkA;dipA;nrmA];
                RotMatT= RotMat';
                %-------------------- 
                l_left  = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent;Ncent;Zcent];
                l_right = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent;Ncent;Zcent];
                u_right = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent;Ncent;Zcent];
                u_left  = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent;Ncent;Zcent];
                
                the_corner = [l_left'; l_right'; u_right'; u_left'];   
                %-------------------- 
                R       = 0.5;              G       = 0.5;
                B       = 0.5;              transp  = 0.5;   
                
                fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','k');

                if ((SelFault1>Faults2Plot) && (i+Faults2Plot == SelFault1))
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','b','LineWidth',2);
                    plot3(the_corner(SelVertex1,1),the_corner(SelVertex1,2),the_corner(SelVertex1,3),'o','MarkerFaceColor','blue','MarkerSize',10,'MarkerEdgeColor','black');
                end
                if ((SelFault2>Faults2Plot) && (i+Faults2Plot == SelFault2))
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','g','LineWidth',2);
                    plot3(the_corner(SelVertex2,1),the_corner(SelVertex2,2),the_corner(SelVertex2,3),'square','MarkerFaceColor','green','MarkerSize',10,'MarkerEdgeColor','black');
                end
                
                if (i == SelBoundPos)
                    fill3(the_corner(:,1),the_corner(:,2),the_corner(:,3),[R G B],'FaceAlpha',transp,'EdgeColor','r','LineWidth',2);
                end
            end
            %-------------------- %-------------------- %-------------------- 
        end

        % Button pushed function: AddFault_Button
        function AddFault_ButtonPushed(app, event)

            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewFltName   = strcat('Fault_',RandomName);
            FaultsInList                        = size(app.FaultNames,2);
            app.FaultNames(FaultsInList+1)      = {NewFltName};
            %---------------------------------------
            NewFault                            = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90),zeros(1,12),(FaultsInList+1),10, 0, 0];
            app.FaultValues                     = [app.FaultValues; NewFault];
            %---------------------------------------
            app.FaultName_EditField.Value       = NewFltName;
            app.FaultEasting_EditField.Value    = app.FaultValues(end,1);
            app.FaultNorthing_EditField.Value   = app.FaultValues(end,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(end,3);
            app.FaultLength_EditField.Value     = app.FaultValues(end,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(end,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(end,6);
            app.FaultDip_EditField.Value        = app.FaultValues(end,7);
            app.FaultID_EditField.Value         = app.FaultValues(end,20);
            app.FaultSlipRate_EditField.Value   = app.FaultValues(end,21);
            app.FaultSlipRake_EditField.Value   = app.FaultValues(end,22);
            app.FaultStressRate_EditField.Value = app.FaultValues(end,23);
            %---------------------------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------        
        end

        % Button pushed function: DeleteFault_Button
        function DeleteFault_ButtonPushed(app, event)

            if (size(app.FaultNames,2) <= 1),           return;                                     end
            SelFaultPos  = app.Fault_ListBox.Value;   
            if (~isa(SelFaultPos,'double')),     SelFaultPos = str2double(SelFaultPos);             end
            SelVertex    = str2double(app.FaultVertexPos_DropDown.Value);
            
            app.FaultNames(SelFaultPos)         = [];
            app.FaultValues(SelFaultPos,:)      = [];
    
            app.FaultName_EditField.Value       = app.FaultNames{1};
            app.FaultNorthing_EditField.Value   = app.FaultValues(1,1);
            app.FaultEasting_EditField.Value    = app.FaultValues(1,2);
            app.FaultDepth_EditField.Value      = app.FaultValues(1,3);
            app.FaultLength_EditField.Value     = app.FaultValues(1,4);
            app.FaultWidth_EditField.Value      = app.FaultValues(1,5);
            app.FaultStrike_EditField.Value     = app.FaultValues(1,6);
            app.FaultDip_EditField.Value        = app.FaultValues(1,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(1,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(1,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(1,10+(SelVertex-1)*3);
            
            app.FaultID_EditField.Value         = app.FaultValues(1,20);
            app.FaultSlipRate_EditField.Value   = app.FaultValues(1,21);
            app.FaultSlipRake_EditField.Value   = app.FaultValues(1,22);
            app.FaultStressRate_EditField.Value = app.FaultValues(1,23);     
            %---------------------------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %--------------------------------------- 
        end

        % Button pushed function: AddBound_Button
        function AddBound_ButtonPushed(app, event)
            
            s = 'ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz0123456789';
            sLength      = 3;
            RandomName   = s(randi([1, 62],1,sLength));
            NewBndName   = strcat('Boundary_',RandomName);
            BoundaryInList                      = size(app.BoundaryValues,1);
            app.BoundaryNames(BoundaryInList+1) = {NewBndName};
            %---------------------------------------
            NewBoundary                         = [  rand(1), rand(1), -rand(1), (rand(1)*10+5), (rand(1)*10+5), (rand(1)*360), (rand(1)*90)];
            app.BoundaryValues                  = [app.BoundaryValues; NewBoundary];
            %---------------------------------------
            app.BoundName_EditField.Value       = NewBndName;
            app.BoundEasting_EditField.Value    = app.BoundaryValues(end,1);
            app.BoundNorthing_EditField.Value   = app.BoundaryValues(end,2);
            app.BoundDepth_EditField.Value      = app.BoundaryValues(end,3);
            app.BoundLength_EditField.Value     = app.BoundaryValues(end,4);
            app.BoundWidth_EditField.Value      = app.BoundaryValues(end,5);
            app.BoundStrike_EditField.Value     = app.BoundaryValues(end,6);
            app.BoundDip_EditField.Value        = app.BoundaryValues(end,7);
            %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %---------------------------------------     
        end

        % Button pushed function: DeleteBound_Button
        function DeleteBound_ButtonPushed(app, event)
            
            if (size(app.BoundaryValues,1) < 1),           return;                                     end
            SelBoundPos  = app.Bound_ListBox.Value;   
            if (~isa(SelBoundPos,'double')),        SelBoundPos = str2double(SelBoundPos);             end
       
            app.BoundaryNames(SelBoundPos)         = [];
            app.BoundaryValues(SelBoundPos,:)      = [];
            
            if (size(app.BoundaryValues,1) < 1)   
                app.BoundaryNames                   = {'No Boundary used'};              
                app.BoundName_EditField.Value       = 'No Boundary used';
                app.BoundNorthing_EditField.Value   = 0;
                app.BoundEasting_EditField.Value    = 0;
                app.BoundDepth_EditField.Value      = 0;
                app.BoundLength_EditField.Value     = 0;
                app.BoundWidth_EditField.Value      = 0;
                app.BoundStrike_EditField.Value     = 0;
                app.BoundDip_EditField.Value        = 0;   
            else
                app.BoundName_EditField.Value       = app.BoundaryNames{1};
                app.BoundNorthing_EditField.Value   = app.BoundaryValues(1,1);
                app.BoundEasting_EditField.Value    = app.BoundaryValues(1,2);
                app.BoundDepth_EditField.Value      = app.BoundaryValues(1,3);
                app.BoundLength_EditField.Value     = app.BoundaryValues(1,4);
                app.BoundWidth_EditField.Value      = app.BoundaryValues(1,5);
                app.BoundStrike_EditField.Value     = app.BoundaryValues(1,6);
                app.BoundDip_EditField.Value        = app.BoundaryValues(1,7);            
            end
             %---------------------------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %---------------------------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %--------------------------------------- 
        end

        % Button pushed function: ShifVertex_tButton
        function ShifVertex_tButtonPushed(app, event)
            
            
            PrimaryFault    = app.Move2_1stFault_DropDown.Value;
            if (~isa(PrimaryFault,'double')),     PrimaryFault    = str2double(PrimaryFault);          end  
            SecondaryFault  = app.Move2_2ndFault_DropDown.Value;
            if (~isa(SecondaryFault,'double')),   SecondaryFault  = str2double(SecondaryFault);        end  
            PrimaryCorner   = app.Move2_1stCorner_DropDown.Value;
            if (~isa(PrimaryCorner,'double')),    PrimaryCorner   = str2double(PrimaryCorner);         end  
            SecondaryCorner = app.Move2_2ndCorner_DropDown.Value;
            if (~isa(SecondaryCorner,'double')),  SecondaryCorner = str2double(SecondaryCorner);       end  
            
            KeepCenterFix = app.Move_KeepCenterFixed_CheckBox.Value;

            if (SecondaryFault > size(app.FaultValues,1))
                KeepCenterFix = 0;
                app.Move_KeepCenterFixed_CheckBox.Value = 0;
                drawnow;
            end
            %---------------------------------------------
            if (PrimaryFault == SecondaryFault), errordlg('Dont want to "fold" the fault :) => make sure that faults to connect are not the same.');         return;             end
            %---------------------------------------------
            %  8        9       10       11      12      13     14     15       16      17     18      19.
            %LL_stk, LL_dip, LL_nrm, LR_stk, LR_dip, LR_nrm,UR_stk, UR_dip, UR_nrm, UL_stk, UL_dip, UL_nrm
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            
            if (PrimaryFault <= size(app.FaultValues,1))
                Ecent_primary  = app.FaultValues(PrimaryFault,1);
                Ncent_primary  = app.FaultValues(PrimaryFault,2);
                Zcent_primary  = app.FaultValues(PrimaryFault,3);
                Length_primary = app.FaultValues(PrimaryFault,4);
                Width_primary  = app.FaultValues(PrimaryFault,5);
                Strike_primary = app.FaultValues(PrimaryFault,6).*pi./180;
                Dip_primary    = app.FaultValues(PrimaryFault,7).*pi./180;
                
                UL_stk = -Length_primary/2 +app.FaultValues(PrimaryFault,17);       UR_stk =  Length_primary/2 +app.FaultValues(PrimaryFault,14);
                LL_stk = -Length_primary/2 +app.FaultValues(PrimaryFault,8);        LR_stk =  Length_primary/2 +app.FaultValues(PrimaryFault,11);
                UL_dip = 0                 +app.FaultValues(PrimaryFault,18);       UR_dip =  0                +app.FaultValues(PrimaryFault,15);
                LL_dip = -Width_primary    +app.FaultValues(PrimaryFault,9);        LR_dip =  -Width_primary   +app.FaultValues(PrimaryFault,12);
                UL_nrm = 0                 +app.FaultValues(PrimaryFault,19);       UR_nrm =  0                +app.FaultValues(PrimaryFault,16);
                LL_nrm = 0                 +app.FaultValues(PrimaryFault,10);       LR_nrm =  0                +app.FaultValues(PrimaryFault,13);
            else
                Ecent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),1);
                Ncent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),2);
                Zcent_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),3);
                Length_primary = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),4);
                Width_primary  = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),5);
                Strike_primary = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),6).*pi./180;
                Dip_primary    = app.BoundaryValues((PrimaryFault-size(app.FaultValues,1)),7).*pi./180;
                
                UL_stk = -Length_primary/2;                 UR_stk =  Length_primary/2;
                LL_stk = -Length_primary/2;                 LR_stk =  Length_primary/2;
                UL_dip = 0;                                 UR_dip =  0;
                LL_dip = -Width_primary;                    LR_dip =  -Width_primary;
                UL_nrm = 0;                                 UR_nrm =  0;
                LL_nrm = 0;                                 LR_nrm =  0;
            end
            stkA   = [sin(Strike_primary), cos(Strike_primary), 0];
            nrmA   = [sin(Dip_primary)*cos(Strike_primary), -sin(Dip_primary)*sin(Strike_primary), cos(Dip_primary)];
            dipA   = cross(nrmA,stkA);
            
            RotMat = [stkA;dipA;nrmA];
            RotMatT= RotMat';
            %-------------------- 
            l_left          = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            l_right         = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            u_right         = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            u_left          = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent_primary;Ncent_primary;Zcent_primary];
            Corners_primary = [l_left'; l_right'; u_right'; u_left'];
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            if (SecondaryFault <= size(app.FaultValues,1))
                Ecent_secondary  = app.FaultValues(SecondaryFault,1);
                Ncent_secondary  = app.FaultValues(SecondaryFault,2);
                Zcent_secondary  = app.FaultValues(SecondaryFault,3);
                Length_secondary = app.FaultValues(SecondaryFault,4);
                Width_secondary  = app.FaultValues(SecondaryFault,5);
                Strike_secondary = app.FaultValues(SecondaryFault,6).*pi./180;
                Dip_secondary    = app.FaultValues(SecondaryFault,7).*pi./180;
                
                if (KeepCenterFix == 1)
                    UL_stk = -Length_secondary/2 ;          UR_stk =  Length_secondary/2;
                    LL_stk = -Length_secondary/2 ;          LR_stk =  Length_secondary/2;
                    UL_dip = 0;                             UR_dip =  0;
                    LL_dip = -Width_secondary;              LR_dip =  -Width_secondary;
                    UL_nrm = 0;                             UR_nrm =  0;
                    LL_nrm = 0;                             LR_nrm =  0;
                else
                    UL_stk = -Length_secondary/2 +app.FaultValues(SecondaryFault,17);       UR_stk =  Length_secondary/2 +app.FaultValues(SecondaryFault,14);
                    LL_stk = -Length_secondary/2 +app.FaultValues(SecondaryFault,8);        LR_stk =  Length_secondary/2 +app.FaultValues(SecondaryFault,11);
                    UL_dip = 0                   +app.FaultValues(SecondaryFault,18);       UR_dip =  0                  +app.FaultValues(SecondaryFault,15);
                    LL_dip = -Width_secondary    +app.FaultValues(SecondaryFault,9);        LR_dip =  -Width_secondary   +app.FaultValues(SecondaryFault,12);
                    UL_nrm = 0                   +app.FaultValues(SecondaryFault,19);       UR_nrm =  0                  +app.FaultValues(SecondaryFault,16);
                    LL_nrm = 0                   +app.FaultValues(SecondaryFault,10);       LR_nrm =  0                  +app.FaultValues(SecondaryFault,13);
                end
            else
                Ecent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1);
                Ncent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2);
                Zcent_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3);
                Length_secondary = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),4);
                Width_secondary  = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),5);
                Strike_secondary = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),6).*pi./180;
                Dip_secondary    = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),7).*pi./180;
                
                UL_stk = -Length_secondary/2;                 UR_stk =  Length_secondary/2;
                LL_stk = -Length_secondary/2;                 LR_stk =  Length_secondary/2;
                UL_dip = 0;                                 UR_dip =  0;
                LL_dip = -Width_secondary;                    LR_dip =  -Width_secondary;
                UL_nrm = 0;                                 UR_nrm =  0;
                LL_nrm = 0;                                 LR_nrm =  0;
                
            end
            stkA   = [sin(Strike_secondary), cos(Strike_secondary), 0];
            nrmA   = [sin(Dip_secondary)*cos(Strike_secondary), -sin(Dip_secondary)*sin(Strike_secondary), cos(Dip_secondary)];
            dipA   = cross(nrmA,stkA);
            
            RotMat = [stkA;dipA;nrmA];
            RotMatT= RotMat';
            %------------------- 
            l_left            = RotMatT*[LL_stk;LL_dip;LL_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            l_right           = RotMatT*[LR_stk;LR_dip;LR_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            u_right           = RotMatT*[UR_stk;UR_dip;UR_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            u_left            = RotMatT*[UL_stk;UL_dip;UL_nrm] +[Ecent_secondary;Ncent_secondary;Zcent_secondary];
            Corners_secondary = [l_left'; l_right'; u_right'; u_left']; 
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
            CornerShift       =  Corners_primary(PrimaryCorner,:) - Corners_secondary(SecondaryCorner,:);

            if (KeepCenterFix == 1) %need to bring CornerShift in local coordinate system
             
                LocalPos  = RotMat*CornerShift';
            
                if (SecondaryFault <= size(app.FaultValues,1))
                    app.FaultValues(SecondaryFault,(8+(SecondaryCorner-1)*3))     = LocalPos(1,1);
                    app.FaultValues(SecondaryFault,(9+(SecondaryCorner-1)*3))     = LocalPos(2,1);
                    app.FaultValues(SecondaryFault,(10+(SecondaryCorner-1)*3))    = LocalPos(3,1);
                else
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) +CornerShift(1,1);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) +CornerShift(1,2);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) +CornerShift(1,3);
                end 
            else 
                if (SecondaryFault <= size(app.FaultValues,1))
                    app.FaultValues(SecondaryFault,1) = app.FaultValues(SecondaryFault,1) +CornerShift(1,1);
                    app.FaultValues(SecondaryFault,2) = app.FaultValues(SecondaryFault,2) +CornerShift(1,2);
                    app.FaultValues(SecondaryFault,3) = app.FaultValues(SecondaryFault,3) +CornerShift(1,3);
                else
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),1) +CornerShift(1,1);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),2) +CornerShift(1,2);
                    app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) = app.BoundaryValues((SecondaryFault-size(app.FaultValues,1)),3) +CornerShift(1,3);
                end
            end
            %oooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooooo
        end

        % Button pushed function: Triangulate_Button
        function Triangulate_ButtonPushed(app, event)
            
            IntTrigFault  = app.IntendedTrigFaults_EditField.Value;     IntTrigFault  = sscanf(IntTrigFault,'%d');
            IntTrigBound  = app.IntendedTrigBound_EditField.Value;
            
            FaultVertices = zeros(size(app.FaultValues,1),10);
            BoundVertices = zeros(size(app.BoundaryValues,1),10);
            CombAreaFault = 0;
            CombAreaBound = 0;
            SizeTrigListFault = [];
            app.Lamp.Color = 'Yellow'; drawnow;
            %-----------------------------           
            for i = 1: size(app.FaultValues,1)
                FaultVertices(i,1) = -app.FaultValues(i,4)/2 +app.FaultValues(i,8); % LL_x these are the x-values (in local coordinates)
                FaultVertices(i,2) =  app.FaultValues(i,4)/2 +app.FaultValues(i,11);% LR_x
                FaultVertices(i,3) =  app.FaultValues(i,4)/2 +app.FaultValues(i,14);% UR_x
                FaultVertices(i,4) = -app.FaultValues(i,4)/2 +app.FaultValues(i,17);% UL_x
                FaultVertices(i,5) = FaultVertices(i,1);
                
                FaultVertices(i,6) = -app.FaultValues(i,5)   +app.FaultValues(i,9); % LL_y these are the y-values (in local coordinates)
                FaultVertices(i,7) = -app.FaultValues(i,5)   +app.FaultValues(i,12);% LR_y
                FaultVertices(i,8) =  0                      +app.FaultValues(i,15);% UR_y
                FaultVertices(i,9) =  0                      +app.FaultValues(i,18);% UL_y
                FaultVertices(i,10)= FaultVertices(i,6);
                CombAreaFault      = CombAreaFault + polyarea(FaultVertices(i,1:4),FaultVertices(i,6:9));
            end
            %-----------------------------           
            for i = 1: size(app.BoundaryValues,1)
                BoundVertices(i,1) = -app.BoundaryValues(i,4)/2; % LL_x these are the x-values (in local coordinates)
                BoundVertices(i,2) =  app.BoundaryValues(i,4)/2;% LR_x
                BoundVertices(i,3) =  app.BoundaryValues(i,4)/2;% UR_x
                BoundVertices(i,4) = -app.BoundaryValues(i,4)/2;% UL_x
                BoundVertices(i,5) = BoundVertices(i,1);
                
                BoundVertices(i,6) = -app.BoundaryValues(i,5); % LL_y these are the y-values (in local coordinates)
                BoundVertices(i,7) = -app.BoundaryValues(i,5);% LR_y
                BoundVertices(i,8) =  0;% UR_y
                BoundVertices(i,9) =  0;% UL_y
                BoundVertices(i,10)= BoundVertices(i,6);
                CombAreaBound      = CombAreaBound + polyarea(BoundVertices(i,1:4),BoundVertices(i,6:9));
            end
            %-----------------------------%-----------------------------
            app.GriddedFault = struct;
            app.GriddedBound = struct;
            %-----------------------------%-----------------------------
            for ii = 1 : size(IntTrigFault,1)
                IntendedTrigArea                      = CombAreaFault./IntTrigFault(ii,1);
                RespectiveLegLength                   = sqrt(IntendedTrigArea*4/sqrt(3));
                app.GriddedFault(ii).TriangleList     = [];
                app.GriddedFault(ii).VertexList       = [];
      
                for i = 1: size(app.FaultValues,1)
                    model = createpde;            
                    P1                                = [2,4,FaultVertices(i,1:4),FaultVertices(i,6:9)]';
                    g                                 = decsg(P1);
                    %----------------------------
                    geometryFromEdges(model,g);
                    generateMesh(model,'Hmax',RespectiveLegLength,'GeometricOrder','linear');
                    %----------------------------
                    p                                 = model.Mesh.Nodes';
                    t                                 = model.Mesh.Elements';
                    FaultID                           = zeros(size(t,1),1) +i;
                    TheFaultNameID                    = zeros(size(t,1),1) +app.FaultValues(i,20);
                    PositionAlongFlt                  = zeros(size(t,1),1);
                    %----------------------------
                    for j = 1 : size(t,1)
                       PositionAlongFlt(j,1) = (p(t(j,1),1)+p(t(j,2),1)+p(t(j,3),1))/3; 
                    end
                    PositionAlongFlt = PositionAlongFlt - min(PositionAlongFlt);
                    PositionAlongFlt = PositionAlongFlt / max(PositionAlongFlt);
                    %----------------------------
                    NewBlockToAdd                     = [t+size(app.GriddedFault(ii).VertexList,1), FaultID, TheFaultNameID, PositionAlongFlt ];
                    app.GriddedFault(ii).TriangleList = [app.GriddedFault(ii).TriangleList; NewBlockToAdd];
                    app.GriddedFault(ii).VertexList   = [app.GriddedFault(ii).VertexList;  p];
                    clear P1 g p t model NewBlockToAdd;
                end
                MinDepth = max(app.GriddedFault(ii).VertexList(:,2)); %this is 0.1m
                app.GriddedFault(ii).VertexList(:,2)   = app.GriddedFault(ii).VertexList(:,2) - MinDepth -0.01;
                
                
                app.GriddedFault(ii).TriangleList(:,6) = rand(size(app.GriddedFault(ii).TriangleList,1),1);
                app.GriddedFault(ii).TriangleList      = sortrows(app.GriddedFault(ii).TriangleList,6);            
            end
            %---------------------------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                IntendedTrigArea                      = CombAreaBound./IntTrigBound;
                RespectiveLegLength                   = sqrt(IntendedTrigArea*4/sqrt(3));
                app.GriddedBound.TriangleList         = [];
                app.GriddedBound.VertexList           = [];
                
                for i = 1: size(app.BoundaryValues,1)
                    model = createpde;            
                    P1                                = [2,4,BoundVertices(i,1:4),BoundVertices(i,6:9)]';
                    g                                 = decsg(P1);
                    %----------------------------
                    geometryFromEdges(model,g);
                    generateMesh(model,'Hmax',RespectiveLegLength,'GeometricOrder','linear');
                    %----------------------------
                    p                                 = model.Mesh.Nodes';
                    t                                 = model.Mesh.Elements';
                    BoundID                           = zeros(size(t,1),1) +i;
                    app.GriddedBound.TriangleList     = [app.GriddedBound.TriangleList; t+size(app.GriddedBound.VertexList,1), BoundID];
                    app.GriddedBound.VertexList       = [app.GriddedBound.VertexList; p];
                    clear P1 g p t model;
                end
                MinDepth = max(app.GriddedBound.VertexList(:,2));
                app.GriddedBound.VertexList(:,2) = app.GriddedBound.VertexList(:,2) - MinDepth  -0.01;
            end
            %---------------------------------------------------------------
            for ii = 1 : size(IntTrigFault,1)
                LegLengthsFault = zeros(size(app.GriddedFault(ii).TriangleList,1),3);
                PatchAreasFault = zeros(size(app.GriddedFault(ii).TriangleList,1),1);
                %-----------------------------------------
                for i = 1 : size(app.GriddedFault(ii).TriangleList,1)
                    v1    = app.GriddedFault(ii).TriangleList(i,1);
                    v2    = app.GriddedFault(ii).TriangleList(i,2);
                    v3    = app.GriddedFault(ii).TriangleList(i,3);
            
                    a     = sqrt((app.GriddedFault(ii).VertexList(v1,1)-app.GriddedFault(ii).VertexList(v2,1))^2 + (app.GriddedFault(ii).VertexList(v1,2)-app.GriddedFault(ii).VertexList(v2,2))^2);
                    b     = sqrt((app.GriddedFault(ii).VertexList(v2,1)-app.GriddedFault(ii).VertexList(v3,1))^2 + (app.GriddedFault(ii).VertexList(v2,2)-app.GriddedFault(ii).VertexList(v3,2))^2);
                    c     = sqrt((app.GriddedFault(ii).VertexList(v3,1)-app.GriddedFault(ii).VertexList(v1,1))^2 + (app.GriddedFault(ii).VertexList(v3,2)-app.GriddedFault(ii).VertexList(v1,2))^2);
                    %---------------------------
                    LegLengthsFault(i,1:3) = [a,  b,  c];  
                    PatchAreasFault(i,1)   = polyarea([app.GriddedFault(ii).VertexList(v1,1), app.GriddedFault(ii).VertexList(v2,1), app.GriddedFault(ii).VertexList(v3,1)],[app.GriddedFault(ii).VertexList(v1,2), app.GriddedFault(ii).VertexList(v2,2), app.GriddedFault(ii).VertexList(v3,2)]);
                    %---------------------------
                end
                meanPatchAreasFault   = nanmean(PatchAreasFault(:));
                meanLegLengthsFault   = nanmean(LegLengthsFault(:));
                %-----------------------------------------
                app.GriddedFault(ii).SizeTrigList     = size(app.GriddedFault(ii).TriangleList,1);
                app.GriddedFault(ii).meanPatchAreas   = meanPatchAreasFault;
                app.GriddedFault(ii).meanLegLengths   = meanLegLengthsFault;
                disp(strcat('Faults: mean leg length = ',num2str(app.GriddedFault(ii).meanLegLengths)));
                disp(strcat('Faults: mean patch area = ',num2str(app.GriddedFault(ii).meanPatchAreas)));
                SizeTrigListFault{ii,1}               = num2str(app.GriddedFault(ii).SizeTrigList);
            end
            %---------------------------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                LegLengthsBound = zeros(size(app.GriddedBound.TriangleList,1),3);
                PatchAreasBound = zeros(size(app.GriddedBound.TriangleList,1),1);
                %-----------------------------------------
                for i = 1 : size(app.GriddedBound.TriangleList,1)
                    v1    = app.GriddedBound.TriangleList(i,1);
                    v2    = app.GriddedBound.TriangleList(i,2);
                    v3    = app.GriddedBound.TriangleList(i,3);
            
                    a     = sqrt((app.GriddedBound.VertexList(v1,1)-app.GriddedBound.VertexList(v2,1))^2 + (app.GriddedBound.VertexList(v1,2)-app.GriddedBound.VertexList(v2,2))^2);
                    b     = sqrt((app.GriddedBound.VertexList(v2,1)-app.GriddedBound.VertexList(v3,1))^2 + (app.GriddedBound.VertexList(v2,2)-app.GriddedBound.VertexList(v3,2))^2);
                    c     = sqrt((app.GriddedBound.VertexList(v3,1)-app.GriddedBound.VertexList(v1,1))^2 + (app.GriddedBound.VertexList(v3,2)-app.GriddedBound.VertexList(v1,2))^2);
                    %---------------------------
                    LegLengthsBound(i,1:3) = [a,  b,  c];  
                    PatchAreasBound(i,1)   = polyarea([app.GriddedBound.VertexList(v1,1), app.GriddedBound.VertexList(v2,1), app.GriddedBound.VertexList(v3,1)],[app.GriddedBound.VertexList(v1,2), app.GriddedBound.VertexList(v2,2), app.GriddedBound.VertexList(v3,2)]);
                    %---------------------------
                end
                meanPatchAreasBound   = nanmean(PatchAreasBound(:));
                meanLegLengthsBound   = nanmean(LegLengthsBound(:));
                %-----------------------------------------
                app.GriddedBound.SizeTrigList     = size(app.GriddedBound.TriangleList,1);
                app.GriddedBound.meanPatchAreas   = meanPatchAreasBound;
                app.GriddedBound.meanLegLengths   = meanLegLengthsBound;
                disp(strcat('Boundary: mean leg length = ',num2str(app.GriddedBound.meanLegLengths)));
                disp(strcat('Boundary: mean patch area = ',num2str(app.GriddedBound.meanPatchAreas)));
            end
            %---------------------------------------------------------------
            app.FaultActualTrig_ListBox.Items     = SizeTrigListFault;
            app.FaultActualTrig_ListBox.ItemsData = 1:size(IntTrigFault,1);
            app.FaultActualTrig_ListBox.Value     = 1;
            if (size(app.BoundaryValues,1) > 0),        app.ActualTrigBound_EditField.Value = app.GriddedBound.SizeTrigList;            end
            %---------------------------------------------------------------
            %  8        9       10       11      12      13     14     15       16      17     18      19.
            %LL_stk, LL_dip, LL_nrm, LR_stk, LR_dip, LR_nrm,UR_stk, UR_dip, UR_nrm, UL_stk, UL_dip, UL_nrm
            for ii = 1 : size(IntTrigFault,1)
                GlobalVertices = NaN(size(app.GriddedFault(ii).VertexList,1),3);
                for i = 1 : size(app.FaultValues,1)
                    
                    FltTrigs     = app.GriddedFault(ii).TriangleList(app.GriddedFault(ii).TriangleList(:,4) == i,1:3);
                    VertStartPos = min(FltTrigs(:));
                    VertEndPos   = max(FltTrigs(:));
                    
                    Ecent  = app.FaultValues(i,1);
                    Ncent  = app.FaultValues(i,2);
                    Zcent  = app.FaultValues(i,3);
                    Length = app.FaultValues(i,4);
                    Width  = app.FaultValues(i,5);
                    Strike = app.FaultValues(i,6).*pi./180;
                    Dip    = app.FaultValues(i,7).*pi./180;
                    %-------------------- 
                    stkA   = [sin(Strike), cos(Strike), 0];
                    nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                    dipA   = cross(nrmA,stkA);
                    RotMat = [stkA;dipA;nrmA];
                    RotMatT= RotMat';
                    %-------------------- 
                    LL_stk = -Length/2 +app.FaultValues(i,8);       LL_dip = -Width +app.FaultValues(i,9);       LL_nrm = 0 +app.FaultValues(i,10);
                    LR_stk =  Length/2 +app.FaultValues(i,11);      LR_dip = -Width +app.FaultValues(i,12);      LR_nrm = 0 +app.FaultValues(i,13);
                    UR_stk =  Length/2 +app.FaultValues(i,14);      UR_dip = 0      +app.FaultValues(i,15);      UR_nrm = 0 +app.FaultValues(i,16);
                    UL_stk = -Length/2 +app.FaultValues(i,17);      UL_dip = 0      +app.FaultValues(i,18);      UL_nrm = 0 +app.FaultValues(i,19);
                    %--------------------  
                    PolygonVerts = [LL_stk, LL_dip, LL_nrm; LR_stk, LR_dip, LR_nrm; UR_stk, UR_dip, UR_nrm; UL_stk, UL_dip, UL_nrm];
                    %--------------------   here I can call the function to shift position of the vertices
                    OutOfPlaneShift = ShiftVertices(app, app.GriddedFault(ii).VertexList(VertStartPos:VertEndPos,:), PolygonVerts);
                    %--------------------
                    for j = 1 : size(OutOfPlaneShift,1)
                        GlobalVertices((VertStartPos+j-1),:) = RotMatT*[app.GriddedFault(ii).VertexList((VertStartPos+j-1),1); app.GriddedFault(ii).VertexList((VertStartPos+j-1),2); OutOfPlaneShift(j,1)] +[Ecent; Ncent; Zcent];   
                    end
                    %--------------------
                end
                app.GriddedFault(ii).GlobalVertices = GlobalVertices;
                %--------------------
                MinDepth = max(app.GriddedFault(ii).GlobalVertices(:,3));
                if (MinDepth >= 0)
                    app.GriddedFault(ii).GlobalVertices(:,3) = app.GriddedFault(ii).GlobalVertices(:,3) - MinDepth -0.0001;% this 0.0001 is 0.10m
                end
                %-----------------------------%-----------------------------
                app.GriddedFault(ii).GlobTrigCentr = zeros(size(app.GriddedFault(ii).TriangleList,1),3);    

                DistLeg2Cent    = zeros(size(app.GriddedFault(ii).TriangleList,1),3);
                
                for i = 1 : size(app.GriddedFault(ii).TriangleList,1)
                    v1 = app.GriddedFault(ii).TriangleList(i,1);
                    v2 = app.GriddedFault(ii).TriangleList(i,2);
                    v3 = app.GriddedFault(ii).TriangleList(i,3);
            
                    Vert1 =  app.GriddedFault(ii).GlobalVertices(v1,:);   
                    Vert2 =  app.GriddedFault(ii).GlobalVertices(v2,:);   
                    Vert3 =  app.GriddedFault(ii).GlobalVertices(v3,:);   
                    
                    app.GriddedFault(ii).GlobTrigCentr(i,1) = (Vert1(1,1)+Vert2(1,1)+Vert3(1,1))/3;
                    app.GriddedFault(ii).GlobTrigCentr(i,2) = (Vert1(1,2)+Vert2(1,2)+Vert3(1,2))/3;
                    app.GriddedFault(ii).GlobTrigCentr(i,3) = (Vert1(1,3)+Vert2(1,3)+Vert3(1,3))/3;
                    
                    app.GriddedFault(ii).Normal(i,:) =cross((Vert2-Vert1),Vert3-Vert1)';
                    app.GriddedFault(ii).Normal(i,:) = app.GriddedFault(ii).Normal(i,:)./norm(app.GriddedFault(ii).Normal(i,:));
                    
                    
                    %---------------------------%---------------------------
                    temp1   = Vert2 - Vert1;
                    temp2   = Vert3 - Vert2;
                    temp3   = Vert1 - Vert3;
   
                    temp    = app.GriddedFault(ii).GlobTrigCentr(i,:) - Vert1;
                    DistLeg2Cent(i,1) = norm(cross(temp1,temp));
                    temp    = app.GriddedFault(ii).GlobTrigCentr(i,:) - Vert2;
                    DistLeg2Cent(i,2) = norm(cross(temp2,temp));
                    temp    = app.GriddedFault(ii).GlobTrigCentr(i,:) - Vert3;
                    DistLeg2Cent(i,3) = norm(cross(temp3,temp));
                    %---------------------------%--------------------------- 
                end
                meanDist2Cent         = nanmean(DistLeg2Cent(:));
                meanDist2CentFrac     = meanDist2Cent/meanLegLengthsFault;
                stdDist2Cent          = nanstd(DistLeg2Cent(:))*2;
                stdDist2CentFrac     = stdDist2Cent/meanLegLengthsFault;
                disp(strcat('Average distance between patch center and triangle edges as fraction of mean edge length: ',num2str(meanDist2CentFrac),'and 2sigma: ',num2str(stdDist2CentFrac)));
                
            end
            %-----------------------------%-----------------------------
            if (size(app.BoundaryValues,1) > 0)
                GlobalVertices = NaN(size(app.GriddedBound.VertexList,1),3);
                for i = 1 : size(app.BoundaryValues,1)
                    
                    FltTrigs     = app.GriddedBound.TriangleList(app.GriddedBound.TriangleList(:,4) == i,1:3);
                    VertStartPos = min(FltTrigs(:));
                    VertEndPos   = max(FltTrigs(:));
                    
                    Ecent  = app.BoundaryValues(i,1);
                    Ncent  = app.BoundaryValues(i,2);
                    Zcent  = app.BoundaryValues(i,3);
                    Length = app.BoundaryValues(i,4);
                    Width  = app.BoundaryValues(i,5);
                    Strike = app.BoundaryValues(i,6).*pi./180;
                    Dip    = app.BoundaryValues(i,7).*pi./180;
                    %-------------------- 
                    stkA   = [sin(Strike), cos(Strike), 0];
                    nrmA   = [sin(Dip)*cos(Strike), -sin(Dip)*sin(Strike), cos(Dip)];
                    dipA   = cross(nrmA,stkA);
                    RotMat = [stkA;dipA;nrmA];
                    RotMatT= RotMat';
                    %-------------------- 
                    LL_stk = -Length/2;         LL_dip = -Width;        LL_nrm = 0;
                    LR_stk =  Length/2;         LR_dip = -Width;        LR_nrm = 0;
                    UR_stk =  Length/2;         UR_dip = 0;             UR_nrm = 0;
                    UL_stk = -Length/2;         UL_dip = 0;             UL_nrm = 0;
                    %--------------------  
                    PolygonVerts = [LL_stk, LL_dip, LL_nrm; LR_stk, LR_dip, LR_nrm; UR_stk, UR_dip, UR_nrm; UL_stk, UL_dip, UL_nrm];
                    %--------------------   here I can call the function to shift position of the vertices
                    OutOfPlaneShift = ShiftVertices(app, app.GriddedBound.VertexList(VertStartPos:VertEndPos,:), PolygonVerts);
                    %--------------------
                    for j = 1 : size(OutOfPlaneShift,1)
                        GlobalVertices((VertStartPos+j-1),:) = RotMatT*[app.GriddedBound.VertexList((VertStartPos+j-1),1); app.GriddedBound.VertexList((VertStartPos+j-1),2); OutOfPlaneShift(j,1)] +[Ecent; Ncent; Zcent];   
                    end
                    %--------------------
                end
                app.GriddedBound.GlobalVertices = GlobalVertices;
                %--------------------
                MinDepth = max(app.GriddedBound.GlobalVertices(:,3));
                if (MinDepth >= 0)
                    app.GriddedBound.GlobalVertices(:,3) = app.GriddedBound.GlobalVertices(:,3) - MinDepth -0.0001;% this 0.01 is 10m
                end
                %-----------------------------%-----------------------------
                app.GriddedBound.GlobTrigCentr = zeros(size(app.GriddedBound.TriangleList,1),3);    

                for i = 1 : size(app.GriddedBound.TriangleList,1)
                    v1 = app.GriddedBound.TriangleList(i,1);
                    v2 = app.GriddedBound.TriangleList(i,2);
                    v3 = app.GriddedBound.TriangleList(i,3);
            
                    Vert1 =  app.GriddedBound.GlobalVertices(v1,:);   
                    Vert2 =  app.GriddedBound.GlobalVertices(v2,:);   
                    Vert3 =  app.GriddedBound.GlobalVertices(v3,:);   
                    
                    app.GriddedBound.GlobTrigCentr(i,1) = (Vert1(1,1)+Vert2(1,1)+Vert3(1,1))/3;
                    app.GriddedBound.GlobTrigCentr(i,2) = (Vert1(1,2)+Vert2(1,2)+Vert3(1,2))/3;
                    app.GriddedBound.GlobTrigCentr(i,3) = (Vert1(1,3)+Vert2(1,3)+Vert3(1,3))/3;
                    
                    app.GriddedBound.Normal(i,:) =cross((Vert2-Vert1),Vert3-Vert1)';
                    app.GriddedBound.Normal(i,:) = app.GriddedBound.Normal(i,:)./norm(app.GriddedBound.Normal(i,:));
                end
            end
            %-----------------------------%-----------------------------
            app.FaultWasGridded = 1;
            app.Lamp.Color      = 'Green';
        end

        % Button pushed function: PlotMesh_Button
        function PlotMesh_ButtonPushed(app, event)
            
            FigNum          = app.FigNumFaultMesh_EditField.Value;
            ViewAngle       = app.ViewAngle_EditField.Value;        ViewAngle = str2num(ViewAngle);
            PlotStats       = app.ShowTrigStats_CheckBox.Value;
            SelectedGrid    = app.FaultActualTrig_ListBox.Value;
            if (~isa(SelectedGrid,'double')),     SelectedGrid = str2double(SelectedGrid);          end               
            %-----------------------------------------------
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            view(ViewAngle(1,1),ViewAngle(1,2));
            %-----------------------------------------------
            AllV1s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
            AllV2s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
            AllV3s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
            Col             = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,1);
            Cntr            = 1;
            %-----------------------------------------------
            for j = 1 : size(app.GriddedFault(SelectedGrid).TriangleList,1)
                AllV1s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),1);
            
                AllV2s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),2);
            
                AllV3s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),3);
                Col(Cntr,1)    = app.GriddedFault(SelectedGrid).TriangleList(j,5);
                
                Cntr           = Cntr +1;
            end
            patch(AllV1s',AllV2s',AllV3s',Col');
            %-----------------------------------------------%-----------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                AllV1s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV2s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV3s          = zeros(app.GriddedBound.SizeTrigList,3);
                Col             = [0.5 0.5 0.5];
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.GriddedBound.TriangleList,1)
                    AllV1s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),1);
                
                    AllV2s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),2);
                
                    AllV3s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),3);
                    
                    
                    Cntr           = Cntr +1;
                end
                patch(AllV1s',AllV2s',AllV3s',Col);
            end
            %-----------------------------------------------
            if PlotStats == 1
                [HistFaultArea_N,HistFaultArea_X,HistBoundArea_N,HistBoundArea_X,HistFaultLgth_N,HistFaultLgth_X,HistBoundLgth_N,HistBoundLgth_X] = ComputeTrigStats(app);
                
                HistFaultArea_X = HistFaultArea_X./mean(HistFaultArea_X);
                HistFaultLgth_X = HistFaultLgth_X./mean(HistFaultLgth_X);
                HistBoundArea_X = HistBoundArea_X./mean(HistBoundArea_X);
                HistBoundLgth_X = HistBoundLgth_X./mean(HistBoundLgth_X);
                
                figure(FigNum*100+1); clf; hold on;
                subplot(2,2,1);    
                histogram('BinEdges',HistFaultLgth_X,'BinCounts',HistFaultLgth_N);
                xlabel('FAULT: leg length, normalized by mean'); ylabel('Frequency');
                subplot(2,2,2);    
                histogram('BinEdges',HistFaultArea_X,'BinCounts',HistFaultArea_N);
                xlabel('FAULT: patch area, normalized by mean'); ylabel('Frequency');
                if (size(app.BoundaryValues,1) > 0)
                    subplot(2,2,3);   
                    histogram('BinEdges',HistBoundLgth_X,'BinCounts',HistBoundLgth_N);
                     xlabel('BOUND: leg length, normalized by mean'); ylabel('Frequency');
                    subplot(2,2,4);    
                    histogram('BinEdges',HistBoundArea_X,'BinCounts',HistBoundArea_N);
                    xlabel('BOUND:patch area, normalized by mean'); ylabel('Frequency');
                end
            end
            %-----------------------------------------------     
        end

        % Button pushed function: SaveMesh_Button
        function SaveMesh_ButtonPushed(app, event)
     
            FltNum     = size(app.FaultValues,1);   
            GridNumber = size(app.GriddedFault,2);
            if (isfield(app.GriddedFault,'TriangleList') == 0),         GridNumber = 0;          end  
             %-----------------------   
            [DataName,DataPath]  = uiputfile('*.*','Save Fault Model (with triangulation if done already)');
            if     (DataName ~=0),      DataName = strtok(DataName,'.');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Number_of_fault_segments:  %d\n',FltNum);
            fprintf(fidascii,'Number_of_grids_generated: %d\n',GridNumber);
            for ii = 1 : 1 : GridNumber
                fprintf(fidascii,'Grid_%d:__Combined_number_of_trig_patches_and_vertices:  %d   %d\n',ii, size(app.GriddedFault(ii).TriangleList,1), size(app.GriddedFault(ii).VertexList,1));
                fprintf(fidascii,'Grid_%d:__Mean_leg_length_and_area:  %10.4e  %10.4e\n',ii, app.GriddedFault(ii).meanLegLengths, app.GriddedFault(ii).meanPatchAreas);
            end
            fprintf(fidascii,'\n');   
            %--------------------------------------
            for i = 1 : FltNum
                fprintf(fidascii,'Fault_ID_number: %d\n',app.FaultValues(i,20));
                
                fprintf(fidascii,'Fault_section_name: %s\n',app.FaultNames{1,i});
                fprintf(fidascii,'Easting_of_fault_top_center(km):     %10.4e\n',app.FaultValues(i,1));
                fprintf(fidascii,'Northing_of_fault_top_center(km):    %10.4e\n',app.FaultValues(i,2));
                fprintf(fidascii,'Depth_of_fault_top_center(km):       %10.4e\n',app.FaultValues(i,3));
                fprintf(fidascii,'Length_of_fault_section(km):         %10.4e\n',app.FaultValues(i,4));
                fprintf(fidascii,'Width_of_fault_section(km):          %10.4e\n',app.FaultValues(i,5));
                fprintf(fidascii,'Strike_of_fault_section(deg):        %10.4e\n',app.FaultValues(i,6));
                fprintf(fidascii,'Dip_of_fault_section(deg):           %10.4e\n',app.FaultValues(i,7));
                fprintf(fidascii,'SlipRate_of_fault_section(mm/yr):    %10.4e\n',app.FaultValues(i,21));
                fprintf(fidascii,'Rake_of_fault_section(deg):          %10.4e\n',app.FaultValues(i,22));
                fprintf(fidascii,'StressRate_of_fault_section(MPa/yr): %10.4e\n',app.FaultValues(i,23));
                fprintf(fidascii,'Shift_of_lowerleft_vertex_(E/N/Z)(km):    %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,8),app.FaultValues(i,9),app.FaultValues(i,10));
                fprintf(fidascii,'Shift_of_lowerright_vertex_(E/N/Z)(km):   %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,11),app.FaultValues(i,12),app.FaultValues(i,13));
                fprintf(fidascii,'Shift_of_upperright_vertex_(E/N/Z)(km):   %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,14),app.FaultValues(i,15),app.FaultValues(i,16));
                fprintf(fidascii,'Shift_of_upperleft_vertex_(E/N/Z)(km):    %10.4e   %10.4e   %10.4e\n',app.FaultValues(i,17),app.FaultValues(i,18),app.FaultValues(i,19));
                for ii = 1 : 1 : GridNumber
                    FltTrigs        = app.GriddedFault(ii).TriangleList(app.GriddedFault(ii).TriangleList(:,4) == i,1:3);
                    PatchesPerFault = size(FltTrigs,1);
                    VertsOfFaults   = unique(FltTrigs(:));
                    VertsPerFault   = size(VertsOfFaults,1);
                    fprintf(fidascii,'Grid_%d:__Triangle_patch_number_of_section:   %d\n',ii, PatchesPerFault);
                    fprintf(fidascii,'Grid_%d:__Triangle_vertex_number_of_section:  %d\n',ii, VertsPerFault);
                end
                fprintf(fidascii,'\n');      
            end
            fclose(fidascii);
            %------------------------------------------
            if (app.FaultWasGridded == 1)
                fidbin    = fopen(Name4binary ,'wb'); 
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
                for (ii = 1 : GridNumber)
                    
                    FltIDnum   = size(unique(app.GriddedFault(ii).TriangleList(:,5)),1);
                    ZeroRMSvals= zeros(FltIDnum,1);
                    fwrite(fidbin,size(app.GriddedFault(ii).TriangleList,1),'int32');
                    fwrite(fidbin,size(app.GriddedFault(ii).VertexList,1),  'int32');
                    fwrite(fidbin,app.GriddedFault(ii).TriangleList(:,1),   'int32');
                    fwrite(fidbin,app.GriddedFault(ii).TriangleList(:,2),   'int32');
                    fwrite(fidbin,app.GriddedFault(ii).TriangleList(:,3),   'int32');
                    fwrite(fidbin,app.GriddedFault(ii).TriangleList(:,4),   'int32'); %running seg number
                    fwrite(fidbin,app.GriddedFault(ii).TriangleList(:,5),   'int32'); %assigned fault ID
                    fwrite(fidbin,app.GriddedFault(ii).VertexList(:,1),     'single');
                    fwrite(fidbin,app.GriddedFault(ii).VertexList(:,2),     'single');    
                    fwrite(fidbin,app.GriddedFault(ii).GlobalVertices(:,1), 'single');
                    fwrite(fidbin,app.GriddedFault(ii).GlobalVertices(:,2), 'single');    
                    fwrite(fidbin,app.GriddedFault(ii).GlobalVertices(:,3), 'single'); 
                    fwrite(fidbin,app.GriddedFault(ii).GlobTrigCentr(:,1),  'single');
                    fwrite(fidbin,app.GriddedFault(ii).GlobTrigCentr(:,2),  'single');    
                    fwrite(fidbin,app.GriddedFault(ii).GlobTrigCentr(:,3),  'single');  
         
                    maxdepth = max(app.GriddedFault(ii).GlobalVertices(:,3));
                    maxdepth2= max(app.GriddedFault(ii).GlobTrigCentr(:,3));
                    
                    
                    fwrite(fidbin,FltIDnum,                                 'int32');
                    fwrite(fidbin,ZeroRMSvals(:,1),                         'single'); % length is fault number
                end
                fclose(fidbin);    
            end
            %------------------------------------------%------------------------------------------
            if (size(app.BoundaryValues,1) > 0)
                BndNum      = size(app.BoundaryValues,1);
                GridNumber  = size(app.GriddedBound,2);
                
                if (isfield(app.GriddedBound,'TriangleList') == 0),         GridNumber = 0;          end
                Name4ascii  = strcat(CompletePath,'_BND.txt');
                Name4binary = strcat(CompletePath,'_BNDtrig.dat');
                %----------------------------------------------------------------
                fidascii    = fopen(Name4ascii ,'w');
                if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
                
                fprintf(fidascii,'Number_of_boundary_segments:  %d\n',BndNum);
                fprintf(fidascii,'Number_of_grids_generated: %d\n',GridNumber);
                for ii = 1 : 1 : GridNumber
                    fprintf(fidascii,'Grid_%d:__Combined_number_of_trig_patches_and_vertices:  %d   %d\n',ii, size(app.GriddedBound.TriangleList,1), size(app.GriddedBound.VertexList,1));
                    fprintf(fidascii,'Grid_%d:__Mean_leg_length_and_area:  %10.4e  %10.4e\n',ii, app.GriddedBound.meanLegLengths, app.GriddedBound.meanPatchAreas);
                end
                fprintf(fidascii,'\n');   
                %--------------------------------------
                for i = 1 : BndNum
                    fprintf(fidascii,'Fault_section_name: %s\n',              app.BoundaryNames{1,i});
                    fprintf(fidascii,'Easting_of_fault_top_center(km):  %10.4e\n',app.BoundaryValues(i,1));
                    fprintf(fidascii,'Northing_of_fault_top_center(km): %10.4e\n',app.BoundaryValues(i,2));
                    fprintf(fidascii,'Depth_of_fault_top_center(km):    %10.4e\n',app.BoundaryValues(i,3));
                    fprintf(fidascii,'Length_of_fault_section(km):      %10.4e\n',app.BoundaryValues(i,4));
                    fprintf(fidascii,'Width_of_fault_section(km):       %10.4e\n',app.BoundaryValues(i,5));
                    fprintf(fidascii,'Strike_of_fault_section(deg):     %10.4e\n',app.BoundaryValues(i,6));
                    fprintf(fidascii,'Dip_of_fault_section(deg):        %10.4e\n',app.BoundaryValues(i,7));

                    for ii = 1 : 1 : GridNumber
                        BndTrigs        = app.GriddedBound.TriangleList(app.GriddedBound.TriangleList(:,4) == i,1:3);
                        PatchesPerBound = size(BndTrigs,1);
                        VertsOfBound    = unique(BndTrigs(:));                          
                        VertsPerBound   = size(VertsOfBound,1);
                        fprintf(fidascii,'Grid_%d:__Triangle_patch_number_of_boundary:   %d\n',ii, PatchesPerBound);
                        fprintf(fidascii,'Grid_%d:__Triangle_vertex_number_of_boundary:: %d\n',ii, VertsPerBound);
                    end
                    fprintf(fidascii,'\n');      
                end
                fclose(fidascii);
                %--------------------------------------
                if (app.FaultWasGridded == 1)
                    fidbin    = fopen(Name4binary ,'wb'); 
                    if (fidbin == -1), errordlg('The entered filename has not been found.');    return;         end
     
                    fwrite(fidbin,size(app.GriddedBound.TriangleList,1),'int32');
                    fwrite(fidbin,size(app.GriddedBound.VertexList,1),  'int32');
                    fwrite(fidbin,app.GriddedBound.TriangleList(:,1),   'int32');
                    fwrite(fidbin,app.GriddedBound.TriangleList(:,2),   'int32');
                    fwrite(fidbin,app.GriddedBound.TriangleList(:,3),   'int32');
                    fwrite(fidbin,app.GriddedBound.TriangleList(:,4),   'int32');
                    fwrite(fidbin,app.GriddedBound.VertexList(:,1),     'single');
                    fwrite(fidbin,app.GriddedBound.VertexList(:,2),     'single');    
                    fwrite(fidbin,app.GriddedBound.GlobalVertices(:,1), 'single');
                    fwrite(fidbin,app.GriddedBound.GlobalVertices(:,2), 'single');    
                    fwrite(fidbin,app.GriddedBound.GlobalVertices(:,3), 'single'); 
                    fwrite(fidbin,app.GriddedBound.GlobTrigCentr(:,1),  'single');
                    fwrite(fidbin,app.GriddedBound.GlobTrigCentr(:,2),  'single');    
                    fwrite(fidbin,app.GriddedBound.GlobTrigCentr(:,3),  'single');  

                    fclose(fidbin);    
                end
            end
        end

        % Button pushed function: LoadMeshAndModel_Button
        function LoadMeshAndModel_ButtonPushed(app, event)
            [DataName,DataPath]  = uigetfile('*_FLT.txt','Load Fault Model (with triangulation if done already)');
            if     (DataName ~=0),      DataName = extractBefore(DataName,'_FLT.txt');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            Name4binary = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            %----------------------------------
            nextline               = fgetl(fidascii);               FltSectionNum          = sscanf(nextline,'%*s %d');  
            nextline               = fgetl(fidascii);               GridNumber             = sscanf(nextline,'%*s %d');
            app.FaultValues        = nan(FltSectionNum,23);         app.FaultNames         = cell(1,FltSectionNum);
            %-----------------------%-----------------------%-----------------------
            for ii = 1 : 1 : GridNumber
                nextline                              = fgetl(fidascii);             nextvals                            = sscanf(nextline,'%*s %d %d');
                app.GriddedFault(ii).SizeTrigList     = nextvals(1,1);               app.GriddedFault(ii).SizeVertList   = nextvals(2,1);
                nextline                              = fgetl(fidascii);             nextvals                            = sscanf(nextline,'%*s %e %e');
                app.GriddedFault(ii).meanLegLengths   = nextvals(1,1);               app.GriddedFault(ii).meanPatchAreas = nextvals(2,1);   
                %----------------------
                app.GriddedFault(ii).TriangleList    = [];
                app.GriddedFault(ii).VertexList      = [];
                app.GriddedFault(ii).GlobalVertices  = [];
                %----------------------
            end
            nextline                = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            for i = 1 : FltSectionNum
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,20)    = sscanf(nextline,'%*s %d');
                FltSecName          = extractAfter(fgetl(fidascii)," ");                     app.FaultNames{1,i}      = FltSecName;
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,7)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,21)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,22)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,23)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,8:10)  = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,11:13) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,14:16) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,17:19) = sscanf(nextline,'%*s %e %e %e')';
                
                for ii = 1 : 1 : GridNumber
                    nextline            = fgetl(fidascii);           %not needed      
                    nextline            = fgetl(fidascii);           %not needed      
                end
                nextline            = fgetl(fidascii);
            end
            fclose(fidascii);
            %-----------------------%-----------------------%-----------------------
            if (GridNumber > 0)
                app.FaultWasGridded = 1;
                %-----------------------
                for (ii = 1 : 1 : GridNumber)
                    app.GriddedFault(ii).TriangleList    = zeros(app.GriddedFault(ii).SizeTrigList,5);
                    app.GriddedFault(ii).GlobTrigCentr   = zeros(app.GriddedFault(ii).SizeTrigList,3);
                    app.GriddedFault(ii).VertexList      = zeros(app.GriddedFault(ii).SizeVertList,2);
                    app.GriddedFault(ii).GlobalVertices  = zeros(app.GriddedFault(ii).SizeVertList,3);
                end
                %-----------------------
                fidbin    = fopen(Name4binary ,'rb');
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
                %-----------------------
                for (ii = 1 : GridNumber)
                    PatchNum = fread(fidbin,1,'int');
                    VertNum  = fread(fidbin,1,'int');
                    if ((PatchNum ~= app.GriddedFault(ii).SizeTrigList) || (VertNum ~= app.GriddedFault(ii).SizeVertList))
                       disp('patch and/or vertex numbers are not consistent');
                       return;
                    end
                    app.GriddedFault(ii).TriangleList(:,1)   = fread(fidbin,PatchNum,'int32');
                    app.GriddedFault(ii).TriangleList(:,2)   = fread(fidbin,PatchNum,'int32');
                    app.GriddedFault(ii).TriangleList(:,3)   = fread(fidbin,PatchNum,'int32');
                    app.GriddedFault(ii).TriangleList(:,4)   = fread(fidbin,PatchNum,'int32');
                    app.GriddedFault(ii).TriangleList(:,5)   = fread(fidbin,PatchNum,'int32');
                    app.GriddedFault(ii).VertexList(:,1)     = fread(fidbin,VertNum,'single');
                    app.GriddedFault(ii).VertexList(:,2)     = fread(fidbin,VertNum,'single');
                    app.GriddedFault(ii).GlobalVertices(:,1) = fread(fidbin,VertNum,'single');
                    app.GriddedFault(ii).GlobalVertices(:,2) = fread(fidbin,VertNum,'single');
                    app.GriddedFault(ii).GlobalVertices(:,3) = fread(fidbin,VertNum,'single');
                    app.GriddedFault(ii).GlobTrigCentr(:,1)  = fread(fidbin,PatchNum,'single');
                    app.GriddedFault(ii).GlobTrigCentr(:,2)  = fread(fidbin,PatchNum,'single');
                    app.GriddedFault(ii).GlobTrigCentr(:,3)  = fread(fidbin,PatchNum,'single');    
                    
                    TempBlahValue = fread(fidbin,1,            'int32');
                    TempBlahValue = fread(fidbin,TempBlahValue,'single');
                end

                fclose(fidbin);
                %-----------------------
                for (ii = 1 : GridNumber)
                    SizeTrigListFault{ii,1} = num2str(app.GriddedFault(ii).SizeTrigList);
                end
                app.FaultActualTrig_ListBox.Items     = SizeTrigListFault;
                app.FaultActualTrig_ListBox.ItemsData = 1:GridNumber;
                app.FaultActualTrig_ListBox.Value     = 1;
               
            end
            %-----------------------%-----------------------%-----------------------
            SelVertex                                 = str2double(app.FaultVertexPos_DropDown.Value);
            app.FaultName_EditField.Value             = app.FaultNames{1};
            app.FaultNorthing_EditField.Value         = app.FaultValues(1,1);
            app.FaultEasting_EditField.Value          = app.FaultValues(1,2);
            app.FaultDepth_EditField.Value            = app.FaultValues(1,3);
            app.FaultLength_EditField.Value           = app.FaultValues(1,4);
            app.FaultWidth_EditField.Value            = app.FaultValues(1,5);
            app.FaultStrike_EditField.Value           = app.FaultValues(1,6);
            app.FaultDip_EditField.Value              = app.FaultValues(1,7);            
            
            app.FaultVertexPos_Strike_EditField.Value = app.FaultValues(1,8+(SelVertex-1)*3);
            app.FaultVertexPos_Dip_EditField.Value    = app.FaultValues(1,9+(SelVertex-1)*3);
            app.FaultVertexPos_Norm_EditField.Value   = app.FaultValues(1,10+(SelVertex-1)*3);
            
            app.FaultID_EditField.Value               = app.FaultValues(1,20);
            app.FaultSlipRate_EditField.Value         = app.FaultValues(1,21);
            app.FaultSlipRake_EditField.Value         = app.FaultValues(1,22);
            app.FaultStressRate_EditField.Value       = app.FaultValues(1,23);  
            %-----------------------%-----------------------%-----------------------
            app.Fault_ListBox.Items     = app.FaultNames;
            app.Fault_ListBox.ItemsData = 1:size(app.FaultNames,2);
            app.Fault_ListBox.Value     = size(app.FaultNames,2);
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%-----------------------
            % look for these files:
            Name4ascii  = strcat(CompletePath,'_BND.txt');
            Name4binary = strcat(CompletePath,'_BNDtrig.dat');
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1),            return;                     end
            %----------------------------------
            nextline               = fgetl(fidascii);               BndSectionNum          = sscanf(nextline,'%*s %d');  
            nextline               = fgetl(fidascii);               GridNumber             = sscanf(nextline,'%*s %d');
            app.BoundaryValues     = nan(BndSectionNum,7);          app.BoundaryNames      = cell(1,BndSectionNum);
            %-----------------------%-----------------------%-----------------------
            if (GridNumber > 0)
                nextline                          = fgetl(fidascii);             nextvals                        = sscanf(nextline,'%*s %d %d');
                app.GriddedBound.SizeTrigList     = nextvals(1,1);               app.GriddedBound.SizeVertList   = nextvals(2,1);
                nextline                          = fgetl(fidascii);             nextvals                        = sscanf(nextline,'%*s %e %e');
                app.GriddedBound.meanLegLengths   = nextvals(1,1);               app.GriddedBound.meanPatchAreas = nextvals(2,1);   
                %----------------------
                app.GriddedBound.TriangleList    = [];
                app.GriddedBound.VertexList      = [];
                app.GriddedBound.GlobalVertices  = [];
                %----------------------
            end
            nextline                = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            for i = 1 : BndSectionNum
                BndSecName          = extractAfter(fgetl(fidascii)," ");                     app.BoundaryNames{1,i}      = BndSecName;
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.BoundaryValues(i,7)     = sscanf(nextline,'%*s %e');
                
                if (GridNumber > 0)
                    nextline        = fgetl(fidascii);           %not needed      
                    nextline        = fgetl(fidascii);           %not needed      
                end
                nextline            = fgetl(fidascii);
            end
            fclose(fidascii);
            %-----------------------%-----------------------%-----------------------
            if (GridNumber > 0)
                %-----------------------
                app.GriddedBound.TriangleList    = zeros(app.GriddedBound.SizeTrigList,4);
                app.GriddedBound.GlobTrigCentr   = zeros(app.GriddedBound.SizeTrigList,3);
                app.GriddedBound.VertexList      = zeros(app.GriddedBound.SizeVertList,2);
                app.GriddedBound.GlobalVertices  = zeros(app.GriddedBound.SizeVertList,3);
                %-----------------------
                fidbin    = fopen(Name4binary ,'rb');
                if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
                %-----------------------%-----------------------
                PatchNum = fread(fidbin,1,'int32');
                VertNum  = fread(fidbin,1,'int32');
                if ((PatchNum ~= app.GriddedBound.SizeTrigList) || (VertNum ~= app.GriddedBound.SizeVertList))
                   disp('patch and/or vertex numbers are not consistent');
                   return;
                end
                %-----------------------%-----------------------
                app.GriddedBound.TriangleList(:,1)   = fread(fidbin,PatchNum,'int32');
                app.GriddedBound.TriangleList(:,2)   = fread(fidbin,PatchNum,'int32');
                app.GriddedBound.TriangleList(:,3)   = fread(fidbin,PatchNum,'int32');
                app.GriddedBound.TriangleList(:,4)   = fread(fidbin,PatchNum,'int32');
                app.GriddedBound.VertexList(:,1)     = fread(fidbin,VertNum,'single');
                app.GriddedBound.VertexList(:,2)     = fread(fidbin,VertNum,'single');
                app.GriddedBound.GlobalVertices(:,1) = fread(fidbin,VertNum,'single');
                app.GriddedBound.GlobalVertices(:,2) = fread(fidbin,VertNum,'single');
                app.GriddedBound.GlobalVertices(:,3) = fread(fidbin,VertNum,'single');
                app.GriddedBound.GlobTrigCentr(:,1)  = fread(fidbin,PatchNum,'single');
                app.GriddedBound.GlobTrigCentr(:,2)  = fread(fidbin,PatchNum,'single');
                app.GriddedBound.GlobTrigCentr(:,3)  = fread(fidbin,PatchNum,'single');    
                %-----------------------%-----------------------
                fclose(fidbin);
                %-----------------------%-----------------------
                for (ii = 1 : GridNumber)
                    SizeTrigListFault{ii,1} = num2str(app.GriddedFault(ii).SizeTrigList);
                end
                app.ActualTrigBound_EditField.Value   = app.GriddedBound.SizeTrigList;
            end
            %-----------------------%-----------------------%-----------------------
            app.BoundName_EditField.Value         = app.BoundaryNames{1};
            app.BoundNorthing_EditField.Value     = app.BoundaryValues(1,1);
            app.BoundEasting_EditField.Value      = app.BoundaryValues(1,2);
            app.BoundDepth_EditField.Value        = app.BoundaryValues(1,3);
            app.BoundLength_EditField.Value       = app.BoundaryValues(1,4);
            app.BoundWidth_EditField.Value        = app.BoundaryValues(1,5);
            app.BoundStrike_EditField.Value       = app.BoundaryValues(1,6);
            app.BoundDip_EditField.Value          = app.BoundaryValues(1,7);            
            %-----------------------%-----------------------%-----------------------
            app.Bound_ListBox.Items     = app.BoundaryNames;
            app.Bound_ListBox.ItemsData = 1:size(app.BoundaryNames,2);
            app.Bound_ListBox.Value     = size(app.BoundaryNames,2);
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%-----------------------
            if (size(app.BoundaryValues,1) >0)
                app.Move2_1stFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_1stFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = [app.FaultNames,app.BoundaryNames];
                app.Move2_2ndFault_DropDown.ItemsData = 1 : (size(app.FaultValues,1)+size(app.BoundaryValues,1));
                app.Move2_2ndFault_DropDown.Value     = 1;
            else
                app.Move2_1stFault_DropDown.Items     = app.FaultNames;
                app.Move2_1stFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_1stFault_DropDown.Value     = 1;
                app.Move2_2ndFault_DropDown.Items     = app.FaultNames;
                app.Move2_2ndFault_DropDown.ItemsData = 1 : size(app.FaultValues,1);
                app.Move2_2ndFault_DropDown.Value     = 1;
            end
            %-----------------------%-----------------------%-----------------------     
        end

        % Button pushed function: LoadPara_Button
        function LoadPara_ButtonPushed(app, event)
            %-------------------------------------
            SelGrid    = app.FaultActualTrig_ListBox.Value;
            if (~isa(SelGrid,'double')),     SelGrid = str2double(SelGrid);          end
            %-------------------------------------
            [LoadName,LoadPath] = uigetfile('*PreRunData.dat','Load selected parameter');
            if     (LoadName ~=0),      ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            fidbin    = fopen(ParaPath ,'rb');      
            if (fidbin == -1), errordlg('The entered filename has not been found.');    return;   end
            %-------------------------------------
            PatchNum = fread(fidbin,1,'int');
            if (PatchNum ~= app.GriddedFault(SelGrid).SizeTrigList) 
                disp('patch numbers are not consistent');
                return;
            end
            %-------------------------------------
            app.ExtraParamter = zeros(PatchNum,8);
            %-------------------------------------
            app.ExtraParamter(:,1)   = fread(fidbin,PatchNum,'int32'); %fault ID
            app.ExtraParamter(:,2)   = fread(fidbin,PatchNum,'int32'); %flagged status
            app.ExtraParamter(:,3)   = fread(fidbin,PatchNum,'single'); %stressing strike
            app.ExtraParamter(:,4)   = fread(fidbin,PatchNum,'single'); %stressin dip   
            app.ExtraParamter(:,5)   = sqrt(app.ExtraParamter(:,3).^2 +app.ExtraParamter(:,4).^2);
            app.ExtraParamter(:,6)   = fread(fidbin,PatchNum,'single'); %strength initial
            app.ExtraParamter(:,7)   = fread(fidbin,PatchNum,'single'); %stress drop initial
            app.ExtraParamter(:,8)   = fread(fidbin,PatchNum,'single'); %D_c initial
            %-------------------------------------
            PatchNum2 = fread(fidbin,1,'int');
            if (PatchNum2 ~= app.GriddedBound.SizeTrigList) 
                disp('patch numbers are not consistent');
                return;
            end
            app.ExtraParamter2 = zeros(PatchNum2,4);
            %-------------------------------------
            app.ExtraParamter2(:,1)   = fread(fidbin,PatchNum2,'single'); % slip in strike
            app.ExtraParamter2(:,2)   = fread(fidbin,PatchNum2,'single'); % slip in dip
            app.ExtraParamter2(:,3)   = fread(fidbin,PatchNum2,'single'); % slip in open direction
            app.ExtraParamter2(:,4)   = sqrt(app.ExtraParamter2(:,1).*app.ExtraParamter2(:,1) +app.ExtraParamter2(:,2).*app.ExtraParamter2(:,2) +app.ExtraParamter2(:,3).*app.ExtraParamter2(:,3));
            %-------------------------------------
            fclose(fidbin);
        end

        % Button pushed function: PlotPara_Button
        function PlotPara_ButtonPushed(app, event)
         
            %---------------------------------------- 
            FigNum    = app.FigNumFaultMesh_EditField.Value*10+1; 
            ViewA     = app.ViewAngle_EditField.Value;                      ViewA     = sscanf(ViewA,'%f')';
            SelPara   = app.PreCatalogParameter2Plot_DropDown.Value;        SelPara   = str2double(SelPara); 
            %----------------------------------------         
            figure(FigNum);  hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            %-----------------------------------------------
            if (SelPara <= 8)
                SelectedGrid    = app.FaultActualTrig_ListBox.Value;
                if (~isa(SelectedGrid,'double')),     SelectedGrid = str2double(SelectedGrid);          end
                
                PlottedComp     = app.ExtraParamter(:,SelPara);
                MeanValue       = mean(PlottedComp)
                AllV1s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
                AllV2s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
                AllV3s          = zeros(app.GriddedFault(SelectedGrid).SizeTrigList,3);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.GriddedFault(SelectedGrid).TriangleList,1)
                    AllV1s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),1);
            
                    AllV2s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),2);
            
                    AllV3s(Cntr,1) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault(SelectedGrid).GlobalVertices(app.GriddedFault(SelectedGrid).TriangleList(j,3),3);
                    Cntr           = Cntr +1;
                end
            elseif (SelPara > 8)
                PlottedComp     = app.ExtraParamter2(:,SelPara-8);
                MeanValue       = mean(PlottedComp)
                AllV1s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV2s          = zeros(app.GriddedBound.SizeTrigList,3);
                AllV3s          = zeros(app.GriddedBound.SizeTrigList,3);
                Cntr            = 1;
                %-----------------------------------------------
                for j = 1 : size(app.GriddedBound.TriangleList,1)
                    AllV1s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),1);
                
                    AllV2s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),2);
                
                    AllV3s(Cntr,1) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedBound.GlobalVertices(app.GriddedBound.TriangleList(j,3),3);
                    Cntr           = Cntr +1;
                end
            else
                return;
            end
            %-----------------------------------------------
            if (app.B2RCheckBox.Value == 1)
                colormap(app.BlueWhiteRed);
            else
                colormap(app.Cols4Slip);
            end
            colorbar;
            %-----------------------------
            patch(AllV1s',AllV2s',AllV3s',PlottedComp');             
        end

        % Value changed function: B2RCheckBox
        function B2RCheckBoxValueChanged(app, event)
            app.B2RCheckBox.Value  = 1;
            app.SlipCheckBox.Value = 0;
        end

        % Value changed function: SlipCheckBox
        function SlipCheckBoxValueChanged(app, event)
            app.SlipCheckBox.Value = 1;
            app.B2RCheckBox.Value  = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Fault_ListBox = uilistbox(app.Panel);
            app.Fault_ListBox.Items = {'Fault_1'};
            app.Fault_ListBox.ItemsData = {'1'};
            app.Fault_ListBox.ValueChangedFcn = createCallbackFcn(app, @Fault_ListBoxValueChanged, true);
            app.Fault_ListBox.Value = '1';
            app.AddFault_Button = uibutton(app.Panel, 'push');
            app.AddFault_Button.ButtonPushedFcn = createCallbackFcn(app, @AddFault_ButtonPushed, true);
            app.AddFault_Button.Text = 'Add fault';
            app.DeleteFault_Button = uibutton(app.Panel, 'push');
            app.DeleteFault_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteFault_ButtonPushed, true);
            app.DeleteFault_Button.Text = 'Delete fault';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.FaultName_EditField = uieditfield(app.Panel, 'text');
            app.FaultName_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultName_EditFieldValueChanged, true);
            app.FaultName_EditField.Value = 'Fault_1';
            app.EastingtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.EastingtopcenterkmEditFieldLabel.Text = 'Easting (top center, km)';
            app.FaultEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultEasting_EditFieldValueChanged, true);
            app.NorthingtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.NorthingtopcenterkmEditFieldLabel.Text = 'Northing(top center, km)';
            app.FaultNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditFieldLabel = uilabel(app.Panel);
            app.DepthtopcenterkmEditFieldLabel.Text = 'Depth (top center, km)';
            app.FaultDepth_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultDepth_EditField.Limits = [-Inf 0];
            app.FaultDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultDepth_EditFieldValueChanged, true);
            app.FaultDepth_EditField.Value = -0.0001;
            app.LengthkmEditFieldLabel = uilabel(app.Panel);
            app.LengthkmEditFieldLabel.Text = 'Length (km)';
            app.FaultLength_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultLength_EditField.Limits = [0 Inf];
            app.FaultLength_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultLength_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultLength_EditFieldValueChanged, true);
            app.FaultLength_EditField.Value = 150;
            app.WidthkmEditFieldLabel = uilabel(app.Panel);
            app.WidthkmEditFieldLabel.Text = 'Width (km)';
            app.FaultWidth_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultWidth_EditField.Limits = [0 Inf];
            app.FaultWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultWidth_EditFieldValueChanged, true);
            app.FaultWidth_EditField.Value = 18;
            app.StrikedegEditFieldLabel = uilabel(app.Panel);
            app.StrikedegEditFieldLabel.Text = 'Strike (deg.)';
            app.FaultStrike_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultStrike_EditField.Limits = [0 360];
            app.FaultStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStrike_EditFieldValueChanged, true);
            app.DipdegEditFieldLabel = uilabel(app.Panel);
            app.DipdegEditFieldLabel.Text = 'Dip (deg.)';
            app.FaultDip_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultDip_EditField.Limits = [0 90];
            app.FaultDip_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultDip_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultDip_EditFieldValueChanged, true);
            app.FaultDip_EditField.Value = 90;
            app.FaultIDEditFieldLabel = uilabel(app.Panel);
            app.FaultIDEditFieldLabel.Text = 'Fault ID';
            app.FaultID_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultID_EditField.Limits = [0 Inf];
            app.FaultID_EditField.RoundFractionalValues = 'on';
            app.FaultID_EditField.ValueDisplayFormat = '%d';
            app.FaultID_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultID_EditFieldValueChanged, true);
            app.slipratemmyrEditFieldLabel = uilabel(app.Panel);
            app.slipratemmyrEditFieldLabel.Text = 'slip-rate (mm/yr)';
            app.FaultSlipRate_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultSlipRate_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultSlipRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRate_EditFieldValueChanged, true);
            app.FaultSlipRate_EditField.Value = 10;
            app.sliprakedegEditFieldLabel = uilabel(app.Panel);
            app.sliprakedegEditFieldLabel.Text = 'slip-rake (deg.)';
            app.FaultSlipRake_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultSlipRake_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultSlipRake_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultSlipRake_EditFieldValueChanged, true);
            app.stressingrateMPayrEditFieldLabel = uilabel(app.Panel);
            app.stressingrateMPayrEditFieldLabel.Text = 'stressing rate (MPa/yr)';
            app.FaultStressRate_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultStressRate_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultStressRate_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultStressRate_EditFieldValueChanged, true);
            app.shiftvertexDropDownLabel = uilabel(app.Panel);
            app.shiftvertexDropDownLabel.Text = 'shift vertex';
            app.FaultVertexPos_DropDown = uidropdown(app.Panel);
            app.FaultVertexPos_DropDown.Items = {'Lower left', 'Lower right', 'Upper right', 'Upper left'};
            app.FaultVertexPos_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.FaultVertexPos_DropDown.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_DropDownValueChanged, true);
            app.FaultVertexPos_DropDown.Value = '1';
            app.instrikeEditFieldLabel = uilabel(app.Panel);
            app.instrikeEditFieldLabel.Text = 'in strike';
            app.FaultVertexPos_Strike_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultVertexPos_Strike_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Strike_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Strike_EditFieldValueChanged, true);
            app.indipEditFieldLabel = uilabel(app.Panel);
            app.indipEditFieldLabel.Text = 'in dip';
            app.FaultVertexPos_Dip_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultVertexPos_Dip_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Dip_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Dip_EditFieldValueChanged, true);
            app.outofplaneEditFieldLabel = uilabel(app.Panel);
            app.outofplaneEditFieldLabel.Text = 'out-of-plane';
            app.FaultVertexPos_Norm_EditField = uieditfield(app.Panel, 'numeric');
            app.FaultVertexPos_Norm_EditField.ValueDisplayFormat = '%6.3f';
            app.FaultVertexPos_Norm_EditField.ValueChangedFcn = createCallbackFcn(app, @FaultVertexPos_Norm_EditFieldValueChanged, true);
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNumFaultModel_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNumFaultModel_EditField.Limits = [0 Inf];
            app.FigNumFaultModel_EditField.RoundFractionalValues = 'on';
            app.FigNumFaultModel_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaultModel_EditField.Value = 2;
            app.PlotFaultSetup_Button = uibutton(app.Panel, 'push');
            app.PlotFaultSetup_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotFaultSetup_ButtonPushed, true);
            app.PlotFaultSetup_Button.Text = 'Plot fault setup';
            app.ViewangleEditFieldLabel = uilabel(app.Panel);
            app.ViewangleEditFieldLabel.Text = 'View angle';
            app.ViewAngle_EditField = uieditfield(app.Panel, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Bound_ListBox = uilistbox(app.Panel_2);
            app.Bound_ListBox.Items = {'Boundary_Bottom'};
            app.Bound_ListBox.ItemsData = 1;
            app.Bound_ListBox.ValueChangedFcn = createCallbackFcn(app, @Bound_ListBoxValueChanged, true);
            app.Bound_ListBox.Value = 1;
            app.NameEditField_2Label = uilabel(app.Panel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.BoundName_EditField = uieditfield(app.Panel_2, 'text');
            app.BoundName_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundName_EditFieldValueChanged, true);
            app.BoundName_EditField.Value = 'Boundary_Fault';
            app.EastingtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.EastingtopcenterkmEditField_2Label.Text = 'Easting (top center, km)';
            app.BoundEasting_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundEasting_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundEasting_EditFieldValueChanged, true);
            app.BoundEasting_EditField.Value = -100;
            app.NorthingtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.NorthingtopcenterkmEditField_2Label.Text = 'Northing(top center, km)';
            app.BoundNorthing_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundNorthing_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundNorthing_EditFieldValueChanged, true);
            app.DepthtopcenterkmEditField_2Label = uilabel(app.Panel_2);
            app.DepthtopcenterkmEditField_2Label.Text = 'Depth (top center, km)';
            app.BoundDepth_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundDepth_EditField.Limits = [-Inf 0];
            app.BoundDepth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDepth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDepth_EditFieldValueChanged, true);
            app.BoundDepth_EditField.Value = -20;
            app.LengthkmEditField_2Label = uilabel(app.Panel_2);
            app.LengthkmEditField_2Label.Text = 'Length (km)';
            app.BoundLength_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundLength_EditField.Limits = [0 Inf];
            app.BoundLength_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundLength_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundLength_EditFieldValueChanged, true);
            app.BoundLength_EditField.Value = 400;
            app.WidthkmEditField_2Label = uilabel(app.Panel_2);
            app.WidthkmEditField_2Label.Text = 'Width (km)';
            app.BoundWidth_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundWidth_EditField.Limits = [0 Inf];
            app.BoundWidth_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundWidth_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundWidth_EditFieldValueChanged, true);
            app.BoundWidth_EditField.Value = 200;
            app.StrikedegEditField_2Label = uilabel(app.Panel_2);
            app.StrikedegEditField_2Label.Text = 'Strike (deg.)';
            app.BoundStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundStrike_EditField.Limits = [0 360];
            app.BoundStrike_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundStrike_EditFieldValueChanged, true);
            app.DipdegEditField_2Label = uilabel(app.Panel_2);
            app.DipdegEditField_2Label.Text = 'Dip (deg.)';
            app.BoundDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.BoundDip_EditField.Limits = [0 90];
            app.BoundDip_EditField.ValueDisplayFormat = '%6.3f';
            app.BoundDip_EditField.ValueChangedFcn = createCallbackFcn(app, @BoundDip_EditFieldValueChanged, true);
            app.BoundDip_EditField.Value = 0.01;
            app.DeleteBound_Button = uibutton(app.Panel_2, 'push');
            app.DeleteBound_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteBound_ButtonPushed, true);
            app.DeleteBound_Button.Text = 'Delete fault';
            app.AddBound_Button = uibutton(app.Panel_2, 'push');
            app.AddBound_Button.ButtonPushedFcn = createCallbackFcn(app, @AddBound_ButtonPushed, true);
            app.AddBound_Button.Text = 'Add fault';
            app.LoadPara_Button = uibutton(app.Panel_2, 'push');
            app.LoadPara_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPara_ButtonPushed, true);
            app.LoadPara_Button.Text = 'load para';
            app.PlotPara_Button = uibutton(app.Panel_2, 'push');
            app.PlotPara_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotPara_ButtonPushed, true);
            app.PlotPara_Button.Text = 'plot para';
            app.B2RCheckBox = uicheckbox(app.Panel_2);
            app.B2RCheckBox.ValueChangedFcn = createCallbackFcn(app, @B2RCheckBoxValueChanged, true);
            app.B2RCheckBox.Text = 'B2R';
            app.B2RCheckBox.Value = true;
            app.SlipCheckBox = uicheckbox(app.Panel_2);
            app.SlipCheckBox.ValueChangedFcn = createCallbackFcn(app, @SlipCheckBoxValueChanged, true);
            app.SlipCheckBox.Text = 'Slip';
            app.PreCatalogParameter2Plot_DropDown = uidropdown(app.Panel_2);
            app.PreCatalogParameter2Plot_DropDown.Items = {'Fault ID', 'Flagged', 'Stressing stk', 'Stressing dip', 'Stressing cmb', 'Strength ini', 'Stress drop ini', 'D_c ini', 'Bound_stkSlip', 'Bound_dipSlip', 'Bound_opnSlip', 'Bound_cmbSlip', ''};
            app.PreCatalogParameter2Plot_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.PreCatalogParameter2Plot_DropDown.Value = '5';
            app.loadingprerundatafirstloadfaultmodelLabel = uilabel(app.Panel_2);
            app.loadingprerundatafirstloadfaultmodelLabel.Text = 'loading pre-run data (first load fault model)';
            app.ActualtrigListBoxLabel = uilabel(app.UIFigure);
            app.ActualtrigListBoxLabel.Text = 'Actual trig #';
            app.FaultActualTrig_ListBox = uilistbox(app.UIFigure);
            app.FaultActualTrig_ListBox.Items = {'0'};
            app.FaultActualTrig_ListBox.Value = '0';
            app.Triangulate_Button = uibutton(app.UIFigure, 'push');
            app.Triangulate_Button.ButtonPushedFcn = createCallbackFcn(app, @Triangulate_ButtonPushed, true);
            app.Triangulate_Button.Text = 'Triangulate';
            app.PlotMesh_Button = uibutton(app.UIFigure, 'push');
            app.PlotMesh_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMesh_ButtonPushed, true);
            app.PlotMesh_Button.Text = 'Plot mesh';
            app.FigEditField_2Label = uilabel(app.UIFigure);
            app.FigEditField_2Label.Text = 'Fig #';
            app.FigNumFaultMesh_EditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumFaultMesh_EditField.Limits = [0 Inf];
            app.FigNumFaultMesh_EditField.RoundFractionalValues = 'on';
            app.FigNumFaultMesh_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaultMesh_EditField.Value = 3;
            app.ShowTrigStats_CheckBox = uicheckbox(app.UIFigure);
            app.ShowTrigStats_CheckBox.Text = 'show trig. stats.';
            app.SaveMesh_Button = uibutton(app.UIFigure, 'push');
            app.SaveMesh_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveMesh_ButtonPushed, true);
            app.SaveMesh_Button.Text = 'Save fault triangulatoin';
            app.LoadMeshAndModel_Button = uibutton(app.UIFigure, 'push');
            app.LoadMeshAndModel_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMeshAndModel_ButtonPushed, true);
            app.LoadMeshAndModel_Button.Text = 'Load fault model (w/ or w/o triangulation';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Move_KeepCenterFixed_CheckBox = uicheckbox(app.Panel_3);
            app.Move_KeepCenterFixed_CheckBox.Text = 'keep center fixed';
            app.ShifVertex_tButton = uibutton(app.Panel_3, 'push');
            app.ShifVertex_tButton.ButtonPushedFcn = createCallbackFcn(app, @ShifVertex_tButtonPushed, true);
            app.ShifVertex_tButton.Text = 'Shift';
            app.ofDropDown_2Label = uilabel(app.Panel_3);
            app.ofDropDown_2Label.Text = 'of';
            app.Move2_2ndFault_DropDown = uidropdown(app.Panel_3);
            app.Move2_2ndFault_DropDown.Items = {'Fault_1'};
            app.Move2_2ndFault_DropDown.ItemsData = {'1'};
            app.Move2_2ndFault_DropDown.Value = '1';
            app.MoveLabel = uilabel(app.Panel_3);
            app.MoveLabel.Text = 'Move';
            app.Move2_2ndCorner_DropDown = uidropdown(app.Panel_3);
            app.Move2_2ndCorner_DropDown.Items = {'Lower left', 'Lower right', 'Upper right', 'Upper left'};
            app.Move2_2ndCorner_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.Move2_2ndCorner_DropDown.Value = '1';
            app.toLabel = uilabel(app.Panel_3);
            app.toLabel.Text = 'to';
            app.Move2_1stCorner_DropDown = uidropdown(app.Panel_3);
            app.Move2_1stCorner_DropDown.Items = {'Lower left', 'Lower right', 'Upper right', 'Upper left'};
            app.Move2_1stCorner_DropDown.ItemsData = {'1', '2', '3', '4'};
            app.Move2_1stCorner_DropDown.Value = '1';
            app.ofDropDownLabel = uilabel(app.Panel_3);
            app.ofDropDownLabel.Text = 'of';
            app.Move2_1stFault_DropDown = uidropdown(app.Panel_3);
            app.Move2_1stFault_DropDown.Items = {'Fault_1'};
            app.Move2_1stFault_DropDown.ItemsData = {'1'};
            app.Move2_1stFault_DropDown.Value = '1';
            app.ShiftingthisoneLabel = uilabel(app.Panel_3);
            app.ShiftingthisoneLabel.Text = '<-- Shifting this one -->';
            app.IntendedtrigfaultsEditFieldLabel = uilabel(app.UIFigure);
            app.IntendedtrigfaultsEditFieldLabel.Text = 'Intended trig # (faults)';
            app.IntendedTrigFaults_EditField = uieditfield(app.UIFigure, 'text');
            app.IntendedTrigFaults_EditField.Value = '12000';
            app.IntendedtrigboundLabel = uilabel(app.UIFigure);
            app.IntendedtrigboundLabel.Text = 'Intended trig # (bound.)';
            app.IntendedTrigBound_EditField = uieditfield(app.UIFigure, 'numeric');
            app.IntendedTrigBound_EditField.Limits = [0 Inf];
            app.IntendedTrigBound_EditField.RoundFractionalValues = 'on';
            app.IntendedTrigBound_EditField.ValueDisplayFormat = '%d';
            app.IntendedTrigBound_EditField.Value = 4000;
            app.ActualtrigboundLabel = uilabel(app.UIFigure);
            app.ActualtrigboundLabel.Text = 'Actual trig # (bound.)';
            app.ActualTrigBound_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ActualTrigBound_EditField.Limits = [0 Inf];
            app.ActualTrigBound_EditField.RoundFractionalValues = 'on';
            app.ActualTrigBound_EditField.ValueDisplayFormat = '%d';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A_FaultGeom_Apr2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end