classdef LaDiCaoz_2v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        datainputPanel                  matlab.ui.container.Panel
        LoadDEM_Button                  matlab.ui.control.Button
        smoothdatawboxcaraverageLabel   matlab.ui.control.Label
        UseBoxcarAveraging_DropDown     matlab.ui.control.DropDown
        PlotOverviewhillshade_Button    matlab.ui.control.Button
        FigEditFieldLabel               matlab.ui.control.Label
        OverviewFigNum_EditField        matlab.ui.control.NumericEditField
        LoadPrevMeasurement_Button      matlab.ui.control.Button
        azimuthEditFieldLabel           matlab.ui.control.Label
        OverviewAzimuth_EditField       matlab.ui.control.NumericEditField
        zenithEditFieldLabel            matlab.ui.control.Label
        OverviewZenith_EditField        matlab.ui.control.NumericEditField
        zfactEditFieldLabel             matlab.ui.control.Label
        OverviewZfact_EditField         matlab.ui.control.NumericEditField
        ImportCoordType_DropDown        matlab.ui.control.DropDown
        datadisplayoptionsPanel         matlab.ui.container.Panel
        DefineZoombox_Button            matlab.ui.control.Button
        Drape_CheckBox                  matlab.ui.control.CheckBox
        withLabel                       matlab.ui.control.Label
        ZoomBoxUpperDataSet_DropDown    matlab.ui.control.DropDown
        transparencyandLabel            matlab.ui.control.Label
        ZoomBoxUpperTransp_EditField    matlab.ui.control.NumericEditField
        colormapLabel                   matlab.ui.control.Label
        ZoomBoxUpper_Colormap_DropDown  matlab.ui.control.DropDown
        overLabel                       matlab.ui.control.Label
        withLabel_2                     matlab.ui.control.Label
        ZoomBoxLowerDataSet_DropDown    matlab.ui.control.DropDown
        transparencyandLabel_2          matlab.ui.control.Label
        ZoomBoxLowerTransp_EditField    matlab.ui.control.NumericEditField
        colormapLabel_2                 matlab.ui.control.Label
        ZoomBoxLower_Colormap_DropDown  matlab.ui.control.DropDown
        ZoomBoxInvertUpper_CheckBox     matlab.ui.control.CheckBox
        ZoomBoxInvertLower_CheckBox     matlab.ui.control.CheckBox
        hillshadeoptionsPanel           matlab.ui.container.Panel
        azimuthEditField_2Label         matlab.ui.control.Label
        ZoomBox_azimuth_EditField       matlab.ui.control.NumericEditField
        zenithEditField_2Label          matlab.ui.control.Label
        ZoomBox_zenith_EditField        matlab.ui.control.NumericEditField
        zfactEditField_2Label           matlab.ui.control.Label
        ZoomBox_zfact_EditField         matlab.ui.control.NumericEditField
        slopeoptionsPanel               matlab.ui.container.Panel
        maxslopeLabel                   matlab.ui.control.Label
        ZoomBox_maxslope_EditField      matlab.ui.control.NumericEditField
        minslopeLabel                   matlab.ui.control.Label
        ZoomBox_minslope_EditField      matlab.ui.control.NumericEditField
        contouroptionsPanel             matlab.ui.container.Panel
        minelevLabel                    matlab.ui.control.Label
        ZoomBox_minelevation_EditField  matlab.ui.control.NumericEditField
        maxelevLabel                    matlab.ui.control.Label
        ZoomBox_maxelevation_EditField  matlab.ui.control.NumericEditField
        deltaelevLabel                  matlab.ui.control.Label
        ZoomBox_contourInterval_EditField  matlab.ui.control.NumericEditField
        Plotzoom_Button                 matlab.ui.control.Button
        FigEditField_2Label             matlab.ui.control.Label
        PlotZoom_FigNum_EditField       matlab.ui.control.NumericEditField
        UseBlindMeasurement_CheckBox    matlab.ui.control.CheckBox
        ShowHistogram_Button            matlab.ui.control.Button
        definefaulttraceandcropredprofilePanel  matlab.ui.container.Panel
        TraceFault_Button               matlab.ui.control.Button
        shiftbyEditFieldLabel           matlab.ui.control.Label
        Shiftby_EditField               matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        Right_Button                    matlab.ui.control.Button
        Left_Button                     matlab.ui.control.Button
        Up_Button                       matlab.ui.control.Button
        Down_Button                     matlab.ui.control.Button
        Rotateby_EditField              matlab.ui.control.NumericEditField
        degposvalueCWLabel              matlab.ui.control.Label
        bplacefaultparallelprofilesLabel  matlab.ui.control.Label
        mdistancetofaulttraceREDprofileLabel  matlab.ui.control.Label
        RedProfDistance_EditField       matlab.ui.control.NumericEditField
        mdistancetofaulttraceBLUEprofileLabel  matlab.ui.control.Label
        BlueProfDistance_EditField      matlab.ui.control.NumericEditField
        withLabel_3                     matlab.ui.control.Label
        mwideswatharoundprofilelinesLabel  matlab.ui.control.Label
        ProfileSwatwidth_EditField      matlab.ui.control.NumericEditField
        CropRedprofile_Button           matlab.ui.control.Button
        RotateTrace_Button              matlab.ui.control.Button
        PlotProfiles_Button             matlab.ui.control.Button
        tracelongitudinalprofilesPanel  matlab.ui.container.Panel
        TraceRedFeature_Button          matlab.ui.control.Button
        TraceBlueFeature_Button         matlab.ui.control.Button
        mwideswatharoundfeaturesLabel   matlab.ui.control.Label
        FeatureSwathwidth_EditField     matlab.ui.control.NumericEditField
        withLabel_4                     matlab.ui.control.Label
        FlipLongProfiles_CheckBox       matlab.ui.control.CheckBox
        Plot_LongProfiles_Button        matlab.ui.control.Button
        TraceRedLongProfile_Button      matlab.ui.control.Button
        TraceBlueLongProfile_Button     matlab.ui.control.Button
        findoptimaloffsetviagridsearchPanel  matlab.ui.container.Panel
        Findoffsets_Button              matlab.ui.control.Button
        FigEditField_2Label_2           matlab.ui.control.Label
        FindOffsetFigNum_EditField      matlab.ui.control.NumericEditField
        horizontalshiftfromLabel        matlab.ui.control.Label
        HorzShiftMin_EditField          matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        HorzShiftMax_EditField          matlab.ui.control.NumericEditField
        mwithdxLabel                    matlab.ui.control.Label
        HorzShiftDlt_EditField          matlab.ui.control.NumericEditField
        verticalshiftfromLabel          matlab.ui.control.Label
        VertShiftMin_EditField          matlab.ui.control.NumericEditField
        toLabel_2                       matlab.ui.control.Label
        VertShiftMax_EditField          matlab.ui.control.NumericEditField
        mwithdzLabel                    matlab.ui.control.Label
        VertShiftDlt_EditField          matlab.ui.control.NumericEditField
        verticalstretchfromLabel        matlab.ui.control.Label
        VertStretchMin_EditField        matlab.ui.control.NumericEditField
        toLabel_3                       matlab.ui.control.Label
        VertStretchMax_EditField        matlab.ui.control.NumericEditField
        mwithdLabel                     matlab.ui.control.Label
        VertStretchDlt_EditField        matlab.ui.control.NumericEditField
        horziontalslipLabel             matlab.ui.control.Label
        CalcHorzDisplace_EditField      matlab.ui.control.NumericEditField
        verticalslipLabel_2             matlab.ui.control.Label
        CalcVertDisplace_EditField      matlab.ui.control.NumericEditField
        slipdirLabel                    matlab.ui.control.Label
        BackSlip_direct_DropDown        matlab.ui.control.DropDown
        backslipDEMalongfaulttracePanel  matlab.ui.container.Panel
        BackslipDEM_Button              matlab.ui.control.Button
        horizontalslipLabel             matlab.ui.control.Label
        BackSlip_HorzSlip_EditField     matlab.ui.control.NumericEditField
        verticalslipLabel               matlab.ui.control.Label
        BackSlip_VertSlip_EditField     matlab.ui.control.NumericEditField
        inDropDownLabel                 matlab.ui.control.Label
        BackSlip_2D3D_DropDown          matlab.ui.control.DropDown
        viewaxisEditFieldLabel          matlab.ui.control.Label
        BackSlip_ViewAxis_EditField     matlab.ui.control.EditField
        FigEditField_2Label_3           matlab.ui.control.Label
        BackSlip_FigNum_EditField       matlab.ui.control.NumericEditField
        BackSlip_CleanBS_CheckBox       matlab.ui.control.CheckBox
        measurementresultsandsavingPanel  matlab.ui.container.Panel
        horzLabel                       matlab.ui.control.Label
        Result_MinHorz_EditField        matlab.ui.control.NumericEditField
        vertLabel                       matlab.ui.control.Label
        Result_MinVert_EditField        matlab.ui.control.NumericEditField
        Result_OptHorz_EditField        matlab.ui.control.NumericEditField
        Result_OptVert_EditField        matlab.ui.control.NumericEditField
        Result_MaxHorz_EditField        matlab.ui.control.NumericEditField
        Result_MaxVert_EditField        matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        optLabel                        matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        qualityratingDropDownLabel      matlab.ui.control.Label
        Result_QualRate_DropDown        matlab.ui.control.DropDown
        UTMzoneLabel                    matlab.ui.control.Label
        Result_UTMzone_EditField        matlab.ui.control.NumericEditField
        Result_UTMhemi_DropDown         matlab.ui.control.DropDown
        distancetorefLabel              matlab.ui.control.Label
        Result_Dist2Ref_EditField       matlab.ui.control.NumericEditField
        commentsEditFieldLabel          matlab.ui.control.Label
        Result_Comment_EditField        matlab.ui.control.EditField
        Result_Saveresults_Button       matlab.ui.control.Button
        ScreenOut_Line1_EditField       matlab.ui.control.EditField
        ScreenOut_Line2_EditField       matlab.ui.control.EditField
        ScreenOut_Line3_EditField       matlab.ui.control.EditField
        ScreenOut_Line4_EditField       matlab.ui.control.EditField
        ScreenOut_Line5_EditField       matlab.ui.control.EditField
        ScreenOut_Line6_EditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        %---------------------------------------------------
        ColTopo =[0.070588235294117 	0.211764705882353       0.141176470588235; 0.078431372549019	0.223529411764706	0.152941176470588; 0.090196078431372	0.239215686274510	0.164705882352941; 0.098039215686274	0.250980392156863	0.176470588235294; 0.109803921568627	0.266666666666667	0.188235294117647;...
                  0.117647058823529	0.278431372549020	0.196078431372549; 0.125490196078431	0.294117647058824	0.211764705882353; 0.137254901960784	0.305882352941177	0.219607843137255; 0.145098039215686	0.321568627450980	0.231372549019608; 0.156862745098039	0.333333333333333	0.243137254901961;...
                  0.164705882352941	0.345098039215686	0.254901960784314; 0.172549019607843	0.360784313725490	0.266666666666667; 0.184313725490196	0.376470588235294	0.278431372549020; 0.192156862745098	0.388235294117647	0.290196078431373; 0.203921568627451	0.403921568627451	0.301960784313725;...
                  0.211764705882353	0.415686274509804	0.313725490196078; 0.219607843137255	0.427450980392157	0.325490196078431; 0.231372549019608	0.443137254901961	0.337254901960784; 0.270588235294118	0.470588235294118	0.341176470588235; 0.305882352941177	0.498039215686275	0.341176470588235;...
                  0.345098039215686	0.525490196078431	0.345098039215686; 0.380392156862745	0.552941176470588	0.349019607843137; 0.419607843137255	0.580392156862745	0.352941176470588; 0.458823529411765	0.607843137254902	0.356862745098039; 0.494117647058824	0.635294117647059	0.360784313725490;...
                  0.533333333333333	0.662745098039216	0.360784313725490; 0.568627450980392	0.690196078431373	0.364705882352941; 0.607843137254902	0.717647058823529	0.368627450980392; 0.643137254901961	0.745098039215686	0.372549019607843; 0.682352941176471	0.772549019607843	0.372549019607843;...
                  0.721568627450980	0.800000000000000	0.376470588235294; 0.756862745098039	0.827450980392157	0.380392156862745; 0.796078431372549	0.854901960784314	0.384313725490196; 0.831372549019608	0.882352941176471	0.388235294117647; 0.870588235294118	0.909803921568627	0.388235294117647;...
                  0.909803921568627	0.937254901960784	0.392156862745098; 0.945098039215686	0.964705882352941	0.396078431372549; 0.984313725490196	0.992156862745098	0.400000000000000; 0.956862745098039	0.956862745098039	0.392156862745098; 0.929411764705882	0.917647058823529	0.384313725490196;...
                  0.905882352941177	0.882352941176471	0.380392156862745; 0.878431372549020	0.847058823529412	0.372549019607843; 0.850980392156863	0.807843137254902	0.364705882352941; 0.823529411764706	0.772549019607843	0.356862745098039; 0.796078431372549	0.737254901960784	0.352941176470588;...
                  0.768627450980392	0.701960784313725	0.345098039215686; 0.745098039215686	0.662745098039216	0.337254901960784; 0.717647058823529	0.627450980392157	0.329411764705882; 0.690196078431373	0.588235294117647	0.325490196078431; 0.662745098039216	0.552941176470588	0.317647058823529;...
                  0.635294117647059	0.517647058823530	0.309803921568627; 0.611764705882353	0.482352941176471	0.301960784313725; 0.584313725490196	0.443137254901961	0.298039215686275; 0.556862745098039	0.407843137254902	0.290196078431373; 0.529411764705882	0.372549019607843	0.282352941176471;...
                  0.505882352941176	0.333333333333333	0.274509803921569; 0.478431372549020	0.298039215686275	0.270588235294118; 0.450980392156863	0.262745098039216	0.262745098039216; 0.541176470588235	0.384313725490196	0.384313725490196; 0.631372549019608	0.509803921568627	0.509803921568627;...
                  0.725490196078431	0.631372549019608	0.631372549019608; 0.815686274509804	0.752941176470588	0.752941176470588; 0.909803921568627	0.878431372549020	0.878431372549020; 1	1	1];            
        ColBlu2Red = [ 0.0784    0.1686    0.5490;  0         0         1.0000; 0.0333    0.0333    1.0000; 0.0667    0.0667    1.0000;  0.1000    0.1000    1.0000; 0.1333    0.1333    1.0000;...
                       0.1667    0.1667    1.0000;  0.2000    0.2000    1.0000; 0.2333    0.2333    1.0000; 0.2667    0.2667    1.0000;  0.3000    0.3000    1.0000; 0.3333    0.3333    1.0000;...
                       0.3667    0.3667    1.0000;  0.4000    0.4000    1.0000; 0.4333    0.4333    1.0000; 0.4667    0.4667    1.0000;  0.5000    0.5000    1.0000; 0.5333    0.5333    1.0000;...
                       0.5667    0.5667    1.0000;  0.6000    0.6000    1.0000; 0.6333    0.6333    1.0000; 0.6667    0.6667    1.0000;  0.7000    0.7000    1.0000; 0.7333    0.7333    1.0000;...
                       0.7667    0.7667    1.0000;  0.8000    0.8000    1.0000; 0.8333    0.8333    1.0000; 0.8667    0.8667    1.0000;  0.9000    0.9000    1.0000; 0.9333    0.9333    1.0000;...
                       0.9667    0.9667    1.0000;  1.0000    1.0000    1.0000; 1.0000    0.9677    0.9677; 1.0000    0.9355    0.9355;  1.0000    0.9032    0.9032; 1.0000    0.8710    0.8710;...
                       1.0000    0.8387    0.8387;  1.0000    0.8065    0.8065; 1.0000    0.7742    0.7742; 1.0000    0.7419    0.7419;  1.0000    0.7097    0.7097; 1.0000    0.6774    0.6774;...
                       1.0000    0.6452    0.6452;  1.0000    0.6129    0.6129; 1.0000    0.5806    0.5806; 1.0000    0.5484    0.5484;  1.0000    0.5161    0.5161; 1.0000    0.4839    0.4839;...
                       1.0000    0.4516    0.4516;  1.0000    0.4194    0.4194; 1.0000    0.3871    0.3871; 1.0000    0.3548    0.3548;  1.0000    0.3226    0.3226; 1.0000    0.2903    0.2903;...
                       1.0000    0.2581    0.2581;  1.0000    0.2258    0.2258; 1.0000    0.1935    0.1935; 1.0000    0.1613    0.1613;  1.0000    0.1290    0.1290; 1.0000    0.0968    0.0968;...
                       1.0000    0.0645    0.0645;  1.0000    0.0323    0.0323; 1.0000         0         0; 0.8471    0.1608         0];       
        %---------------------------------------------------            
        Quad_R       = [0,0,0,0,0,0,0,0;0,205,205,205,205,205,205,0;0,205,205,205,205,205,205,0;0,205,205,205,205,205,205,0;0,205,205,205,205,205,205,0;0,205,205,205,205,205,205,0;0,205,205,205,205,205,205,0;0,0,0,0,0,0,0,0];
        Quad_G       = [0,0,0,0,0,0,0,0;0,170,170,170,170,170,170,0;0,170,170,170,170,170,170,0;0,170,170,170,170,170,170,0;0,170,170,170,170,170,170,0;0,170,170,170,170,170,170,0;0,170,170,170,170,170,170,0;0,0,0,0,0,0,0,0];
        Quad_B       = [0,0,0,0,0,0,0,0;0,102,102,102,102,102,102,0;0,102,102,102,102,102,102,0;0,102,102,102,102,102,102,0;0,102,102,102,102,102,102,0;0,102,102,102,102,102,102,0;0,102,102,102,102,102,102,0;0,0,0,0,0,0,0,0];
        Symbol       = [];
        %---------------------------------------------------
        BlindFactor     = 1;
        SaveBSdemAndVert= 0;     
        bracket1 = "]";
        bracket2 = ">";
        %---------------------------------------------------    
        YLLcorner = [];                  XLLcorner = [];
        NCols     = [];                  NRows     = [];
        Cellsize  = [];                  NoDataVal = [];           
        Easting   = [];                  Northing  = [];  
        Earray    = [];                  Narray    = [];
        Elevation = [];  
        dZdx      = [];                  dZdy      = [];
        %---------------------------------------------------    
        ZoomDot1      = [];              ZoomDot2    = [];
        ZoomRectangle = [];              ZoomBoxVals = [];
        ZoomBoxDrawn  = 0;
        %---------------------------------------------------   
        relativeEast1  = [];             relativeEast2   = [];
        relativeNorth1 = [];             relativeNorth2  = [];
        EarrayZoom     = [];             NarrayZoom      = [];
        %---------------------------------------------------
        CutElev        = [];             CutGrad         = [];              CutAsp         = [];
  
        FaultLine      = [];             FaultLineDot1   = [];              FaultLineDot2  = [];   
        FaultLinePts   = [];
        REDprofile     = [];             REDdot          = [];           
        REDpts         = [];             REDBox          = [];
        CropDot1RED    = [];             CropDot2RED     = [];              CropDistRED       = [];
        CropDotPts     = [];
        BLUEprofile    = [];             BLUEdot         = []; 
        BLUEpts        = [];             BLUEBox         = []; 
        REDprofilepts  = [];             BLUEprofilepts = [];
        REDTraceLine   = [];             REDTracePnts    = [];              REDLongBox     = [];
        REDTracePoints = [];
        BLUETraceLine  = [];             BLUETracePnts   = [];              BLUELongBox    = [];            
        BLUETracePoints= [];
        REDLongitudePoints=[];           BLUELongitudePoints=[];
        REDLongProjLine  = [];           REDLongProjPnts  = [];             REDProjPoints   = [];
        BLUELongProjLine = [];           BLUELongProjPnts = [];             BLUEProjPoints  = [];
        BestStretch      = [];           GOFAlongHoriz    = [];             GOFAlongVerti   = [];
        ShiftREDvert     = [];           ShiftREDhorz     = [];             ShftBLUEhorz    = [];
        ShftBLUEvert     = [];
        %---------------------------------------------------     
    end
    
    methods (Access = private)
        function CleanLines(app)
            app.ScreenOut_Line1_EditField.Value = "";               app.ScreenOut_Line2_EditField.Value = "";
            app.ScreenOut_Line3_EditField.Value = "";               app.ScreenOut_Line4_EditField.Value = "";
            app.ScreenOut_Line5_EditField.Value = "";               app.ScreenOut_Line6_EditField.Value = "";
        end


        function CleanLineWork(app)
            if ~isempty(app.FaultLine),        delete(app.FaultLine);           app.FaultLine     = [];  end  
            if ~isempty(app.FaultLineDot1),    delete(app.FaultLineDot1);       app.FaultLineDot1 = [];  end  
            if ~isempty(app.FaultLineDot2),    delete(app.FaultLineDot2);       app.FaultLineDot2 = [];  end  
            if ~isempty(app.REDprofile),       delete(app.REDprofile);          app.REDprofile    = [];  end
            if ~isempty(app.REDdot),           delete(app.REDdot);              app.REDdot        = [];  end
            if ~isempty(app.CropDot1RED),      delete(app.CropDot1RED);         app.CropDot1RED   = [];  end
            if ~isempty(app.CropDot2RED),      delete(app.CropDot2RED);         app.CropDot2RED   = [];  end
            if ~isempty(app.REDBox),           delete(app.REDBox);              app.REDBox        = [];  end 
            if ~isempty(app.BLUEprofile),      delete(app.BLUEprofile);         app.BLUEprofile   = [];  end
            if ~isempty(app.BLUEdot),          delete(app.BLUEdot);             app.BLUEdot       = [];  end  
            if ~isempty(app.BLUEBox),          delete(app.BLUEBox);             app.BLUEBox       = [];  end 
            if ~isempty(app.REDTraceLine),     delete(app.REDTraceLine);        app.REDTraceLine  = [];  end 
            if ~isempty(app.REDTracePnts),     delete(app.REDTracePnts);        app.REDTracePnts  = [];  end 
            if ~isempty(app.REDLongBox),       delete(app.REDLongBox);          app.REDLongBox    = [];  end 
            if ~isempty(app.BLUETraceLine),    delete(app.BLUETraceLine);       app.BLUETraceLine = [];  end 
            if ~isempty(app.BLUETracePnts),    delete(app.BLUETracePnts);       app.BLUETracePnts = [];  end 
            if ~isempty(app.BLUELongBox),      delete(app.BLUELongBox);         app.BLUELongBox   = [];  end 
            
          %  if ~isempty(app.FaultLinePts),       app.FaultLinePts    = [];  end
            if ~isempty(app.REDpts),             app.REDpts          = [];  end
            if ~isempty(app.BLUEpts),            app.BLUEpts         = [];  end 
            if ~isempty(app.REDprofilepts),      app.REDprofilepts   = [];  end
            if ~isempty(app.BLUEprofilepts),     app.BLUEprofilepts  = [];  end
            if ~isempty(app.BLUETracePoints),    app.BLUETracePoints = [];  end 
            if ~isempty(app.REDTracePoints),     app.REDTracePoints  = [];  end 
            
            if ~isempty(app.REDTracePnts),       app.REDTracePnts        = [];  end 
            if ~isempty(app.CropDistRED),        app.CropDistRED         = [];  end 
            if ~isempty(app.CropDotPts),         app.CropDotPts          = [];  end 
            if ~isempty(app.REDLongitudePoints), app.REDLongitudePoints  = []; end
            if ~isempty(app.BLUELongitudePoints),app.BLUELongitudePoints = []; end
        end
        
        function [Lat,Lon] = GetGeoCoordinates(app,Easting,Northing,Zone,Hemi)
            
            sa = 6378137.000000 ; sb = 6356752.314245;
  
            e        = sqrt(1-(sb^2/sa^2));                 e2strich = e^2/(1-e^2);
            k0       = 0.9996;                              long0    = Zone*6 -183;
            X        = 500000 -Easting;
            if (Hemi == 2),                                  Y = 10000000 -Northing;
            else,                                            Y = Northing;
            end
            M        = Y/k0;                                 mu       = M/(sa*(1-e^2/4 -3*e^4/64 -5*e^6/256));
            e1       = (1-sqrt(1-e^2))/(1+sqrt(1-e^2));           
            J1       = (3*e1/2 -27*e1^3/32);                 J2       = (21*e1^2/16 -55*e1^4/32);
            J3       = (151*e1^3/96);                        J4       = (1097*e1^4/512);
            fp       = mu +J1*sin(2*mu) +J2*sin(4*mu) +J3*sin(6*mu) +J4*sin(8*mu);
           
            C1       = e2strich*cos(fp)^2;                   T1       = tan(fp)^2;
            R1       = (sa*(1-e^2))/(1-e^2*sin(fp)^2)^1.5;   N1       = sa/sqrt(1-e^2*sin(fp)^2);
            D        = X/(N1*k0);
           
            Q1       = N1*tan(fp)/R1;                        Q2       = D^2/2;
            Q3       = (5 +3*T1 +10*C1 -4*C1^2 -9*e2strich)*D^4/24;
            Q4       = (61 +90*T1 +298*C1 +45*T1^2 -3*C1^2 -252*e2strich)*D^6/720;
           
            Q5       = D;                                     Q6       = (1 +2*T1 +C1)*D^3/6;
            Q7       = (5-2*C1 +28*T1 -3*C1^2 +8*e2strich +24*T1^2)*D^5/120;
              
           Lat = (fp -Q1*(Q2 -Q3 +Q4))*180/pi;
           Lon = long0 -((Q5 -Q6 +Q7)/cos(fp))*180/pi;


        end
        
        function results = GetCoordinates(app,Lati, Long, UTMzone, UTMhemi)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ProfileSwatwidth_EditField
        function ProfileSwatwidth_EditFieldValueChanged(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            %------------------------------------------
            if ~isempty(app.REDBox)    == 1,       delete(app.REDBox);        app.REDBox  = [];     end 
            if ~isempty(app.BLUEBox)   == 1,       delete(app.BLUEBox);       app.BLUEBox = [];     end 
            %------------------------------------------
            SwathWdth   = app.ProfileSwatwidth_EditField.Value*app.BlindFactor;
            %------------------------------------------
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %------------------------------------------
            RedSt   = app.REDpts(1,:);              RedEd   = app.REDpts(2,:);
            LineVect  = RedSt-RedEd;                LineTrend = atan2(LineVect(1,1),LineVect(1,2));

            dx        = SwathWdth/2.*-cos(LineTrend);     dy        = SwathWdth/2.*sin(LineTrend);
        
            V1             = [(RedSt(1,1) +dx*app.BlindFactor),(RedSt(1,2) +dy*app.BlindFactor)];
            V2             = [(RedEd(1,1) +dx*app.BlindFactor),(RedEd(1,2) +dy*app.BlindFactor)];
            V3             = [(RedSt(1,1) -dx*app.BlindFactor),(RedSt(1,2) -dy*app.BlindFactor)];
            V4             = [(RedEd(1,1) -dx*app.BlindFactor),(RedEd(1,2) -dy*app.BlindFactor)];
            RedVList     = [V1;V2;V4;V3;V1];
            app.REDBox = plot(RedVList(:,1),RedVList(:,2),'-k');
            %------------------------------------------
            BlueSt   = app.BLUEpts(1,:);              BlueEd   = app.BLUEpts(2,:);
            LineVect  = BlueSt-BlueEd;              LineTrend  = atan2(LineVect(1,1),LineVect(1,2));
        
            dx        = SwathWdth/2.*-cos(LineTrend);     dy        = SwathWdth/2.*sin(LineTrend);
        
            V1             = [(BlueSt(1,1) +dx*app.BlindFactor),(BlueSt(1,2) +dy*app.BlindFactor)];
            V2             = [(BlueEd(1,1) +dx*app.BlindFactor),(BlueEd(1,2) +dy*app.BlindFactor)];
            V3             = [(BlueSt(1,1) -dx*app.BlindFactor),(BlueSt(1,2) -dy*app.BlindFactor)];
            V4             = [(BlueEd(1,1) -dx*app.BlindFactor),(BlueEd(1,2) -dy*app.BlindFactor)];
            BlueVList     = [V1;V2;V4;V3;V1];
            app.BLUEBox = plot(BlueVList(:,1),BlueVList(:,2),'-k');
            %------------------------------------------    
        end

        % Value changed function: FeatureSwathwidth_EditField
        function FeatureSwathwidth_EditFieldValueChanged(app, event)
            
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if isempty(app.BLUETracePoints),app.ScreenOut_Line1_EditField.Value = "BLUE longitudinal profile not defined; do that first";          return;     end
            if isempty(app.REDTracePoints), app.ScreenOut_Line1_EditField.Value = "RED longitudinal profile not defined; do that first";          return;     end        
            %------------------------------------------
            SwathWidth = app.FeatureSwathwidth_EditField.Value*app.BlindFactor;
            FigNum     = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %------------------------------------------
            if ~isempty(app.REDLongBox),                delete(app.REDLongBox);             app.REDLongBox = [];        end 
            if ~isempty(app.BLUELongBox),               delete(app.BLUELongBox);            app.BLUELongBox = [];       end 
            BLULongTlst = [];                           REDLongTlst        = [];                      
            %------------------------------------------
            for i = 2 : size(app.BLUETracePoints,1)
                if (app.BLUETracePoints(i-1,3) == 0) && (app.BLUETracePoints(i,3) == 0)
                    
                    BLUESt   = app.BLUETracePoints(i-1,1:2);        BLUEEd    = app.BLUETracePoints(i,1:2);
                    LineVect  = BLUESt-BLUEEd;                      LineTrend = atan2(LineVect(1,1),LineVect(1,2));
                    dx        = SwathWidth/2.*-cos(LineTrend);      dy        = SwathWidth/2.*sin(LineTrend);
                    V1        = [(BLUESt(1,1) +dx*app.BlindFactor),(BLUESt(1,2) +dy*app.BlindFactor)];
                    V2        = [(BLUEEd(1,1) +dx*app.BlindFactor),(BLUEEd(1,2) +dy*app.BlindFactor)];
                    V4        = [(BLUEEd(1,1) -dx*app.BlindFactor),(BLUEEd(1,2) -dy*app.BlindFactor)];
                    V3        = [(BLUESt(1,1) -dx*app.BlindFactor),(BLUESt(1,2) -dy*app.BlindFactor)];
                    UpLgVList = [V1;V2;V4;V3;V1];
                    BLULongT   = plot(UpLgVList(:,1),UpLgVList(:,2),'-k');
                    BLULongTlst= [BLULongTlst; BLULongT];    
                end
            end
            app.BLUELongBox = BLULongTlst;
            %------------------------------------------
            for i = 2 : size(app.REDTracePoints,1)
                if (app.REDTracePoints(i-1,3) == 0) && (app.REDTracePoints(i,3) == 0)
                    
                    REDSt   = app.REDTracePoints(i-1,1:2);        REDEd     = app.REDTracePoints(i,1:2);
                    LineVect  = REDSt-REDEd;                      LineTrend = atan2(LineVect(1,1),LineVect(1,2));
                    dx        = SwathWidth/2.*-cos(LineTrend);    dy        = SwathWidth/2.*sin(LineTrend);
                    V1        = [(REDSt(1,1) +dx*app.BlindFactor),(REDSt(1,2) +dy*app.BlindFactor)];
                    V2        = [(REDEd(1,1) +dx*app.BlindFactor),(REDEd(1,2) +dy*app.BlindFactor)];
                    V4        = [(REDEd(1,1) -dx*app.BlindFactor),(REDEd(1,2) -dy*app.BlindFactor)];
                    V3        = [(REDSt(1,1) -dx*app.BlindFactor),(REDSt(1,2) -dy*app.BlindFactor)];
                    DnLgVList = [V1;V2;V4;V3;V1];
                    REDLongT   = plot(DnLgVList(:,1),DnLgVList(:,2),'-k');
                    REDLongTlst= [REDLongTlst; REDLongT];    
                end
            end
            app.REDLongBox = REDLongTlst;
            %------------------------------------------            
        end

        % Button pushed function: LoadDEM_Button
        function LoadDEM_ButtonPushed(app, event)
            CleanLines(app);
            %---------------------------------------------------------
            app.Symbol  = (cat(3,app.Quad_R,app.Quad_G,app.Quad_B))./255;   
            MovinAv     = str2double(app.UseBoxcarAveraging_DropDown.Value);
            CoordType   = str2double(app.ImportCoordType_DropDown.Value);
            %---------------------------------------------------------
            [DataName,DataPath]  = uigetfile({'*.*'});
            if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
            elseif (DataName ==0),                      return;         
            end
            %---------------------------------------------------------
            if     contains(DataName, '.tif'),          FileType = 2;
            elseif contains(DataName,'.asc'),           FileType = 1;
            elseif contains(DataName,'.mat'),           FileType = 3;
            else 
                app.ScreenOut_Line1_EditField.Value = "Selected data file does not have proper ending... -wrong file type?!";
                return;
            end
            %---------------------------------------------------------
            app.ScreenOut_Line1_EditField.Value = "Importing the DEM..."; drawnow;
            if (FileType == 2) %the Geotiff file
                %---------------------
                [app.Elevation,Info] = geotiffread(CompletePath);
                app.Elevation        = double(app.Elevation);
                app.NCols            = Info.RasterSize(1,2);
                app.NRows            = Info.RasterSize(1,1);
                app.XLLcorner        = Info.XWorldLimits(1,1);
                app.YLLcorner        = Info.YWorldLimits(1,1);
                app.Cellsize         = Info.CellExtentInWorldX;
                app.NoDataVal        = -9999;
                %---------------------
            elseif (FileType == 1) %the ASCII file
                fid = fopen(CompletePath ,'r');
                if (fid == -1), app.ScreenOut_Line2_EditField.Value = "The entered filename has not been found";    return;   end
                %---------------------
                nextline      = fgetl(fid);      app.NCols        = sscanf(nextline,'%*s %f');
                nextline      = fgetl(fid);      app.NRows        = sscanf(nextline,'%*s %f');
                nextline      = fgetl(fid);      app.XLLcorner    = sscanf(nextline,'%*s %f');
                nextline      = fgetl(fid);      app.YLLcorner    = sscanf(nextline,'%*s %f');
                nextline      = fgetl(fid);      app.Cellsize     = sscanf(nextline,'%*s %f');
                nextline      = fgetl(fid);      app.NoDataVal    = sscanf(nextline,'%*s %f');
                app.Elevation = fscanf(fid, '%g',[app.NCols app.NRows]);
                app.Elevation = app.Elevation';
                %---------------------
                fclose(fid);
            elseif (FileType == 3) %the mat file => assumes that I have already the structure provided above...
                load(CompletePath);
                
                app.Elevation = double(Elevation);
                app.NCols     = ncols;
                app.NRows     = nrows;
                app.XLLcorner = xllcorner;
                app.YLLcorner = yllcorner;
                app.Cellsize  = cellsize;
                app.NoDataVal = NODATA_value;
            end
            app.ScreenOut_Line1_EditField.Value = "Importing the DEM... -done"; drawnow;
            app.ScreenOut_Line2_EditField.Value = strcat("Cell size from import:  ",num2str(app.Cellsize)); drawnow;
            %---------------------------------------------------------
            app.Elevation = flipud(app.Elevation); 
            app.Elevation(app.Elevation == app.NoDataVal) = NaN;
            if MovinAv > 1
                app.ScreenOut_Line2_EditField.Value = app.ScreenOut_Line2_EditField.Value + "__used box-car smoothing"; drawnow;
                h             = ones(MovinAv*2-1,MovinAv*2-1)./(MovinAv*2-1)^2;
                app.Elevation = filter2(h, app.Elevation);    
            end   
            %---------------------------------------------------------
            if (CoordType == 2)
                MinInX = app.XLLcorner;         MaxInX = MinInX + app.Cellsize*app.NCols;
                MinInY = app.YLLcorner;         MaxInY = MinInY + app.Cellsize*app.NRows;
                if ((MinInX>=-360)&&(MaxInX<=360)&&(MinInY>=-90)&&(MaxInY<=90))
                    app.ScreenOut_Line3_EditField.Value = "Converting decimal degree data to UTM"; drawnow;
                
                    [xllcorner1,yllcorner1] = GetGeoCoordinates(app.XLLcorner,             app.YLLcorner);
                    [xllcorner2,yllcorner2] = GetGeoCoordinates(app.XLLcorner+app.Cellsize,app.YLLcorner+app.Cellsize);
                    app.Cellsize            = (xllcorner2-xllcorner1) +(yllcorner2-yllcorner1)/2;
                    app.ScreenOut_Line4_EditField.Value = strcat('Cell size from import (in m):  ',num2str(app.Cellsize)); drawnow;
                    app.XLLcorner = xllcorner1;             
                    app.YLLcorner = yllcorner1;
                else
                    app.ScreenOut_Line3_EditField.Value = "decimal degree for input data was selected but data outside of range (longitude must be -360 <= x <= 360; longitude must be -90 <= y <= 90)";   drawnow;
                end
            end
            %---------------------------------------------------------
            app.Easting             = app.XLLcorner:app.Cellsize:(app.XLLcorner+(app.NCols-1)*app.Cellsize);       
            app.Northing            = app.YLLcorner:app.Cellsize:(app.YLLcorner+(app.NRows-1)*app.Cellsize);  
            [app.Earray,app.Narray] = meshgrid(app.Easting, app.Northing);
            %---------------------------------------------------------
            app.dZdx      = zeros(size(app.Elevation));
            app.dZdy      = zeros(size(app.Elevation));
            ColBlocks     = ceil(app.NCols/1000);
            RowBlocks     = ceil(app.NRows/1000);
            ColDiff2End   = 1000 -(ColBlocks*1000 -app.NCols);
            RowDiff2End   = 1000 -(RowBlocks*1000 -app.NRows);
            app.ScreenOut_Line4_EditField.Value = ('Calculating topographic gradients'); drawnow;
            for i = 1 : ColBlocks
                if i == ColBlocks,      ColStart =    (i-1)*1000 +1;    ColEnd = ColStart + ColDiff2End -1;           
                else,                   ColStart =    (i-1)*1000 +1;    ColEnd = ColStart + 1000        -1;  
                end
                for j = 1 : RowBlocks
                    if j == RowBlocks,      RowStart =    (j-1)*1000 +1;    RowEnd = RowStart + RowDiff2End -1;           
                    else,                   RowStart =    (j-1)*1000 +1;    RowEnd = RowStart + 1000        -1;  end
            
                    [dZdx, dZdy]      = gradient(app.Elevation(RowStart:RowEnd,ColStart:ColEnd),app.Cellsize,app.Cellsize);
                    app.dZdx(RowStart:RowEnd,ColStart:ColEnd) = dZdx;
                    app.dZdy(RowStart:RowEnd,ColStart:ColEnd) = dZdy;
                    app.ScreenOut_Line5_EditField.Value       = app.ScreenOut_Line5_EditField.Value +"|"; drawnow;
                end
            end
            %---------------------%---------------------    
            
            app.HorzShiftDlt_EditField.Value    = app.Cellsize;
            app.VertShiftDlt_EditField.Value    = app.Cellsize;
            app.ScreenOut_Line6_EditField.Value = "DEM successfully imported"; drawnow;
            %---------------------%---------------------   
        end

        % Button pushed function: PlotOverviewhillshade_Button
        function PlotOverviewhillshade_ButtonPushed(app, event)
            CleanLines(app);
            if isempty(app.Elevation),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded.";       return;     end
            %---------------------%---------------------
            FigNum  = app.OverviewFigNum_EditField.Value;
            Azimuth = app.OverviewAzimuth_EditField.Value;
            Zenith  = app.OverviewZenith_EditField.Value;
            Z_fact  = app.OverviewZfact_EditField.Value;
            %---------------------   
            Azimuth     = Azimuth-90;                               Azimuth(Azimuth>=360) = Azimuth-360;
            Azimuth     = Azimuth.*(pi/180);                        Zenith                = (90-Zenith) * (pi/180); 
            [asp,grad]  = cart2pol(app.dZdy,app.dZdx); 
            grad        = atan(Z_fact*grad); %steepest slope
        
            asp(asp<pi)    = asp(asp<pi)+(pi/2);                    asp(asp<0)            = asp(asp<0)+(2*pi);
            Surf2Show      =  255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
            Surf2Show (Surf2Show <0) = 0; % set hillshade values to min of 0. 
            %---------------------
            figure(FigNum);
            clf;    hold on;    axis equal;
            xlabel('Easting'); ylabel('Northing');
            imagesc(app.Easting,app.Northing,Surf2Show);
            colormap('gray'); 
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            %---------------------
        end

        % Button pushed function: LoadPrevMeasurement_Button
        function LoadPrevMeasurement_ButtonPushed(app, event)
            
        end

        % Button pushed function: DefineZoombox_Button
        function DefineZoombox_ButtonPushed(app, event)
            CleanLines(app);
            if isempty(app.Elevation),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded.";       return;     end
            %---------------------%---------------------
            if ~isempty(app.ZoomDot1),          delete(app.ZoomDot1);            app.ZoomDot1 = [];          end
            if ~isempty(app.ZoomDot2),          delete(app.ZoomDot2);            app.ZoomDot2 = [];          end
            if ~isempty(app.ZoomRectangle),     delete(app.ZoomRectangle);       app.ZoomRectangle = [];     end

            FigNum  = app.OverviewFigNum_EditField.Value;
            figure(FigNum);    hold on;
            %---------------------%---------------------
            pointcounter   = 0;                 TheCorners     = zeros(2,2);
            while pointcounter < 2
                [xi,yi,but] = ginput(1);
                if (but == 1)
                    pointcounter               = pointcounter +1;
                    TheCorners(pointcounter,1) = xi;
                    TheCorners(pointcounter,2) = yi;
                    LLeftX                     = min(TheCorners(:,1));
                    LLeftY                     = min(TheCorners(:,2));
                    if pointcounter == 1
                        app.ZoomDot1 = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','y','MarkerSize',4);
                    elseif pointcounter == 2
                        app.ZoomDot2 = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','y','MarkerSize',4);
                    end
                elseif (but == 27)
                    return;
                end
            end
            app.ZoomRectangle = rectangle('curvature',[0 0],'position',[LLeftX LLeftY abs(TheCorners(2,1)-TheCorners(1,1)) abs(TheCorners(2,2)-TheCorners(1,2))],'linewidth',2); drawnow;
            app.ZoomBoxVals   = TheCorners;
            app.ZoomBoxDrawn  = 1;
            %---------------------%---------------------
            EastLimits        = [TheCorners(1,1),TheCorners(2,1)];
            NorthLimits       = [TheCorners(1,2),TheCorners(2,2)];
            if min(TheCorners(:,1))<min(app.Easting);  EastLimits(1,1)  = min(app.Easting);  else EastLimits(1,1)  = min(TheCorners(:,1)); end
            if max(TheCorners(:,1))>max(app.Easting);  EastLimits(1,2)  = max(app.Easting);  else EastLimits(1,2)  = max(TheCorners(:,1)); end
            if min(TheCorners(:,2))<min(app.Northing); NorthLimits(1,1) = min(app.Northing); else NorthLimits(1,1) = min(TheCorners(:,2)); end
            if max(TheCorners(:,2))>max(app.Northing); NorthLimits(1,2) = max(app.Northing); else NorthLimits(1,2) = max(TheCorners(:,2)); end
            %---------------------
            app.relativeEast1   =1+ceil(((min(EastLimits) - min(app.Easting))/(max(app.Easting)   -min(app.Easting))) *app.NCols);
            app.relativeEast2   = floor(((max(EastLimits) - min(app.Easting))/(max(app.Easting)   -min(app.Easting))) *app.NCols);
            app.relativeNorth1  =1+ceil(((min(NorthLimits)- min(app.Northing))/(max(app.Northing) -min(app.Northing)))*app.NRows);
            app.relativeNorth2  = floor(((max(NorthLimits)- min(app.Northing))/(max(app.Northing) -min(app.Northing)))*app.NRows);
            
            [app.EarrayZoom, app.NarrayZoom] = meshgrid(app.Easting(app.relativeEast1:app.relativeEast2), app.Northing(app.relativeNorth1:app.relativeNorth2));
            [asp,grad]                       = cart2pol(app.dZdy(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2),app.dZdx(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2));      
            %---------------------%---------------------
            app.CutElev = app.Elevation(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2);
            CutSlopemap = atan(grad) .*180/pi;%steepest slope

            app.CutGrad = grad;
            app.CutAsp  = asp;
            %---------------------%---------------------
            app.ZoomBox_minelevation_EditField.Value = min(app.CutElev(:));
            app.ZoomBox_maxelevation_EditField.Value = max(app.CutElev(:));
            app.ZoomBox_minslope_EditField.Value     = min(CutSlopemap(:));
            app.ZoomBox_maxslope_EditField.Value     = max(CutSlopemap(:));
            %----------------------------------
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                app.BlindFactor   = rand(1)*10 +0.1; %gives factor value between 0.1 and 10  
            else
                app.BlindFactor   = 1;
            end
            %---------------------------------- 
        end

        % Button pushed function: Plotzoom_Button
        function Plotzoom_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            %---------------------%---------------------  
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            DrapeSwitch    = app.Drape_CheckBox.Value;
            
            UpperLayerType = str2double(app.ZoomBoxUpperDataSet_DropDown.Value);
            UpperColMap    = str2double(app.ZoomBoxUpper_Colormap_DropDown.Value);
            InvUpperColor  = app.ZoomBoxInvertUpper_CheckBox.Value;
            UpperTransp    = app.ZoomBoxUpperTransp_EditField.Value;
            LowerLayerType = str2double(app.ZoomBoxLowerDataSet_DropDown.Value);
            LowerColMap    = str2double(app.ZoomBoxLower_Colormap_DropDown.Value);
            InvLowerColor  = app.ZoomBoxInvertLower_CheckBox.Value;
            LowerTransp    = app.ZoomBoxLowerTransp_EditField.Value;           
            MinElev        = app.ZoomBox_minelevation_EditField.Value;
            MaxElev        = app.ZoomBox_maxelevation_EditField.Value;
            Intervl        = app.ZoomBox_contourInterval_EditField.Value;
            Azimuth        = app.ZoomBox_azimuth_EditField.Value;
            Zenith         = app.ZoomBox_zenith_EditField.Value;
            Z_fact         = app.ZoomBox_zfact_EditField.Value;
            MinSlope       = app.ZoomBox_minslope_EditField.Value;
            MaxSlope       = app.ZoomBox_maxslope_EditField.Value;
            %---------------------%--------------------- 
            if     (UpperColMap ==1),        ColMap1 = app.ColTopo;
            elseif (UpperColMap ==2),        ColMap1 = app.ColBlu2Red;
            elseif (UpperColMap ==3),        ColMap1 = gray(64);
            elseif (UpperColMap==4),         ColMap1 = parula(64);
            elseif (UpperColMap==5),         ColMap1 = jet(64);
            elseif (UpperColMap==6),         ColMap1 = hsv(64);
            end
            if     (LowerColMap ==1),        ColMap2 = app.ColTopo;
            elseif (LowerColMap ==2),        ColMap2 = app.ColBlu2Red;
            elseif (LowerColMap ==3),        ColMap2 = gray(64);
            elseif (LowerColMap==4),         ColMap2 = parula(64);
            elseif (LowerColMap==5),         ColMap2 = jet(64);
            elseif (LowerColMap==6),         ColMap2 = hsv(64);
            end
            if (InvUpperColor == 1),         ColMap1 = flipud(ColMap1);        end
            if (InvLowerColor == 1),         ColMap2 = flipud(ColMap2);        end   
            if (DrapeSwitch == 0),           CombColMap = ColMap2;
            else,                            CombColMap = [ColMap1;ColMap2];
            end
            %---------------------%---------------------
            CleanLineWork(app);
            %---------------------%---------------------
            figure(FigNum);
            clf; hold on;axis equal;
            xlabel('Easting'); ylabel('Northing');
            colormap(CombColMap);
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            %---------------------%---------------------%---------------------%---------------------
            if (LowerLayerType == 1)
                LowerMap                        = app.CutElev; 
                LowerMap(LowerMap < MinElev)    = MinElev;
                LowerMap(LowerMap > MaxElev)    = MaxElev;                                      
            elseif (LowerLayerType == 2)
                asp            = app.CutAsp;                   grad                  = app.CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                LowerMap       = Hshd; %required for back-slip later on  
            elseif (LowerLayerType == 3)
                LowerMap                      = atan(app.CutGrad).*180/pi; %steepest slope
                LowerMap(LowerMap < MinSlope) = MinSlope;
                LowerMap(LowerMap > MaxSlope) = MaxSlope; 
            elseif (LowerLayerType == 4)
                LowerMap                      = app.CutAsp.*180/pi;  
            elseif (LowerLayerType == 5)
                LowerMap                          = app.CutAsp.*180/pi;
                ScaledSlop                        = atan(app.CutGrad).*180/pi;
                ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                LowerTransp= ScaledSlop./max(ScaledSlop(:)).*LowerTransp;
            end
            if (Intervl > 0)
                MaxVal   = max(LowerMap(:));    MinVal = min(LowerMap(:));
                LowerMap = MinElev + (LowerMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%---------------------%---------------------
            if (UpperLayerType == 1)
                UpperMap                        = app.CutElev; 
                UpperMap(UpperMap < MinElev)    = MinElev;
                UpperMap(UpperMap > MaxElev)    = MaxElev;                     
            elseif (UpperLayerType == 2)
                asp            = app.CutAsp;                   grad                  = app.CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                UpperMap       = Hshd; %required for back-slip later on  
            elseif (UpperLayerType == 3)
                UpperMap                      = atan(app.CutGrad).*180/pi; %steepest slope
                UpperMap(UpperMap < MinSlope) = MinSlope;
                UpperMap(UpperMap > MaxSlope) = MaxSlope; 
            elseif (UpperLayerType == 4)
                UpperMap                      = app.CutAsp.*180/pi;  
            elseif (UpperLayerType == 5)
                UpperMap                          = app.CutAsp.*180/pi;
                ScaledSlop                        = atan(app.CutGrad).*180/pi;
                ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                UpperTransp= ScaledSlop./max(ScaledSlop(:)).*UpperTransp;
            end
            if (Intervl > 0)
                MaxVal   = max(UpperMap(:));    MinVal = min(UpperMap(:));
                UpperMap = MinElev + (UpperMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%---------------------%---------------------
            if (DrapeSwitch == 0)
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',LowerMap,'AlphaData',LowerTransp);
            else
                maxVal   = max(LowerMap(:));    minVal = min(LowerMap(:));
                ScaledLowerMap = round(((LowerMap-minVal)./(maxVal-minVal)).*64)+64;
                ScaledLowerMap(ScaledLowerMap<65)=65;    ScaledLowerMap(ScaledLowerMap>128)=128;
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',ScaledLowerMap,'AlphaData',LowerTransp);
                
                maxVal   = max(UpperMap(:));    minVal = min(UpperMap(:));
                ScaledUpperMap = round(((UpperMap-minVal)./(maxVal-minVal)).*64)+1;
                ScaledUpperMap(ScaledUpperMap(:,:)<1)=1;    ScaledUpperMap(ScaledUpperMap(:,:)>64)=64;
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',ScaledUpperMap,'AlphaData',UpperTransp);
                caxis([min(ScaledUpperMap(:)) max(ScaledLowerMap(:))]);
            end
            %---------------------%---------------------%--------------------- 
            if (Intervl > 0)
                Contnumber = round((MaxElev-MinElev)/Intervl);      
                contour(app.EarrayZoom,app.NarrayZoom,app.CutElev,Contnumber,'LineColor','black','linewidth',0.5);
            end
            %---------------------%---------------------%---------------------
        end

        % Button pushed function: ShowHistogram_Button
        function ShowHistogram_ButtonPushed(app, event)
 
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            %---------------------%---------------------  
            FigNum         = app.PlotZoom_FigNum_EditField.Value *100 +1;

            MinElev        = app.ZoomBox_minelevation_EditField.Value;
            MaxElev        = app.ZoomBox_maxelevation_EditField.Value;
            Azimuth        = app.ZoomBox_azimuth_EditField.Value;
            Zenith         = app.ZoomBox_zenith_EditField.Value;
            Z_fact         = app.ZoomBox_zfact_EditField.Value;
            MinSlope       = app.ZoomBox_minslope_EditField.Value;
            MaxSlope       = app.ZoomBox_maxslope_EditField.Value;
            LowerLayerType = str2double(app.ZoomBoxLowerDataSet_DropDown.Value);
            %---------------------%---------------------        
            figure(FigNum); clf; hold on; 
            %--------------------- 

            if (LowerLayerType == 1)
                LowerMap                        = app.CutElev; 
                LowerMap(LowerMap < MinElev)    = MinElev;
                LowerMap(LowerMap > MaxElev)    = MaxElev;   
                histogram(LowerMap(:),50,'Normalization',"probability");
                xlabel('Elevation values'); ylabel('Frequency');
            elseif (LowerLayerType == 2)
                asp            = app.CutAsp;                   grad                  = app.CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                LowerMap       = Hshd; %required for back-slip later on  
                histogram(LowerMap(:),50,'Normalization',"probability");
                xlabel('Hillshade values'); ylabel('Frequency');
            elseif (LowerLayerType == 3)
                LowerMap                      = atan(app.CutGrad).*180/pi; %steepest slope
                LowerMap(LowerMap < MinSlope) = MinSlope;
                LowerMap(LowerMap > MaxSlope) = MaxSlope; 
                histogram(LowerMap(:),50,'Normalization',"probability");
                xlabel('Slope values'); ylabel('Frequency');
            elseif (LowerLayerType == 4)
                LowerMap                      = app.CutAsp.*180/pi;  
                histogram(LowerMap(:),50,'Normalization',"probability");
                xlabel('Aspect values'); ylabel('Frequency');
            elseif (LowerLayerType == 5)
                LowerMap                          = app.CutAsp.*180/pi;
                ScaledSlop                        = atan(app.CutGrad).*180/pi;
                ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                LowerTransp = ScaledSlop./max(ScaledSlop(:));
                LowerMap    = LowerMap./LowerTransp;
                histogram(LowerMap(:),50,'Normalization',"probability");
                xlabel('Aspect weighted by slope'); ylabel('Frequency');
            end
     
        end

        % Button pushed function: TraceFault_Button
        function TraceFault_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------
            CleanLineWork(app);
            app.ScreenOut_Line1_EditField.Value = 'Click left mouse button to select start and end point of the fault';

            pointcounter   = 0;
            TheCorners     = zeros(2,2);
            while pointcounter < 2
                [xi,yi,but] = ginput(1);
                if (but == 1)
                    pointcounter               = pointcounter +1;
                    TheCorners(pointcounter,1) = xi;
                    TheCorners(pointcounter,2) = yi;
                    if pointcounter == 1
                        app.FaultLineDot1 = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
                    elseif pointcounter == 2
                        app.FaultLineDot2 = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
                    end
                elseif (but == 27)
                    return;
                end
            end
            app.FaultLine      = plot([TheCorners(1,1) TheCorners(2,1)],[TheCorners(1,2) TheCorners(2,2)],'color','cyan','linewidth',2);
            app.FaultLinePts   = TheCorners;
 
            
        end

        % Button pushed function: Right_Button
        function Right_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            ShiftVal = app.Shiftby_EditField.Value;
            if (ShiftVal == 0)                      return;         end   
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------
            CleanLineWork(app);   
            %---------------------------------------
            app.FaultLinePts(:,1) = app.FaultLinePts(:,1) +ShiftVal*app.BlindFactor;

            app.FaultLine      = plot([app.FaultLinePts(1,1) app.FaultLinePts(2,1)],[app.FaultLinePts(1,2) app.FaultLinePts(2,2)],'cyan','linewidth',2);
            app.FaultLineDot1  = plot(app.FaultLinePts(1,1),app.FaultLinePts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2  = plot(app.FaultLinePts(2,1),app.FaultLinePts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);       
        end

        % Button pushed function: Left_Button
        function Left_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            ShiftVal = app.Shiftby_EditField.Value;
            if (ShiftVal == 0)                      return;         end   
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------
            CleanLineWork(app);   
            %---------------------------------------
            app.FaultLinePts(:,1) = app.FaultLinePts(:,1) -ShiftVal*app.BlindFactor;

            app.FaultLine      = plot([app.FaultLinePts(1,1) app.FaultLinePts(2,1)],[app.FaultLinePts(1,2) app.FaultLinePts(2,2)],'cyan','linewidth',2);
            app.FaultLineDot1  = plot(app.FaultLinePts(1,1),app.FaultLinePts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2  = plot(app.FaultLinePts(2,1),app.FaultLinePts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);      
        end

        % Button pushed function: Up_Button
        function Up_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            ShiftVal = app.Shiftby_EditField.Value;
            if (ShiftVal == 0)                      return;         end   
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------
            CleanLineWork(app);   
            %---------------------------------------
            app.FaultLinePts(:,2) = app.FaultLinePts(:,2) +ShiftVal*app.BlindFactor;

            app.FaultLine      = plot([app.FaultLinePts(1,1) app.FaultLinePts(2,1)],[app.FaultLinePts(1,2) app.FaultLinePts(2,2)],'cyan','linewidth',2);
            app.FaultLineDot1  = plot(app.FaultLinePts(1,1),app.FaultLinePts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2  = plot(app.FaultLinePts(2,1),app.FaultLinePts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6); 
        end

        % Button pushed function: Down_Button
        function Down_ButtonPushed(app, event)
           CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            ShiftVal = app.Shiftby_EditField.Value;
            if (ShiftVal == 0)                      return;         end   
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------
            CleanLineWork(app);   
            %---------------------------------------
            app.FaultLinePts(:,2) = app.FaultLinePts(:,2) -ShiftVal*app.BlindFactor;

            app.FaultLine      = plot([app.FaultLinePts(1,1) app.FaultLinePts(2,1)],[app.FaultLinePts(1,2) app.FaultLinePts(2,2)],'cyan','linewidth',2);
            app.FaultLineDot1  = plot(app.FaultLinePts(1,1),app.FaultLinePts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2  = plot(app.FaultLinePts(2,1),app.FaultLinePts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);      
        end

        % Button pushed function: CropRedprofile_Button
        function CropRedprofile_ButtonPushed(app, event)
            
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            %------------------------------------------
            if ~isempty(app.CropDot1RED)  == 1,       delete(app.CropDot1RED);        app.CropDot1RED  = [];     end 
            if ~isempty(app.CropDot2RED)  == 1,       delete(app.CropDot2RED);        app.CropDot2RED  = [];     end 
            if ~isempty(app.CropDistRED),             app.CropDistRED     = [];  end
            %------------------------------------------
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %------------------------------------------
            app.ScreenOut_Line1_EditField.Value = "Click left mouse button to select crop points (start and end point required";
            %------------------------------------------
            RedSt   = app.REDpts(1,:);       RedEd    = app.REDpts(2,:);      
            RedVect = RedEd-RedSt;           Red_m    = RedVect(1,2)/RedVect(1,1);
            Nrm_m   = -1/Red_m;              
            %------------------------------------------
            ThePoints   = zeros(2,2);
            pointcounter = 0;
            while (pointcounter < 2)
                [xi,yi,but] = ginput(1);
                if (but == 1)
                    pointcounter  = pointcounter +1;
                    Crop_x  = xi;                   Crop_y  = yi;
                    r1      = (Crop_y -RedSt(1,2) +Nrm_m*(RedSt(1,1) -Crop_x))/(Red_m-Nrm_m);
                    Pt_x    = RedSt(1,1) +r1;       Pt_y    = RedSt(1,2) +r1*Red_m;
                    Dist1   = sqrt((Pt_x-RedSt(1,1))^2 +(Pt_y-RedSt(1,2))^2);
                    Dist2   = sqrt((Pt_x-RedEd(1,1))^2 +(Pt_y-RedEd(1,2))^2);
                    Dist3   = sqrt((RedSt(1,1)-RedEd(1,1))^2 +(RedSt(1,2)-RedEd(1,2))^2);
                    if ((Dist1 > Dist3)||(Dist2 > Dist3))
                       if  (Dist1 <= Dist2),        Pt_x = RedSt(1,1);      Pt_y = RedSt(1,2);  app.CropDistRED(1,pointcounter) = 0;
                       else,                        Pt_x = RedEd(1,1);      Pt_y = RedEd(1,2);  app.CropDistRED(1,pointcounter) = Dist3;
                       end
                    else
                        app.CropDistRED(1,pointcounter) = Dist1; %always use distance to starting point
                    end
                    ThePoints(pointcounter,1) = xi;
                    ThePoints(pointcounter,2) = yi;
                    
                    if pointcounter == 1
                        app.CropDot1RED =  plot(Pt_x,Pt_y,'o','MarkerEdgeColor','k','MarkerFaceColor','y','MarkerSize',6);
                    else
                        app.CropDot2RED =  plot(Pt_x,Pt_y,'o','MarkerEdgeColor','k','MarkerFaceColor','y','MarkerSize',6);
                    end
                elseif (but == 27)
                    return;
                end
            end
            app.CropDotPts = ThePoints;
            %------------------------------------------
        end

        % Button pushed function: PlotProfiles_Button
        function PlotProfiles_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            %------------------------------------------
            SwathWdth   = app.ProfileSwatwidth_EditField.Value;
            %------------------------------------------
            FigNum    = app.PlotZoom_FigNum_EditField.Value*100+1;
            figure(FigNum); hold on;
            %------------------------------------------

            BLUSt          = app.BLUEpts(1,:);                    REDSt          = app.REDpts(1,:);   
            BLUEd          = app.BLUEpts(2,:);                    REDEd          = app.REDpts(2,:);  
            BLUVect        = BLUEd-BLUSt;                         REDVect        = REDEd-REDSt;
            BLUVect        = BLUVect./sqrt(sum(BLUVect.^2));      REDVect        = REDVect./sqrt(sum(REDVect.^2));   
            BLUNorm        = [BLUVect(1,2),-BLUVect(1,1)];        REDNorm        = [REDVect(1,2),-REDVect(1,1)];
            %-------------------------------------------
            BLUPolyPnts   = [(BLUSt +BLUNorm.*(SwathWdth/2)); (BLUEd +BLUNorm.*(SwathWdth/2)); (BLUEd -BLUNorm.*(SwathWdth/2)); (BLUSt -BLUNorm.*(SwathWdth/2))];
            IsBLUE        = inpolygon(app.EarrayZoom,app.NarrayZoom, BLUPolyPnts(:,1), BLUPolyPnts(:,2));
            BLUEElevation = app.CutElev(IsBLUE);
            BLUEEvalue    = app.EarrayZoom(IsBLUE);
            BLUENvalue    = app.NarrayZoom(IsBLUE);
            BLUEDistance  = sqrt((BLUEEvalue-BLUSt(1,1)).^2 + (BLUENvalue-BLUSt(1,2)).^2);
            BLUEPoints    = [BLUEEvalue(:),BLUENvalue(:),BLUEElevation(:),BLUEDistance(:)];
            %-------------------------------------------
            REDPolyPnts     = [(REDSt +REDNorm .*(SwathWdth/2)); (REDEd +REDNorm .*(SwathWdth/2)); (REDEd -REDNorm .*(SwathWdth/2)); (REDSt -REDNorm .*(SwathWdth/2))];
            IsRED           = inpolygon(app.EarrayZoom,app.NarrayZoom,  REDPolyPnts(:,1),  REDPolyPnts(:,2));
            REDElevation    = app.CutElev(IsRED);
            REDEvalue       = app.EarrayZoom(IsRED);
            REDNvalue       = app.NarrayZoom(IsRED);
            REDDistance     = sqrt((REDEvalue-REDSt(1,1)).^2 + (REDNvalue-REDSt(1,2)).^2);
            REDPoints       = [REDEvalue(:),REDNvalue(:),REDElevation(:),REDDistance(:)];
            %-------------------------------------------
            if ~isempty(app.CropDistRED)
                StartCrop = min(app.CropDistRED);                  EndCrop   = max(app.CropDistRED);
                if (StartCrop ~= EndCrop)                
                    REDPoints = REDPoints(REDPoints(:,4)>=StartCrop,:);
                    REDPoints = REDPoints(REDPoints(:,4)<=EndCrop,:);
                end
            end
            %-------------------------------------------
            figure(FigNum); clf; hold on;
            AxMinX = min(min(BLUEPoints(:,4)),min(REDPoints(:,4)));
            AxMaxX = max(max(BLUEPoints(:,4)),max(REDPoints(:,4)));
            AxMinY = min(min(BLUEPoints(:,3)),min(REDPoints(:,3)));
            AxMaxY = max(max(BLUEPoints(:,3)),max(REDPoints(:,3)));
            
            subplot(2,1,1);
            plot(BLUEPoints(:,4),BLUEPoints(:,3),'.b');
            xlabel('Distance along Profile');   ylabel('Elevation'); xlim([AxMinX AxMaxX]);      ylim([AxMinY AxMaxY]);
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            subplot(2,1,2);
            plot(REDPoints(:,4),REDPoints(:,3),'.r');
            xlabel('Distance along Profile');   ylabel('Elevation'); xlim([AxMinX AxMaxX]);      ylim([AxMinY AxMaxY]);
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            %-------------------------------------------
            app.BLUEprofilepts  = BLUEPoints;
            app.REDprofilepts   = REDPoints;
            %-------------------------------------------
        end

        % Button pushed function: TraceRedFeature_Button
        function TraceRedFeature_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if (isempty(app.BLUEprofilepts) || isempty(app.REDprofilepts)), app.ScreenOut_Line1_EditField.Value = "BLUE  or RED cross-sectional profile have not been plotted/extracted; do that first";          return;     end
            %------------------------------------------
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %------------------------------------------
            if ~isempty(app.REDTraceLine),     delete(app.REDTraceLine);        app.REDTraceLine  = [];  end 
            if ~isempty(app.REDTracePnts),     delete(app.REDTracePnts);        app.REDTracePnts  = [];  end 
            if ~isempty(app.REDLongBox),       delete(app.REDLongBox);          app.REDLongBox    = [];  end 
            if ~isempty(app.REDLongitudePoints), app.REDLongitudePoints  = []; end
            if ~isempty(app.REDTracePoints),     app.REDTracePoints      = [];  end 
            %------------------------------------------
            TempVals      = [];        counter        = 0;   
            TempHandlePt  = [];        TempHandleLine = [];         
            app.ScreenOut_Line1_EditField.Value = "'Left mouse' button to add tracing point; 'Right mouse' to remove last tracing point"; 
            app.ScreenOut_Line2_EditField.Value = "'F' for location of fault position "; 
            app.ScreenOut_Line3_EditField.Value = " 'Space bar' to stop tracing";
            %------------------------------------------
            but            = 1;
            while but ~= 32
                  [xi,yi,but] = ginput(1);
                if     but == 3 %remove a point
                    but = 1;
                    if (size(TempVals,1) == 1),           TempVals = [];                    counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(TempVals,1) >=2),            TempVals = TempVals(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end
                    if (size(TempHandleLine,1) == 1),     delete(TempHandleLine(1,1));      TempHandleLine = [];                          end 
                    if (size(TempHandleLine,1) >= 2),     delete(TempHandleLine(end,1));    TempHandleLine = TempHandleLine(1:end-1,1);   end 
                elseif but == 32 %this is the space bar
                    Picked = size(TempVals,1);
                    if (Picked <=1), app.ScreenOut_Line4_EditField.Value = "define at least two points for feature trace"; drawnow; but = 1; end
                elseif (but == 27)
                    return;
                else
                    TempVals = [TempVals;xi, yi, 0];      
                    if (but == 70)||(but == 102),   %this is "f" => fault location 
                        if ((size(TempVals,1) >= 2) &&(TempVals(end-1,3) == 0))
                            St1  = app.FaultLinePts(1,:); Ed1  = app.FaultLinePts(2,:);                   
                            Vect1= Ed1-St1;                   m1   = Vect1(1,2)/Vect1(1,1);
                            St2  = TempVals(end-1,1:2);       Ed2  = TempVals(end,1:2);                  
                            Vect2= Ed2-St2;                   m2   = Vect2(1,2)/Vect2(1,1);                
                            r1   = (yi -St1(1,2) +m2*(St1(1,1) -xi))/(m1-m2);
                            xi   = St1(1,1) +r1;              yi   = St1(1,2) +r1*m1;
                        end
                        TempVals(end,3) = 1; 
                        TempHandle      = plot(xi,yi,'square','MarkerEdgeColor','k','MarkerFaceColor','red','MarkerSize',6);
                    else                                                 
                        TempHandle = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                    end
                    TempHandlePt = [TempHandlePt; TempHandle];
                    counter      = counter  +1;
                    if counter >=2
                        if (but == 70)||(but == 102),   TypCol = '-y';
                        else,                           TypCol = '-r';
                        end
                        TempHandle    = plot([TempVals(counter-1,1), TempVals(counter,1)],[TempVals(counter-1,2), TempVals(counter,2)],TypCol);
                        TempHandleLine= [TempHandleLine; TempHandle];
                    end 
                    but = 1;
                end
            end
           
            %------------------------------------------
            app.REDTraceLine   = TempHandleLine;
            app.REDTracePnts   = TempHandlePt;
            app.REDTracePoints = TempVals; 
            %------------------------------------------
            if (~isempty(app.BLUETracePoints) && ~isempty(app.REDTracePoints))
                FltPntBLUE = app.BLUETracePoints(app.BLUETracePoints(:,3) == 1, :);         FltPntBLUE = FltPntBLUE(1,:);
                FltPntRED  = app.REDTracePoints( app.REDTracePoints(:,3)  == 1, :);         FltPntRED  = FltPntRED(1,:);
                HorzDist   = sqrt((FltPntRED(1,1)-FltPntBLUE(1,1))^2 + (FltPntRED(1,2)-FltPntBLUE(1,2))^2); 
                app.CalcHorzDisplace_EditField.Value = HorzDist*app.BlindFactor;
            end
            %------------------------------------------
        end

        % Button pushed function: TraceBlueFeature_Button
        function TraceBlueFeature_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if (isempty(app.BLUEprofilepts) || isempty(app.REDprofilepts)), app.ScreenOut_Line1_EditField.Value = "BLUE  or RED cross-sectional profile have not been plotted/extracted; do that first";          return;     end
            %------------------------------------------
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %------------------------------------------
            if ~isempty(app.BLUETraceLine),    delete(app.BLUETraceLine);       app.BLUETraceLine = [];  end 
            if ~isempty(app.BLUETracePnts),    delete(app.BLUETracePnts);       app.BLUETracePnts = [];  end 
            if ~isempty(app.BLUELongBox),      delete(app.BLUELongBox);         app.BLUELongBox   = [];  end
            if ~isempty(app.BLUELongitudePoints),app.BLUELongitudePoints = []; end
            if ~isempty(app.BLUETracePoints),    app.BLUETracePoints     = [];  end 
            %------------------------------------------
            TempVals      = [];        counter        = 0;   
            TempHandlePt  = [];        TempHandleLine = [];         
            app.ScreenOut_Line1_EditField.Value = "'Left mouse' button to add tracing point; 'Right mouse' to remove last tracing point"; 
            app.ScreenOut_Line2_EditField.Value = "'F' for location of fault position "; 
            app.ScreenOut_Line3_EditField.Value = " 'Space bar' to stop tracing";
            %------------------------------------------
            but            = 1;
            while but ~= 32
                  [xi,yi,but] = ginput(1);
                if     but == 3 %remove a point
                    but = 1;
                    if (size(TempVals,1) == 1),           TempVals = [];                    counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(TempVals,1) >=2),            TempVals = TempVals(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end
                    if (size(TempHandleLine,1) == 1),     delete(TempHandleLine(1,1));      TempHandleLine = [];                          end 
                    if (size(TempHandleLine,1) >= 2),     delete(TempHandleLine(end,1));    TempHandleLine = TempHandleLine(1:end-1,1);   end 
                elseif but == 32 %this is the space bar
                    Picked = size(TempVals,1);
                    if (Picked <=1), app.ScreenOut_Line4_EditField.Value = "define at least two points for feature trace"; drawnow; but = 1; end
                elseif (but == 27)
                    return;
                else
                    TempVals = [TempVals;xi, yi, 0];      
                    if (but == 70)||(but == 102),   %this is "f" => fault location 
                        if ((size(TempVals,1) >= 2) &&(TempVals(end-1,3) == 0))
                            St1  = app.FaultLinePts(1,:); Ed1  = app.FaultLinePts(2,:);                   
                            Vect1= Ed1-St1;                   m1   = Vect1(1,2)/Vect1(1,1);
                            St2  = TempVals(end-1,1:2);       Ed2  = TempVals(end,1:2);                  
                            Vect2= Ed2-St2;                   m2   = Vect2(1,2)/Vect2(1,1);                
                            r1   = (yi -St1(1,2) +m2*(St1(1,1) -xi))/(m1-m2);
                            xi   = St1(1,1) +r1;              yi   = St1(1,2) +r1*m1;
                        end
                        TempVals(end,3) = 1; 
                        TempHandle      = plot(xi,yi,'square','MarkerEdgeColor','k','MarkerFaceColor','blue','MarkerSize',6);
                    else                                                 
                        TempHandle = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',6);
                    end
                    TempHandlePt = [TempHandlePt; TempHandle];
                    counter      = counter  +1;
                    if counter >=2
                        if (but == 70)||(but == 102),   TypCol = '-y';
                        else,                           TypCol = '-b';
                        end
                        TempHandle    = plot([TempVals(counter-1,1), TempVals(counter,1)],[TempVals(counter-1,2), TempVals(counter,2)],TypCol);
                        TempHandleLine= [TempHandleLine; TempHandle];
                    end 
                    but = 1;
                end
            end
            %------------------------------------------
            app.BLUETraceLine   = TempHandleLine;
            app.BLUETracePnts   = TempHandlePt;
            app.BLUETracePoints = TempVals;  
            %------------------------------------------
            if (~isempty(app.BLUETracePoints) && ~isempty(app.REDTracePoints))
                FltPntBLUE = app.BLUETracePoints(app.BLUETracePoints(:,3) == 1, :);         FltPntBLUE = FltPntBLUE(1,:);
                FltPntRED  = app.REDTracePoints( app.REDTracePoints(:,3)  == 1, :);         FltPntRED  = FltPntRED(1,:);
                HorzDist   = sqrt((FltPntRED(1,1)-FltPntBLUE(1,1))^2 + (FltPntRED(1,2)-FltPntBLUE(1,2))^2); 
                app.CalcHorzDisplace_EditField.Value = HorzDist*app.BlindFactor;
            end
            %------------------------------------------
        end

        % Button pushed function: Plot_LongProfiles_Button
        function Plot_LongProfiles_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if isempty(app.BLUETracePoints),app.ScreenOut_Line1_EditField.Value = "BLUE longitudinal profile not defined; do that first";          return;     end
            if isempty(app.REDTracePoints), app.ScreenOut_Line1_EditField.Value = "RED longitudinal profile not defined; do that first";          return;     end        
            %------------------------------------------
            SwathWdth   = app.FeatureSwathwidth_EditField.Value*app.BlindFactor;
            FlipProfs   = app.FlipLongProfiles_CheckBox.Value;
            BLUDistance =-app.BlueProfDistance_EditField.Value*app.BlindFactor;
            REDDistance = app.RedProfDistance_EditField.Value*app.BlindFactor;      
            FigNum      = app.PlotZoom_FigNum_EditField.Value*100 +2;
            figure(FigNum); clf; hold on;
            %-------------------------------------------
            app.BLUELongitudePoints = [];
            app.REDLongitudePoints  = [];
            %-------------------------------------------
            FindFaultPosBLU = find(app.BLUETracePoints(:,3) == 1);
            BLUTrace        = app.BLUETracePoints(1:FindFaultPosBLU(1,1),1:2);
            FindFaultPosRED = find(app.REDTracePoints(:,3) == 1);
            REDTrace        = app.REDTracePoints(1:FindFaultPosRED(1,1),1:2);
            %..........................
            BLUTraceLengths = sqrt( (BLUTrace(2:end,1)-BLUTrace(1:end-1,1)).^2 + (BLUTrace(2:end,2)-BLUTrace(1:end-1,2)).^2);
            BLUTraceLengths = cumsum(BLUTraceLengths);
            BLUTraceLengths = [0; BLUTraceLengths];
            REDTraceLengths = sqrt( (REDTrace(2:end,1)-REDTrace(1:end-1,1)).^2 + (REDTrace(2:end,2)-REDTrace(1:end-1,2)).^2);
            REDTraceLengths = cumsum(REDTraceLengths);
            REDTraceLengths = [0; REDTraceLengths];
            %-------------------------------------------
            BLFLongPoints    = [];
            for ii = 2 : size(BLUTrace)-1
                BLUSt        = BLUTrace(ii-1,:);
                BLUEd        = BLUTrace(ii,:);
                BLUSegVect   = BLUEd - BLUSt;
                BLUSegVect   = BLUSegVect./sqrt(sum(BLUSegVect.^2));
                BLUSegNorm   = [BLUSegVect(1,2),-BLUSegVect(1,1)];
                SegmEdgePnts = [(BLUSt +BLUSegNorm.*(SwathWdth/2)); (BLUEd +BLUSegNorm.*(SwathWdth/2)); (BLUEd -BLUSegNorm.*(SwathWdth/2)); (BLUSt -BLUSegNorm.*(SwathWdth/2))];
                %----------------------------
                IsBLUE     = inpolygon(app.EarrayZoom,app.NarrayZoom, SegmEdgePnts(:,1), SegmEdgePnts(:,2));
                %----------------------------
                BLUEElevation  = app.CutElev(IsBLUE);
                BLUEEvalue     = app.EarrayZoom(IsBLUE);
                BLUENvalue     = app.NarrayZoom(IsBLUE);
                BLUEDistance2  = sqrt((BLUEEvalue-BLUSt(1,1)).^2 + (BLUENvalue-BLUSt(1,2)).^2) +BLUTraceLengths(ii-1,1);
                %----------------------------
                BLFLongPoints    = [BLFLongPoints;BLUEEvalue(:),BLUENvalue(:),BLUEElevation(:),BLUEDistance2(:)];
                %----------------------------
            end
            %-------------------------------------------
            RDFLongPoints    = [];
            for ii = 2 : size(REDTrace)-1
                REDSt        = REDTrace(ii-1,:);
                REDEd        = REDTrace(ii,:);
                REDSegVect   = REDEd - REDSt;
                REDSegVect   = REDSegVect./sqrt(sum(REDSegVect.^2));
                REDSegNorm   = [REDSegVect(1,2),-REDSegVect(1,1)];
                SegmEdgePnts = [(REDSt +REDSegNorm.*(SwathWdth/2)); (REDEd +REDSegNorm.*(SwathWdth/2)); (REDEd -REDSegNorm.*(SwathWdth/2)); (REDSt -REDSegNorm.*(SwathWdth/2))];
                %----------------------------
                IsRED     = inpolygon(app.EarrayZoom,app.NarrayZoom, SegmEdgePnts(:,1), SegmEdgePnts(:,2));
                %----------------------------
                REDElevation  = app.CutElev(IsRED);
                REDEvalue     = app.EarrayZoom(IsRED);
                REDNvalue     = app.NarrayZoom(IsRED);
                REDDistance2  = sqrt((REDEvalue-REDSt(1,1)).^2 + (REDNvalue-REDSt(1,2)).^2) +REDTraceLengths(ii-1,1);
                %----------------------------
                RDFLongPoints = [RDFLongPoints;REDEvalue(:),REDNvalue(:),REDElevation(:),REDDistance2(:)];
                %----------------------------
            end
            %-------------------------------------------%-------------------------------------------
            BLFLongPoints(:,4) = BLFLongPoints(:,4)-BLUTraceLengths(end,1);
            RDFLongPoints(:,4) = RDFLongPoints(:,4)-REDTraceLengths(end,1);
            RDFLongPoints(:,4) = RDFLongPoints(:,4).*-1;
            %-------------------------------------------
            if (FlipProfs == 1)
                BLFLongPoints(:,4) = BLFLongPoints(:,4).*-1;             RDFLongPoints(:,4) = RDFLongPoints(:,4).*-1;
                BLUDistance        = -BLUDistance;                       REDDistance        = -REDDistance;      
            end
            %-------------------------------------------
            AxMin_X= min(min(BLFLongPoints(:,4)),min(RDFLongPoints(:,4)));
            AxMax_X= max(max(BLFLongPoints(:,4)),max(RDFLongPoints(:,4)));
            AxMin_Y= min(min(BLFLongPoints(:,3)),min(RDFLongPoints(:,3)));
            AxMax_Y= max(max(BLFLongPoints(:,3)),max(RDFLongPoints(:,3)));
            %-------------------------------------------
            figure(FigNum); clf; hold on;
            xlabel('Distance along longitudinal profile'); ylabel('Elevation');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            xlim([AxMin_X AxMax_X]);
            ylim([AxMin_Y AxMax_Y]);
            
            plot([0 0],[AxMin_Y AxMax_Y],'-c','LineWidth',2);
            plot([REDDistance REDDistance],[-AxMin_Y AxMax_Y],'--r');
            plot([BLUDistance BLUDistance],[-AxMin_Y AxMax_Y],'--b');
            plot(BLFLongPoints(:,4),BLFLongPoints(:,3),'.b');
            plot(RDFLongPoints(:,4),RDFLongPoints(:,3),'.r');
            %-------------------------------------------
            app.BLUELongitudePoints = BLFLongPoints;
            app.REDLongitudePoints  = RDFLongPoints;
            %-------------------------------------------   
        end

        % Button pushed function: TraceRedLongProfile_Button
        function TraceRedLongProfile_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if isempty(app.BLUETracePoints),app.ScreenOut_Line1_EditField.Value = "BLUE longitudinal profile not defined; do that first";          return;     end
            if isempty(app.REDTracePoints), app.ScreenOut_Line1_EditField.Value = "RED longitudinal profile not defined; do that first";          return;     end        
            if (isempty(app.REDLongitudePoints)||isempty(app.REDLongitudePoints)), app.ScreenOut_Line1_EditField.Value = "RED and BLUE longitudinal profile must be ploted; do that first";          return;     end        
            %------------------------------------------    
            FigNum      = app.PlotZoom_FigNum_EditField.Value*100 +2;
            figure(FigNum); hold on;
            %------------------------------------------
            if ~isempty(app.REDLongProjLine),       delete(app.REDLongProjLine);            app.REDLongProjLine = [];           end
            if ~isempty(app.REDLongProjPnts),       delete(app.REDLongProjPnts);            app.REDLongProjPnts = [];           end
            if ~isempty(app.REDProjPoints),         app.REDProjPoints = [];         end
            %--------------------- 
            ThePoints     = zeros(2,2);            TempHandle  = [];               counter   = 0;       
            %------------------------------------------
            while counter < 2
                [xi,yi,but]          = ginput(1);
                counter              = counter +1;
                ThePoints(counter,1) = xi;
                ThePoints(counter,2) = yi;
                ThePoints(counter,3) = abs(xi);
 
                handle     = plot(xi,yi,'square','MarkerEdgeColor','k','MarkerFaceColor','red','MarkerSize',6);
                TempHandle = [TempHandle; handle];
                if (but == 27)
                    return;
                end
            end
            %------------------------------------------
            ThePoints = flipud(sortrows(ThePoints,3));             
            Pr_x      = 0;
            Pr_y      = ThePoints(1,2) -(ThePoints(1,3)/(ThePoints(2,1)-ThePoints(1,1)) *(ThePoints(2,2)-ThePoints(1,2))) *sign(ThePoints(1,1));
            
            app.REDLongProjLine = plot([ThePoints(1,1),Pr_x],[ThePoints(1,2),Pr_y],'-m');
            handle              = plot(Pr_x,Pr_y,'square','MarkerEdgeColor','k','MarkerFaceColor','magenta','MarkerSize',8);
            TempHandle          = [TempHandle; handle];
            app.REDLongProjPnts = TempHandle;
            app.REDProjPoints   = ThePoints(:,1:2);    
            %------------------------------------------
            if (~isempty(app.REDProjPoints) && ~isempty(app.BLUEProjPoints))
                Pr_yRED   = app.REDProjPoints(1,2)  -(abs(app.REDProjPoints(1,1)) /(app.REDProjPoints(2,1) -app.REDProjPoints(1,1))  *(app.REDProjPoints(2,2) -app.REDProjPoints(1,2)))  *sign(app.REDProjPoints(1,1));
                Pr_yBLUE  = app.BLUEProjPoints(1,2) -(abs(app.BLUEProjPoints(1,1))/(app.BLUEProjPoints(2,1)-app.BLUEProjPoints(1,1)) *(app.BLUEProjPoints(2,2)-app.BLUEProjPoints(1,2))) *sign(app.BLUEProjPoints(1,1));
                VertDist  = abs(Pr_yBLUE-Pr_yRED);
                app.CalcVertDisplace_EditField.Value = VertDist*app.BlindFactor;
            end
            %------------------------------------------       
        end

        % Button pushed function: TraceBlueLongProfile_Button
        function TraceBlueLongProfile_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if isempty(app.BLUETracePoints),app.ScreenOut_Line1_EditField.Value = "BLUE longitudinal profile not defined; do that first";          return;     end
            if isempty(app.REDTracePoints), app.ScreenOut_Line1_EditField.Value = "RED longitudinal profile not defined; do that first";          return;     end        
            if (isempty(app.REDLongitudePoints)||isempty(app.REDLongitudePoints)), app.ScreenOut_Line1_EditField.Value = "RED and BLUE longitudinal profile must be ploted; do that first";          return;     end        
            %------------------------------------------    
            FigNum      = app.PlotZoom_FigNum_EditField.Value*100 +2;
            figure(FigNum); hold on;
            %------------------------------------------
            if ~isempty(app.BLUELongProjLine),       delete(app.BLUELongProjLine);            app.BLUELongProjLine = [];           end
            if ~isempty(app.BLUELongProjPnts),       delete(app.BLUELongProjPnts);            app.BLUELongProjPnts = [];           end
            if ~isempty(app.BLUEProjPoints),         app.BLUEProjPoints = [];         end
            %--------------------- 
            ThePoints     = zeros(2,2);            TempHandle  = [];               counter   = 0;       
            %------------------------------------------
            while counter < 2
                [xi,yi,but]          = ginput(1);
                counter              = counter +1;
                ThePoints(counter,1) = xi;
                ThePoints(counter,2) = yi;
                ThePoints(counter,3) = abs(xi);
 
                handle     = plot(xi,yi,'square','MarkerEdgeColor','k','MarkerFaceColor','blue','MarkerSize',6);
                TempHandle = [TempHandle; handle];
                if (but == 27)
                    return;
                end
            end
            %------------------------------------------
            ThePoints = flipud(sortrows(ThePoints,3));             
            Pr_x      = 0;
            Pr_y      = ThePoints(1,2) -(ThePoints(1,3)/(ThePoints(2,1)-ThePoints(1,1)) *(ThePoints(2,2)-ThePoints(1,2))) *sign(ThePoints(1,1));
            
            app.BLUELongProjLine = plot([ThePoints(1,1),Pr_x],[ThePoints(1,2),Pr_y],'-m');
            handle              = plot(Pr_x,Pr_y,'square','MarkerEdgeColor','k','MarkerFaceColor','magenta','MarkerSize',8);
            TempHandle          = [TempHandle; handle];
            app.BLUELongProjPnts = TempHandle;
            app.BLUEProjPoints   = ThePoints(:,1:2);     
            if (~isempty(app.REDProjPoints) && ~isempty(app.BLUEProjPoints))
                Pr_yRED   = app.REDProjPoints(1,2)  -(abs(app.REDProjPoints(1,1)) /(app.REDProjPoints(2,1) -app.REDProjPoints(1,1))  *(app.REDProjPoints(2,2) -app.REDProjPoints(1,2)))  *sign(app.REDProjPoints(1,1));
                Pr_yBLUE  = app.BLUEProjPoints(1,2) -(abs(app.BLUEProjPoints(1,1))/(app.BLUEProjPoints(2,1)-app.BLUEProjPoints(1,1)) *(app.BLUEProjPoints(2,2)-app.BLUEProjPoints(1,2))) *sign(app.BLUEProjPoints(1,1));
                VertDist  = abs(Pr_yBLUE-Pr_yRED);
                app.CalcVertDisplace_EditField.Value = VertDist*app.BlindFactor;
            end
            %------------------------------------------ 
        end

        % Button pushed function: Findoffsets_Button
        function Findoffsets_ButtonPushed(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";                       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";                           return;     end
            if isempty(app.REDpts),        app.ScreenOut_Line1_EditField.Value = "RED cross-sectional profile not defined; do that first";           return;     end
            if isempty(app.BLUEpts),       app.ScreenOut_Line1_EditField.Value = "BLUE cross-sectional profile not defined; do that first";          return;     end
            if isempty(app.BLUETracePoints),app.ScreenOut_Line1_EditField.Value = "BLUE longitudinal profile not defined; do that first";          return;     end
            if isempty(app.REDTracePoints), app.ScreenOut_Line1_EditField.Value = "RED longitudinal profile not defined; do that first";          return;     end        
            if (isempty(app.REDLongitudePoints)||isempty(app.REDLongitudePoints)), app.ScreenOut_Line1_EditField.Value = "RED and BLUE longitudinal profile must be ploted; do that first";          return;     end        
            %------------------------------------------    
            
            FigNum        = app.FindOffsetFigNum_EditField.Value;
            deltHorz      = app.HorzShiftDlt_EditField.Value/app.BlindFactor;
            MinHorz       = app.HorzShiftMin_EditField.Value/app.BlindFactor;
            MaxHorz       = app.HorzShiftMax_EditField.Value/app.BlindFactor;
            Horzsteps     = (abs(round((MaxHorz-MinHorz)/deltHorz)))+1;
            deltVert      = app.VertShiftDlt_EditField.Value/app.BlindFactor;
            MinVert       = app.VertShiftMin_EditField.Value/app.BlindFactor;
            MaxVert       = app.VertShiftMax_EditField.Value/app.BlindFactor;
            Vertsteps     = (abs(round((MaxVert-MinVert)/deltVert)))+1;
            dStretch      = app.VertStretchDlt_EditField.Value;
            MinStretch    = app.VertStretchMin_EditField.Value;
            MaxStretch    = app.VertStretchMax_EditField.Value;
            Strtsteps     = (abs(round((MaxStretch-MinStretch)/dStretch)))+1;
            SlipDir       = str2double(app.BackSlip_direct_DropDown.Value);
            if     (SlipDir == 1),              HorzSlipSign = 1;
            elseif (SlipDir == 2),              HorzSlipSign =-1;   
            end
            %------------------------------------------
            GOFcube       = zeros(Strtsteps,Vertsteps,Horzsteps);
            %------------------------------------------
            HorzRange     = linspace(MinHorz,MaxHorz,Horzsteps);%MinHorz    : deltHorz : MaxHorz;
            VertRange     = linspace(MinVert,MaxVert,Vertsteps);%MinVert    : deltVert : MaxVert;
            StretchRange  = linspace(MinStretch,MaxStretch,Strtsteps);%MinStretch : dStretch : MaxStretch;
            %------------------------------------------
            FltX = app.FaultLinePts(1,1);               FdirX= app.FaultLinePts(2,1) - app.FaultLinePts(1,1);     
            FltY = app.FaultLinePts(1,2);               FdirY= app.FaultLinePts(2,2) - app.FaultLinePts(1,2);

            REDFltInLong   = app.REDTracePoints(app.REDTracePoints(:,3) == 1,:); %identifies location of red feature trace projection into fault
            REDFltInLong   = REDFltInLong(1,:);
            BLUEFltInLong  = app.BLUETracePoints(app.BLUETracePoints(:,3) == 1,:); %identifies location of blue feature trace projection into fault
            BLUEFltInLong  = BLUEFltInLong(1,:);

            Dist0RED       = sqrt((REDFltInLong(1,1) -FltX)^2 + (REDFltInLong(1,2) -FltY)^2); %distance to start of the fault line (to get distance to start of fault line for the key
            Dist0BLUE      = sqrt((BLUEFltInLong(1,1)-FltX)^2 + (BLUEFltInLong(1,2)-FltY)^2); %distance to start of the fault line (to get distance to start of fault line for the key
            
            REDProfPnts    = [app.REDprofilepts(:,4), app.REDprofilepts(:,3)];
            BLUEProfPnts   = [app.BLUEprofilepts(:,4),app.BLUEprofilepts(:,3)];
            %------------------------------------------
            app.ShiftREDhorz = 0;
            for i = 2 : size(app.REDTracePoints,1)
                P1  = app.REDTracePoints(i-1,:); %want to find the section of the longitudinal profile that contains/covers the cross-sectional profile; need that to get the projection point of that one into the fault
                P2  = app.REDTracePoints(i,  :); %do this by determining the distance of start and end point of the long-profile section to the fault trace
                A   = P1(1,2)/FdirY -FltY/FdirY -P1(1,1)/FdirX +FltX/FdirX; %schnittpunkt bestimmen und dann entfernung ausrechnen;...
                B   = FdirY/FdirX +FdirX/FdirY;
                r   = A/B;
                IntX = P1(1,1) +r*FdirY;
                IntY = P1(1,2) -r*FdirX;
                Dists = [sqrt((P1(1,1)-IntX)^2 + (P1(1,2)-IntY)^2)    sqrt((P2(1,1)-IntX)^2 + (P2(1,2)-IntY)^2)];
                if ((min(Dists) <= app.RedProfDistance_EditField.Value*app.BlindFactor) && (max(Dists) >= app.RedProfDistance_EditField.Value*app.BlindFactor)) %if one long-profile point is closer to fault than the x-section profile distance and the other point is farther way than this distance, then I have the right segment
                    %already know the intersection point, can now determine its distance to the fault starting point (as was done before for the projected point   
                    Dist1RED    = sqrt((IntX -FltX)^2 + (IntY -FltY)^2);
                    app.ShiftREDhorz = Dist0RED -Dist1RED;
                    break;
                end
            end 
            app.ShiftREDvert      =  (app.REDProjPoints(2,2)-app.REDProjPoints(1,2))/(app.REDProjPoints(2,1)-app.REDProjPoints(1,1))*app.RedProfDistance_EditField.Value*app.BlindFactor;%REDElevAtXprof +
            %-----------------------------------------
            app.ShftBLUEhorz = 0;
            for i = 2 : size(app.BLUETracePoints,1)
                P1  = app.BLUETracePoints(i-1,:); %want to find the section of the longitudinal profile that contains/covers the cross-sectional profile; need that to get the projection point of that one into the fault
                P2  = app.BLUETracePoints(i,  :); %do this by determining the distance of start and end point of the long-profile section to the fault trace
                A   = P1(1,2)/FdirY -FltY/FdirY -P1(1,1)/FdirX +FltX/FdirX; %schnittpunkt bestimmen und dann entfernung ausrechnen;...
                B   = FdirY/FdirX +FdirX/FdirY;
                r   = A/B;
                IntX = P1(1,1) +r*FdirY;
                IntY = P1(1,2) -r*FdirX;
                Dists = [sqrt((P1(1,1)-IntX)^2 + (P1(1,2)-IntY)^2)    sqrt((P2(1,1)-IntX)^2 + (P2(1,2)-IntY)^2)];
                if ((min(Dists) <= app.BlueProfDistance_EditField.Value*app.BlindFactor) && (max(Dists) >= app.BlueProfDistance_EditField.Value*app.BlindFactor)) %if one long-profile point is closer to fault than the x-section profile distance and the other point is farther way than this distance, then I have the right segment
                    %already know the intersection point, can now determine its distance to the fault starting point (as was done before for the projected point   
                    Dist1BLUE    = sqrt((IntX -FltX)^2 + (IntY -FltY)^2);
                    app.ShftBLUEhorz = Dist0BLUE -Dist1BLUE;
                    break;
                end
            end
            app.ShftBLUEvert  =  (app.BLUEProjPoints(2,2)-app.BLUEProjPoints(1,2))/(app.BLUEProjPoints(2,1)-app.BLUEProjPoints(1,1))*app.BlueProfDistance_EditField.Value*app.BlindFactor; %REDElevAtXprof +
            %------------------------------------------
            REDProfPnts(:,1)  = REDProfPnts(:,1) +app.ShiftREDhorz;
            REDProfPnts(:,2)  = REDProfPnts(:,2) +app.ShiftREDvert*sign(app.REDProjPoints(2,2)-app.REDProjPoints(1,2));
            BLUEProfPnts(:,1) = BLUEProfPnts(:,1)+app.ShftBLUEhorz;
            BLUEProfPnts(:,2) = BLUEProfPnts(:,2)+app.ShftBLUEvert*sign(app.BLUEProjPoints(2,2)-app.BLUEProjPoints(1,2));
            %-----------------------------------------------------------------
            %-----------------------------------------------------------------
            for StretchRun = 1 : Strtsteps
                CurStretch       = StretchRange(1,StretchRun);
                UsedRedProf      = REDProfPnts;
                UsedRedProf(:,2) = UsedRedProf(:,2) -min(REDProfPnts(:,2));               
                UsedRedProf(:,2) = UsedRedProf(:,2).*CurStretch;
                UsedRedProf(:,2) = UsedRedProf(:,2) +min(REDProfPnts(:,2));
                for VertRun = 1 : Vertsteps
                    UsedRedProf2      = UsedRedProf;
                    UsedRedProf2(:,2) = UsedRedProf(:,2) + VertRange(1,VertRun); %now the profile is uplifted by minimum amount
            
                    for HorzRun = 1 : Horzsteps  
                        %--------------------
                        UsedRedProf2(:,1) = UsedRedProf(:,1) + HorzRange(1,HorzRun)*HorzSlipSign;
                        summeddiff        = 0;
                        UsedBlueProf      = BLUEProfPnts(BLUEProfPnts(:,1) >=min(UsedRedProf2(:,1)),:);
                        UsedBlueProf      = UsedBlueProf(UsedBlueProf(:,1) <=max(UsedRedProf2(:,1)),:);
                        if ~isempty(UsedBlueProf)
                            for (i = 1 : size(UsedRedProf2,1))
                                thediff    = sqrt((UsedBlueProf(:,1)-UsedRedProf2(i,1)).^2 + (UsedBlueProf(:,2)-UsedRedProf2(i,2)).^2); 
                                summeddiff = summeddiff + min(thediff(:)); 
                            end
                        else
                            summeddiff = NaN;
                        end
                        GOFcube(StretchRun,VertRun,HorzRun) = summeddiff;
                    end
                end
            end
            %-----------------------------------------------------------------
            %-----------------------------------------------------------------
            [StrchPos,VertPos,HorzPos] = ind2sub(size(GOFcube),find(GOFcube == min(GOFcube(:))));
            OptStretch        = StretchRange(1,StrchPos);
            OptVert           = VertRange(1,VertPos);
            OptHorz           = HorzRange(1,HorzPos);
            app.BestStretch   = OptStretch;
            
            GOFAlongHorz      = squeeze(GOFcube(StrchPos,VertPos,:))';  
            GOFAlongVert      = squeeze(GOFcube(StrchPos,:,HorzPos));
            
            BackSlipRED       = REDProfPnts;
            BackSlipRED(:,1)  = BackSlipRED(:,1) +HorzRange(1,HorzPos)*HorzSlipSign;
            BackSlipRED(:,2)  = BackSlipRED(:,2) +VertRange(1,VertPos);

            OptSurface        = squeeze(GOFcube(StrchPos,:,:));  
            %------------------------------------------
            figure(FigNum*10+1); clf; 
            
            BlueProfLongPnts = app.BLUELongitudePoints;
            RedProfLongPnts  = app.REDLongitudePoints;


            figure(FigNum); clf;   
            subplot(3,3,1:3);hold on  
            plot(BLUEProfPnts(:,1),BLUEProfPnts(:,2),'.b');      
            plot(REDProfPnts(:,1), REDProfPnts(:,2), '.r');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            xlabel('Distance along profile (BEFORE applying back-slip)'); ylabel('Elevation');
            
            subplot(3,3,4:6);hold on  
            plot(BLUEProfPnts(:,1),BLUEProfPnts(:,2),'.b');      
            plot(BackSlipRED(:,1), BackSlipRED(:,2), '.r');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            xlabel('Distance along profile (AFTER applying back-slip)'); ylabel('Elevation');
            
            subplot(3,3,7);hold on; axis tight;
            imagesc(HorzRange.*app.BlindFactor,VertRange.*app.BlindFactor,OptSurface);
            plot([min(HorzRange)*app.BlindFactor max(HorzRange)*app.BlindFactor],[OptVert*app.BlindFactor OptVert*app.BlindFactor],'-k');
            plot([OptHorz*app.BlindFactor OptHorz*app.BlindFactor],[min(VertRange)*app.BlindFactor max(VertRange)*app.BlindFactor],'-k');
            xlabel('Horz. displacement');   ylabel('Vert. displacement');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            subplot(3,3,8);hold on  
            plot(HorzRange*app.BlindFactor,GOFAlongHorz,'-k');  
            xlabel('Horizontal displacement'); ylabel('Misfit');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            
            subplot(3,3,9);hold on  
            plot(VertRange*app.BlindFactor,GOFAlongVert,'-k');      
            xlabel('Vertical displacement'); ylabel('Misfit');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            %------------------------------------------
            app.ScreenOut_Line1_EditField.Value = strcat('Optimal Stretch = ',num2str(OptStretch));
            app.ScreenOut_Line2_EditField.Value = strcat('Optimal Vertical Slip = ',num2str(OptVert*app.BlindFactor));
            app.ScreenOut_Line3_EditField.Value = strcat('Optimal Horizontal Slip = ',num2str(OptHorz*app.BlindFactor));
            app.CalcHorzDisplace_EditField.Value = OptHorz*app.BlindFactor;
            app.CalcVertDisplace_EditField.Value = OptVert*app.BlindFactor;
            app.GOFAlongHoriz                    = [HorzRange;GOFAlongHorz];
            app.GOFAlongVerti                    = [VertRange;GOFAlongVert];
        end

        % Button pushed function: BackslipDEM_Button
        function BackslipDEM_ButtonPushed(app, event)
            FigNum      = app.BackSlip_FigNum_EditField.Value*100+1;
            In3D        = str2double(app.BackSlip_2D3D_DropDown.Value);
            ViewAxis    = str2num(app.BackSlip_ViewAxis_EditField.Value);
            OffsetHorz  = app.BackSlip_HorzSlip_EditField.Value /app.BlindFactor;
            OffsetVert  = app.BackSlip_VertSlip_EditField.Value /app.BlindFactor;
            SlipDirect  = str2double(app.BackSlip_direct_DropDown.Value);
            FlipLongProf= app.FlipLongProfiles_CheckBox.Value;
            if (SlipDirect == 1)
                OffsetHorz  = -OffsetHorz;    
            end
            
            Clean_BS    = app.BackSlip_CleanBS_CheckBox.Value;
            %---------------------------------------------
            DrapeSwitch    = app.Drape_CheckBox.Value;
            UpperLayerType = str2double(app.ZoomBoxUpperDataSet_DropDown.Value);
            UpperColMap    = str2double(app.ZoomBoxUpper_Colormap_DropDown.Value);
            InvUpperColor  = app.ZoomBoxInvertUpper_CheckBox.Value;
            UpperTransp    = app.ZoomBoxUpperTransp_EditField.Value;
            LowerLayerType = str2double(app.ZoomBoxLowerDataSet_DropDown.Value);
            LowerColMap    = str2double(app.ZoomBoxLower_Colormap_DropDown.Value);
            InvLowerColor  = app.ZoomBoxInvertLower_CheckBox.Value;
            LowerTransp    = app.ZoomBoxLowerTransp_EditField.Value;           
            MinElev        = app.ZoomBox_minelevation_EditField.Value;
            MaxElev        = app.ZoomBox_maxelevation_EditField.Value;
            Intervl        = app.ZoomBox_contourInterval_EditField.Value;
            Azimuth        = app.ZoomBox_azimuth_EditField.Value;
            Zenith         = app.ZoomBox_zenith_EditField.Value;
            Z_fact         = app.ZoomBox_zfact_EditField.Value;
            MinSlope       = app.ZoomBox_minslope_EditField.Value;
            MaxSlope       = app.ZoomBox_maxslope_EditField.Value;
            %---------------------------------------------
            if     (UpperColMap ==1),        ColMap1 = app.ColTopo;
            elseif (UpperColMap ==2),        ColMap1 = app.ColBlu2Red;
            elseif (UpperColMap ==3),        ColMap1 = gray(64);
            elseif (UpperColMap==4),         ColMap1 = parula(64);
            elseif (UpperColMap==5),         ColMap1 = jet(64);
            elseif (UpperColMap==6),         ColMap1 = hsv(64);
            end
            if     (LowerColMap ==1),        ColMap2 = app.ColTopo;
            elseif (LowerColMap ==2),        ColMap2 = app.ColBlu2Red;
            elseif (LowerColMap ==3),        ColMap2 = gray(64);
            elseif (LowerColMap==4),         ColMap2 = parula(64);
            elseif (LowerColMap==5),         ColMap2 = jet(64);
            elseif (LowerColMap==6),         ColMap2 = hsv(64);
            end
            if (InvUpperColor == 1),         ColMap1 = flipud(ColMap1);        end
            if (InvLowerColor == 1),         ColMap2 = flipud(ColMap2);        end   
            if (DrapeSwitch == 0),           CombColMap = ColMap2;
            else,                            CombColMap = [ColMap1;ColMap2];
            end
            %---------------------%---------------------
            FltPntA        = app.FaultLinePts(1,:);
            FltPntB        = app.FaultLinePts(2,:);
            FltCent        = [(FltPntA(1,1)+FltPntB(1,1))/2 , (FltPntA(1,2)+FltPntB(1,2))/2];
            FltVect        = [(FltPntB(1,1)-FltPntA(1,1))   , (FltPntB(1,2)-FltPntA(1,2))  ];
            FltVect        = FltVect./sqrt(sum(FltVect.^2)); %normalized the length
            FltNorm        = [FltVect(1,2),-FltVect(1,1)];
            %--------------------------------%--------------------------------
            AlgFltSlipVect = [0, OffsetHorz, OffsetVert];
            FaultStrike    = atan2(FltVect(1,1),FltVect(1,2));
            if (FaultStrike < 0),   FaultStrike = 2*pi + FaultStrike;           end
            FaultDip       = 90*pi/180;
            LocalY         = [sin(FaultStrike), cos(FaultStrike), 0];
            LocalX         = [sin(FaultDip)*cos(FaultStrike), -sin(FaultDip)*sin(FaultStrike), cos(FaultDip)];
            LocalZ         = cross(LocalX,LocalY);
            RotMat         = [LocalX;LocalY;LocalZ];
            RotMatT        = RotMat';
            GlobSlipVect   = RotMatT*AlgFltSlipVect';
            %--------------------------------%--------------------------------
            if (Clean_BS == 0)
                MinStep        = min([(app.EarrayZoom(1,2)-app.EarrayZoom(1,1)) (app.NarrayZoom(2,1)-app.NarrayZoom(1,1))]);
                Dist2Center    = sqrt((app.EarrayZoom   -FltCent(1,1)).^2 +(app.NarrayZoom   -FltCent(1,2)).^2);
                DistChkPnt     = FltCent +FltNorm.*(MinStep/2);
                Dist2ChkPnt    = sqrt((app.EarrayZoom-DistChkPnt(1,1)).^2 +(app.NarrayZoom-DistChkPnt(1,2)).^2);
                %-------------------
                TestGrid       = zeros(size(app.CutElev))+1;
                TestGrid(Dist2Center < Dist2ChkPnt) = -1;
                Earray    = app.EarrayZoom; 
                Narray    = app.NarrayZoom;
                Elevation = app.CutElev;
            elseif (Clean_BS == 1)
                MinStep        = min([(app.Earray(1,2)-app.Earray(1,1)) (app.Narray(2,1)-app.Narray(1,1))]);
                Dist2Center    = sqrt((app.Earray   -FltCent(1,1)).^2 +(app.Narray   -FltCent(1,2)).^2);
                DistChkPnt     = FltCent +FltNorm.*(MinStep/2);
                Dist2ChkPnt    = sqrt((app.Earray-DistChkPnt(1,1)).^2 +(app.Narray-DistChkPnt(1,2)).^2);
                %-------------------
                TestGrid       = zeros(size(app.Elevation))+1;
                TestGrid(Dist2Center < Dist2ChkPnt) = -1;
                Earray    = app.Earray; 
                Narray    = app.Narray;
                Elevation = app.Elevation;
            end
            %--------------------------------
            Earray(TestGrid == -1)   = Earray(TestGrid == -1)   +GlobSlipVect(1,1)/2;
            Earray(TestGrid ==  1)   = Earray(TestGrid ==  1)   -GlobSlipVect(1,1)/2;
            %--------------------------------
            Narray(TestGrid == -1)   = Narray(TestGrid == -1)   +GlobSlipVect(2,1)/2;
            Narray(TestGrid ==  1)   = Narray(TestGrid ==  1)   -GlobSlipVect(2,1)/2;
            %--------------------------------
            Elevation(TestGrid == -1)= Elevation(TestGrid== -1) -GlobSlipVect(3,1)/2;
            Elevation(TestGrid ==  1)= Elevation(TestGrid==  1) +GlobSlipVect(3,1)/2;
            %--------------------------------%--------------------------------
            Xmin    = min(Earray(:));            Xmax = max(Earray(:));
            Ymin    = min(Narray(:));            Ymax = max(Narray(:));
            dX      = app.Cellsize;              dY   = app.Cellsize;
            %--------------------------------%--------------------------------
            [NewEarray,NewNarray] = meshgrid(Xmin:dX:Xmax, Ymin:dY:Ymax);
            CutShiftElev          = griddata(Narray,Earray,Elevation,NewNarray,NewEarray); 
            if (Clean_BS == 1)
                CutShiftElev = CutShiftElev(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2);
                NewEarray    =NewEarray(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2);
                NewNarray    =NewNarray(app.relativeNorth1:app.relativeNorth2,app.relativeEast1:app.relativeEast2);
            end
            [CutdZdx, CutdZdy]     = gradient(CutShiftElev,app.Cellsize,app.Cellsize);      
            [CutAsp,CutGrad]       = cart2pol(CutdZdy,CutdZdx);      
            %--------------------------------%--------------------------------
            %--------------------------------%--------------------------------
            figure(FigNum);
            clf; hold on;axis equal;
            xlabel('Easting'); ylabel('Northing');
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            colormap(CombColMap);
            %---------------------%--------------------
            if (LowerLayerType == 1)
                LowerMap                        = CutShiftElev; 
                LowerMap(LowerMap < MinElev)    = MinElev;
                LowerMap(LowerMap > MaxElev)    = MaxElev;                                      
            elseif (LowerLayerType == 2)
                asp            = CutAsp;                       grad                  = CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                LowerMap       = Hshd; %required for back-slip later on  
 
            elseif (LowerLayerType == 3)
                LowerMap                          = atan(CutGrad).*180/pi; %steepest slope
                LowerMap(LowerMap < MinSlope)     = MinSlope;
                LowerMap(LowerMap > MaxSlope)     = MaxSlope; 
            elseif (LowerLayerType == 4)
                LowerMap                          = CutAsp.*180/pi;  
            elseif (LowerLayerType == 5)
                LowerMap                          = CutAsp.*180/pi;
                
                if (In3D == 1)
                    ScaledSlop                        = atan(CutGrad).*180/pi;
                    ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                    ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                    LowerTransp= ScaledSlop./max(ScaledSlop(:)).*LowerTransp;
                end
            end
            if (Intervl > 0)
                MaxVal   = max(LowerMap(:));    MinVal = min(LowerMap(:));
                LowerMap = MinElev + (LowerMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%------
            if (UpperLayerType == 1)
                UpperMap                        = CutShiftElev; 
                UpperMap(UpperMap < MinElev)    = MinElev;
                UpperMap(UpperMap > MaxElev)    = MaxElev;                     
            elseif (UpperLayerType == 2)
                asp            = CutAsp;                       grad                  = CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                UpperMap       = Hshd; %required for back-slip later on  
            elseif (UpperLayerType == 3)
                UpperMap                      = atan(CutGrad).*180/pi; %steepest slope
                UpperMap(UpperMap < MinSlope) = MinSlope;
                UpperMap(UpperMap > MaxSlope) = MaxSlope; 
            elseif (UpperLayerType == 4)
                UpperMap                      = CutAsp.*180/pi;  
            elseif (UpperLayerType == 5)
                UpperMap                          = CutAsp.*180/pi;
                if (In3D == 1)
                    ScaledSlop                        = atan(CutGrad).*180/pi;
                    ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                    ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                    UpperTransp= ScaledSlop./max(ScaledSlop(:)).*UpperTransp;
                end
            end
            if (Intervl > 0)
                MaxVal   = max(UpperMap(:));    MinVal = min(UpperMap(:));
                UpperMap = MinElev + (UpperMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%------
            if (In3D == 1)
                %--------------------- 
                if DrapeSwitch == 0
                    imagesc(NewEarray(1,1:end),NewNarray(1:end,1)',LowerMap,'AlphaData',LowerTransp);
                else
                    maxVal   = max(LowerMap(:));    minVal = min(LowerMap(:));
                    ScaledLowerMap = round(((LowerMap-minVal)./(maxVal-minVal)).*64)+64;
                    ScaledLowerMap(ScaledLowerMap<65)=65;    ScaledLowerMap(ScaledLowerMap>128)=128;
                    imagesc(NewEarray(1,1:end),NewNarray(1:end,1)',ScaledLowerMap,'AlphaData',LowerTransp);
            
                    maxVal   = max(UpperMap(:));    minVal = min(UpperMap(:));
                    ScaledUpperMap = round(((UpperMap-minVal)./(maxVal-minVal)).*64)+1;
                    ScaledUpperMap(ScaledUpperMap(:,:)<1)=1;    ScaledUpperMap(ScaledUpperMap(:,:)>64)=64;
                    imagesc(NewEarray(1,1:end),NewNarray(1:end,1)',ScaledUpperMap,'AlphaData',UpperTransp);
                    caxis([min(ScaledUpperMap(:)) max(ScaledLowerMap(:))]);
                end
                %--------------------- 
                if (Intervl > 0)
                    Contnumber = round((MaxElev-MinElev)/Intervl);      
                    contour(NewEarray(1,1:end),NewNarray(1:end,1)',CutShiftElev,Contnumber,'LineColor','black','linewidth',0.5);
                end
                %---------------------   
            else
                if DrapeSwitch == 0
                    surf(NewEarray(1,1:end),NewNarray(1:end,1)',CutShiftElev,LowerMap, 'FaceAlpha',LowerTransp,'linestyle','none');
                else
                    maxVal   = max(LowerMap(:));    minVal = min(LowerMap(:));
                    ScaledLowerMap = round(((LowerMap-minVal)./(maxVal-minVal)).*64)+64;
                    ScaledLowerMap(ScaledLowerMap<65)=65;    ScaledLowerMap(ScaledLowerMap>128)=128;
                    surf(NewEarray(1,1:end),NewNarray(1:end,1)',CutShiftElev,ScaledLowerMap, 'FaceAlpha',LowerTransp,'linestyle','none');
                    %---------------------
                    maxVal   = max(UpperMap(:));    minVal = min(UpperMap(:));
                    ScaledUpperMap = round(((UpperMap-minVal)./(maxVal-minVal)).*64)+1;
                    ScaledUpperMap(ScaledUpperMap(:,:)<1)=1;    ScaledUpperMap(ScaledUpperMap(:,:)>64)=64;
                    surf(NewEarray(1,1:end),NewNarray(1:end,1)',CutShiftElev,ScaledUpperMap, 'FaceAlpha',UpperTransp,'linestyle','none');
                end
                caxis([min(ScaledUpperMap(:)) max(ScaledLowerMap(:))]);
                %-----------------------------------
                if (Intervl > 0)
                    Contnumber = round((MaxElev-MinElev)/Intervl);      
                    contour3(NewEarray(1,1:end),NewNarray(1:end,1)',CutShiftElev,Contnumber,'LineColor','black','linewidth',0.5);
                end    
                %-----------------------------------
                view(ViewAxis(1,1),ViewAxis(1,2));
            end
            %---------------------------------------------
            %now comes the vertical back slip in the longitudinal profile view
            figure(FigNum+1);clf; hold on
            if (app.UseBlindMeasurement_CheckBox.Value == 1)
                set(gca,'XTickLabel',[]);           set(gca,'YTickLabel',[]);    
            end
            xlabel('Distance along longitudinal profile'); ylabel('Elevation');
            RedProfDist    = app.RedProfDistance_EditField.Value/app.BlindFactor;
            BlueProfDist   = app.BlueProfDistance_EditField.Value/app.BlindFactor;
            if (FlipLongProf == 1)
                 BlueProfDist2        =  BlueProfDist;                       RedProfDist2        =-RedProfDist; 
            else
                 BlueProfDist2        = -BlueProfDist;                       RedProfDist2        =RedProfDist; 
            end
            
            AxMin_X= min(min(app.BLUELongitudePoints(:,4)),min(app.REDLongitudePoints(:,4)));
            AxMax_X= max(max(app.BLUELongitudePoints(:,4)),max(app.REDLongitudePoints(:,4)));
            AxMin_Y= min(min(app.BLUELongitudePoints(:,3)),min(app.REDLongitudePoints(:,3)));
            AxMax_Y= max(max(app.BLUELongitudePoints(:,3)),max(app.REDLongitudePoints(:,3)));
            xlim([AxMin_X AxMax_X]);
            ylim([AxMin_Y AxMax_Y]);
            
            plot([0 0],[AxMin_Y AxMax_Y],'-c','LineWidth',2);
            plot([RedProfDist2 RedProfDist2],  [-AxMin_Y AxMax_Y],'--r');
            plot([BlueProfDist2 BlueProfDist2],[-AxMin_Y AxMax_Y],'--b');
            plot(app.BLUELongitudePoints(:,4),app.BLUELongitudePoints(:,3),'.b');
            plot(app.REDLongitudePoints(:,4),app.REDLongitudePoints(:,3)+OffsetVert,'.r');
            
        end

        % Button pushed function: Result_Saveresults_Button
        function Result_Saveresults_ButtonPushed(app, event)
            QualRating = str2double(app.Result_QualRate_DropDown.Value);
            %-------------------------------------------------
            [DataName,DataPath]  = uiputfile({'*.*'},'Pick common file name for results; saved figures have flag added, indicating slip amount.');
            if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
            elseif (DataName ==0),                      return;         
            end
            %-------------------------------------------------
            currDir = pwd;
            cd (DataPath);
            %-------------------------------------------------
            FigNumTrce     = app.PlotZoom_FigNum_EditField.Value;
            FigNum2        = 2 + 100* FigNumTrce; %for the Longitudinal profile+
            FigNumXcor     = app.FindOffsetFigNum_EditField.Value;
            FigNumHorzBS   = 1+100*app.BackSlip_FigNum_EditField.Value;
            FigNumVertBS   = 2+100*app.BackSlip_FigNum_EditField.Value;
            %-------------------------------------------------
            HorzXcorr      = app.CalcHorzDisplace_EditField.Value/app.BlindFactor;
            VertXcorr      = app.CalcVertDisplace_EditField.Value/app.BlindFactor;
            StretchXcorr   = app.BestStretch;
            VertBS_min     = app.Result_MinVert_EditField.Value/app.BlindFactor;
            VertBS_opt     = app.Result_OptVert_EditField.Value/app.BlindFactor;
            VertBS_max     = app.Result_MaxVert_EditField.Value/app.BlindFactor;
            HorzBS_min     = app.Result_MinHorz_EditField.Value/app.BlindFactor;
            HorzBS_opt     = app.Result_OptHorz_EditField.Value/app.BlindFactor;
            HorzBS_max     = app.Result_MaxHorz_EditField.Value/app.BlindFactor;
            SlipDir        = str2double(app.BackSlip_direct_DropDown.Value);
            if     (SlipDir == 1),              HorzSlipSign = 1;
            elseif (SlipDir == 2),              HorzSlipSign =-1;   
            end
            %-------------------------------------------------
            DrapeSwitch    = app.Drape_CheckBox.Value;
            UpperLayerType = str2double(app.ZoomBoxUpperDataSet_DropDown.Value);
            UpperString    = app.ZoomBoxUpperDataSet_DropDown.Items{UpperLayerType};
            
            UpperColMap    = str2double(app.ZoomBoxUpper_Colormap_DropDown.Value);
            InvUpperColor  = app.ZoomBoxInvertUpper_CheckBox.Value;
            UpperTransp    = app.ZoomBoxUpperTransp_EditField.Value;
            LowerLayerType = str2double(app.ZoomBoxLowerDataSet_DropDown.Value);
            LowerString    = app.ZoomBoxLowerDataSet_DropDown.Items{LowerLayerType};
            LowerColMap    = str2double(app.ZoomBoxLower_Colormap_DropDown.Value);
            InvLowerColor  = app.ZoomBoxInvertLower_CheckBox.Value;
            LowerTransp    = app.ZoomBoxLowerTransp_EditField.Value;           
            MinElev        = app.ZoomBox_minelevation_EditField.Value;
            MaxElev        = app.ZoomBox_maxelevation_EditField.Value;
            Intervl        = app.ZoomBox_contourInterval_EditField.Value;
            Azimuth        = app.ZoomBox_azimuth_EditField.Value;
            Zenith         = app.ZoomBox_zenith_EditField.Value;
            Z_fact         = app.ZoomBox_zfact_EditField.Value;
            MinSlope       = app.ZoomBox_minslope_EditField.Value;
            MaxSlope       = app.ZoomBox_maxslope_EditField.Value;
            %---------------------%--------------------- 
            if     (UpperColMap ==1),        ColMap1 = app.ColTopo;
            elseif (UpperColMap ==2),        ColMap1 = app.ColBlu2Red;
            elseif (UpperColMap ==3),        ColMap1 = gray(64);
            elseif (UpperColMap==4),         ColMap1 = parula(64);
            elseif (UpperColMap==5),         ColMap1 = jet(64);
            elseif (UpperColMap==6),         ColMap1 = hsv(64);
            end
            if     (LowerColMap ==1),        ColMap2 = app.ColTopo;
            elseif (LowerColMap ==2),        ColMap2 = app.ColBlu2Red;
            elseif (LowerColMap ==3),        ColMap2 = gray(64);
            elseif (LowerColMap==4),         ColMap2 = parula(64);
            elseif (LowerColMap==5),         ColMap2 = jet(64);
            elseif (LowerColMap==6),         ColMap2 = hsv(64);
            end
            if (InvUpperColor == 1),         ColMap1 = flipud(ColMap1);        end
            if (InvLowerColor == 1),         ColMap2 = flipud(ColMap2);        end   
            if (DrapeSwitch == 0),           CombColMap = ColMap2;
            else,                            CombColMap = [ColMap1;ColMap2];
            end
            %-------------------------------------------------
            RedProfDist    = app.RedProfDistance_EditField.Value/app.BlindFactor;
            BlueProfDist   = app.BlueProfDistance_EditField.Value/app.BlindFactor;
            DeltHorz       = app.HorzShiftDlt_EditField.Value/app.BlindFactor;
            DeltVert       = app.VertShiftDlt_EditField.Value/app.BlindFactor;
            XsecSwath      = app.ProfileSwatwidth_EditField.Value/app.BlindFactor;
            LongPSwath     = app.FeatureSwathwidth_EditField.Value/app.BlindFactor;
            Dist2Ref       = app.Result_Dist2Ref_EditField.Value;
            UTMZone        = app.Result_UTMzone_EditField.Value;
            UTMHemi        = str2double(app.Result_UTMhemi_DropDown.Value);
            Comments       = app.Result_Comment_EditField.Value;
            Cellsize       = app.Cellsize;
            FlipLongProf   = app.FlipLongProfiles_CheckBox.Value;
            %---------------------------------------------------------------
            %--------------------------------------------------------------- 
            Comp2 = strcat(CompletePath,'.txt');
            fid   = fopen(Comp2,'w');
            if (fid == -1), errordlg('The entered filename has not been found to open for writing.');    return;   end
            
            fprintf(fid,'Offset estimates from different methods\n');
            fprintf(fid,'From_calculation_(minimizing point-cloud distance):\n');
            fprintf(fid,'horz. %7.3f\n',HorzXcorr);
            fprintf(fid,'vert. %7.3f\n',VertXcorr);
            fprintf(fid,'opt_stretch %7.3f\n',StretchXcorr);
            fprintf(fid,'From_back-slipping:\n');
            fprintf(fid,'opt_horz. %7.3f\n',HorzBS_opt);
            fprintf(fid,'min_horz. %7.3f\n',HorzBS_min);
            fprintf(fid,'max_horz. %7.3f\n',HorzBS_max);
            fprintf(fid,'opt_vert. %7.3f\n',VertBS_opt);
            fprintf(fid,'min_vert. %7.3f\n',VertBS_min);
            fprintf(fid,'max_vert. %7.3f\n',VertBS_max);
            fprintf(fid,'Quality_rating: %d\n',QualRating);
            fprintf(fid,'\n');
            fprintf(fid,'DEM cell size\n');
            fprintf(fid,'%f\n',Cellsize);
            fprintf(fid,'Distance to reference point\n');
            fprintf(fid,'%f\n',Dist2Ref);
            fprintf(fid,'\n');
            fprintf(fid,'Plot Options used in analysis:\n');
            if DrapeSwitch == 1,        fprintf(fid,'Draped %s over %s data set with %f and %f transparency respectively\n',UpperString,LowerString,UpperTransp,LowerTransp);    
            else                        fprintf(fid,'Used %s data set with %f transparency\n',LowerString,LowerTransp);
            end
            if ~isnan(Intervl),         fprintf(fid,'Overlay with elevation contours; dz = %f\n',Intervl);
            else                        fprintf(fid,'No contours used\n');
            end
            fprintf(fid,'Plotting parameters:\n');
            fprintf(fid,'Hillshade (Azim/Zeni/Z-fact)\n');
            fprintf(fid,'%f  %f  %f\n',Azimuth,Zenith,Z_fact);
            fprintf(fid,'Slope (Min/Max slope in degree)\n');
            fprintf(fid,'%f  %f\n',MinSlope,MaxSlope);
            fprintf(fid,'Elevation (Min/Interval/Max value)\n');
            fprintf(fid,'%f  %f  %f\n',MinElev, Intervl, MaxElev);
            fprintf(fid,'\n');
            fprintf(fid,'Red profile distance to fault\n');
            fprintf(fid,'%f\n',RedProfDist);
            fprintf(fid,'Blue profile distance to fault\n');
            fprintf(fid,'%f\n',BlueProfDist);
            fprintf(fid,'Cross-sectional profile swath width\n');
            fprintf(fid,'%f\n',XsecSwath);
            fprintf(fid,'Longitudinal profile swath width\n');
            fprintf(fid,'%f\n',LongPSwath);
            fprintf(fid,'Profile discretization in horizontal and vertical \n');
            fprintf(fid,'%f  %f\n',DeltHorz, DeltVert);
            fprintf(fid,'Goodness of Fit results; first line is the GoF for horizontal slip; second line is (horizontal)shift distance,; third line is GoF for vertical slip; fourth line is (vertical) shift distance \n');
            
            for i = 1 : size(app.GOFAlongHoriz,2),  fprintf(fid,' %10.5f',app.GOFAlongHoriz(1,i));       end
            fprintf(fid,'\n');
            for i = 1 : size(app.GOFAlongHoriz,2),  fprintf(fid,' %10.5f',app.GOFAlongHoriz(2,i));       end
            fprintf(fid,'\n');
            for i = 1 : size(app.GOFAlongVerti,2),  fprintf(fid,' %10.5f',app.GOFAlongVerti(1,i));       end
            fprintf(fid,'\n');
            for i = 1 : size(app.GOFAlongVerti,2),  fprintf(fid,' %10.5f',app.GOFAlongVerti(2,i));       end
            fprintf(fid,'\n');
            
            fclose(fid);
            %---------------------------------------------------------------
            %---------------------------------------------------------------
            if VertBS_opt < 0,   OffVal2 = strcat('m',num2str(abs(VertBS_opt)));
            else                 OffVal2 = num2str(abs(VertBS_opt));
            end
            %---------------------------------------------------------------
            %---------------------------------------------------------------
            SaveNameA1     = strcat(CompletePath,'_Topography.jpg');
            SaveNameA2     = strcat(CompletePath,'_Tracing.jpg');
            %---------------------%---------------------%---------------------%---------------------
            %---------------------%---------------------%---------------------%---------------------
            figure(FigNumTrce); close(gcf); figure(FigNumTrce); hold on; axis tight;
            xlabel('Easting'); ylabel('Northing');
            colormap(CombColMap);
            %---------------------------------------------------------------
            if (LowerLayerType == 1)
                LowerMap                        = app.CutElev; 
                LowerMap(LowerMap < MinElev)    = MinElev;
                LowerMap(LowerMap > MaxElev)    = MaxElev;                                      
            elseif (LowerLayerType == 2)
                asp            = app.CutAsp;                   grad                  = app.CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0; % set hillshade values to min of 0.
                LowerMap       = Hshd; %required for back-slip later on  
 
            elseif (LowerLayerType == 3)
                LowerMap                      = atan(app.CutGrad).*180/pi; %steepest slope
                LowerMap(LowerMap < MinSlope) = MinSlope;
                LowerMap(LowerMap > MaxSlope) = MaxSlope; 
            elseif (LowerLayerType == 4)
                LowerMap                      = app.CutAsp.*180/pi;  
            elseif (LowerLayerType == 5)
                LowerMap                          = app.CutAsp.*180/pi;
                ScaledSlop                        = atan(app.CutGrad).*180/pi;
                ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                LowerTransp= ScaledSlop./max(ScaledSlop(:)).*LowerTransp;
            end
            if (Intervl > 0)
                MaxVal   = max(LowerMap(:));    MinVal = min(LowerMap(:));
                LowerMap = MinElev + (LowerMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%--------------------
            if (UpperLayerType == 1)
                UpperMap                        = app.CutElev; 
                UpperMap(UpperMap < MinElev)    = MinElev;
                UpperMap(UpperMap > MaxElev)    = MaxElev;                     
            elseif (UpperLayerType == 2)
                asp            = app.CutAsp;                   grad                  = app.CutGrad;
                Azimuth        = Azimuth-90;                   Azimuth(Azimuth>=360) = Azimuth-360;
                Azimuth        = Azimuth.*(pi/180);            Zenith                = (90-Zenith) * (pi/180);             
                grad           = atan(Z_fact*grad); %steepest slope
                asp(asp<pi)    = asp(asp<pi)+(pi/2);           
                asp(asp<0)     = asp(asp<0)+(2*pi);
                Hshd           = 255.0*( (cos(Zenith).*cos(grad) ) + ( sin(Zenith).*sin(grad).*cos(Azimuth-asp)) ); % ESRIs algorithm
                Hshd(Hshd<0)   = 0;    %set hillshade values to min of 0.
                UpperMap       = Hshd; %required for back-slip later on  
            elseif (UpperLayerType == 3)
                UpperMap                      = atan(app.CutGrad).*180/pi; %steepest slope
                UpperMap(UpperMap < MinSlope) = MinSlope;
                UpperMap(UpperMap > MaxSlope) = MaxSlope; 
            elseif (UpperLayerType == 4)
                UpperMap                      = app.CutAsp.*180/pi;  
            elseif (UpperLayerType == 5)
                UpperMap                          = app.CutAsp.*180/pi;
                ScaledSlop                        = atan(app.CutGrad).*180/pi;
                ScaledSlop(ScaledSlop < MinSlope) = MinSlope;
                ScaledSlop(ScaledSlop > MaxSlope) = MaxSlope; 
                UpperTransp= ScaledSlop./max(ScaledSlop(:)).*UpperTransp;
            end
            if (Intervl > 0)
                MaxVal   = max(UpperMap(:));    MinVal = min(UpperMap(:));
                UpperMap = MinElev + (UpperMap - MinVal)/(MaxVal-MinVal)*(MaxElev-MinElev);
            end
            %---------------------%---------------------%--------------------
            if (DrapeSwitch == 0)
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',LowerMap,'AlphaData',LowerTransp);
            else
                maxVal   = max(LowerMap(:));    minVal = min(LowerMap(:));
                ScaledLowerMap = round(((LowerMap-minVal)./(maxVal-minVal)).*64)+64;
                ScaledLowerMap(ScaledLowerMap<65)=65;    ScaledLowerMap(ScaledLowerMap>128)=128;
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',ScaledLowerMap,'AlphaData',LowerTransp);                
                maxVal   = max(UpperMap(:));    minVal = min(UpperMap(:));
                ScaledUpperMap = round(((UpperMap-minVal)./(maxVal-minVal)).*64)+1;
                ScaledUpperMap(ScaledUpperMap(:,:)<1)=1;    ScaledUpperMap(ScaledUpperMap(:,:)>64)=64;
                imagesc(app.EarrayZoom(1,1:end),app.NarrayZoom(1:end,1)',ScaledUpperMap,'AlphaData',UpperTransp);
                caxis([min(ScaledUpperMap(:)) max(ScaledLowerMap(:))]);
            end
            %---------------------%---------------------%--------------------- 
            if (Intervl > 0)
                Contnumber = round((MaxElev-MinElev)/Intervl);      
                contour(app.EarrayZoom,app.NarrayZoom,app.CutElev,Contnumber,'LineColor','black','linewidth',0.5);
            end
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            print(FigNumTrce, '-r300', '-djpeg', SaveNameA1); 
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            app.FaultLineDot1 = plot(app.FaultLinePts(1,1),app.FaultLinePts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2 = plot(app.FaultLinePts(2,1),app.FaultLinePts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLine     = plot([app.FaultLinePts(1,1) app.FaultLinePts(2,1)],[app.FaultLinePts(1,2) app.FaultLinePts(2,2)],'color','cyan','linewidth',2);
            %-------------------------------------------------
            FaultStart   = app.FaultLinePts(1,:);               FaultEnd     = app.FaultLinePts(2,:);      
            FaultVect    = FaultEnd-FaultStart;                 FaultTrend   = atan2(FaultVect(1,1),FaultVect(1,2));          
            delta_x      = RedProfDist*cos(FaultTrend);         delta_y      = RedProfDist*-sin(FaultTrend);
            
            DownStart      = [(FaultStart(1,1)+delta_x); (FaultStart(1,2)+delta_y)];
            DownEnd        = [(FaultEnd(1,1)  +delta_x); (FaultEnd(1,2)  +delta_y)];           
            app.REDdot     = plot(DownStart(1,1),DownStart(2,1),'O','MarkerEdgeColor','k','MarkerFaceColor','red','MarkerSize',8);
            app.REDprofile = plot([DownStart(1,1) DownEnd(1,1)],[DownStart(2,1) DownEnd(2,1)],'red','linewidth',2);
            app.REDpts     = [DownStart(1,1), DownStart(2,1);DownEnd(1,1), DownEnd(2,1)];
            %-------------------------------------------------   
            FaultStart   = app.FaultLinePts(1,:);               FaultEnd     = app.FaultLinePts(2,:);      
            FaultVect    = FaultEnd-FaultStart;                 FaultTrend   = atan2(FaultVect(1,1),FaultVect(1,2));          
            delta_x      = BlueProfDist*-cos(FaultTrend);        delta_y      = BlueProfDist*sin(FaultTrend);
            
            DownStart      = [(FaultStart(1,1)+delta_x); (FaultStart(1,2)+delta_y)];
            DownEnd        = [(FaultEnd(1,1)  +delta_x); (FaultEnd(1,2)  +delta_y)];  
            app.BLUEdot    = plot(DownStart(1,1),DownStart(2,1),'O','MarkerEdgeColor','k','MarkerFaceColor','blue','MarkerSize',8);
            app.BLUEprofile= plot([DownStart(1,1) DownEnd(1,1)],[DownStart(2,1) DownEnd(2,1)],'blue','linewidth',2);
            app.BLUEpts    = [DownStart(1,1), DownStart(2,1);DownEnd(1,1), DownEnd(2,1)];
            %-------------------------------------------------   
            app.CropDot1RED = plot(app.CropDotPts(1,1),app.CropDotPts(1,2),'o','MarkerEdgeColor','k','MarkerFaceColor','yellow','MarkerSize',6);
            app.CropDot2RED = plot(app.CropDotPts(2,1),app.CropDotPts(2,2),'o','MarkerEdgeColor','k','MarkerFaceColor','yellow','MarkerSize',6);
            %---------------------%---------------------%---------------------
            app.REDTracePnts = [];          TempHandleLine = [];
            app.REDTraceLine = [];          TempHandlePt   = [];
            for (i = 1 : size(app.REDTracePoints,1))
                if (i < size(app.REDTracePoints,1))
                    TempHandle   = plot(app.REDTracePoints(i,1),app.REDTracePoints(i,2),'square','MarkerEdgeColor','k','MarkerFaceColor','red','MarkerSize',6);
                else
                    TempHandle   = plot(app.REDTracePoints(i,1),app.REDTracePoints(i,2),'square','MarkerEdgeColor','k','MarkerFaceColor','magenta','MarkerSize',6);
                end
                TempHandlePt = [TempHandlePt; TempHandle];   
                if (i > 1)
                    TempHandle    = plot([app.REDTracePoints(i-1,1), app.REDTracePoints(i,1)],[app.REDTracePoints(i-1,2), app.REDTracePoints(i,2)],'-r');
                    TempHandleLine= [TempHandleLine; TempHandle];     
                end
            end    
            app.REDTraceLine   = TempHandleLine;
            app.REDTracePnts   = TempHandlePt;
            %------------------------------------------%------------------------------------------
            app.BLUETracePnts = [];          TempHandleLine = [];
            app.BLUETraceLine = [];          TempHandlePt   = [];
            for (i = 1 : size(app.BLUETracePoints,1))
                if (i < size(app.BLUETracePoints,1))
                    TempHandle   = plot(app.BLUETracePoints(i,1),app.BLUETracePoints(i,2),'square','MarkerEdgeColor','k','MarkerFaceColor','blue','MarkerSize',6);
                else
                    TempHandle   = plot(app.BLUETracePoints(i,1),app.BLUETracePoints(i,2),'square','MarkerEdgeColor','k','MarkerFaceColor','magenta','MarkerSize',6);
                end
                TempHandlePt = [TempHandlePt; TempHandle];   
                if (i > 1)
                    TempHandle    = plot([app.BLUETracePoints(i-1,1), app.BLUETracePoints(i,1)],[app.BLUETracePoints(i-1,2), app.BLUETracePoints(i,2)],'-b');
                    TempHandleLine= [TempHandleLine; TempHandle];     
                end
            end    
            app.BLUETraceLine   = TempHandleLine;
            app.BLUETracePnts   = TempHandlePt;
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            print(FigNumTrce, '-r300', '-djpeg', SaveNameA2); 
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            SaveNameB      = strcat(CompletePath,'_Longi_Profile.jpg');
            figure(FigNum2); close(gcf);    figure(FigNum2); hold on;            
             if (FlipLongProf == 1)
                 BlueProfDist2        =  BlueProfDist;                       RedProfDist2        =-RedProfDist; 
             else
                 BlueProfDist2        = -BlueProfDist;                       RedProfDist2        =RedProfDist; 
             end
            
            AxMin_X= min(min(app.BLUELongitudePoints(:,4)),min(app.REDLongitudePoints(:,4)));
            AxMax_X= max(max(app.BLUELongitudePoints(:,4)),max(app.REDLongitudePoints(:,4)));
            AxMin_Y= min(min(app.BLUELongitudePoints(:,3)),min(app.REDLongitudePoints(:,3)));
            AxMax_Y= max(max(app.BLUELongitudePoints(:,3)),max(app.REDLongitudePoints(:,3)));
            %-------------------------------------------
            xlabel('Distance along longitudinal profile (m)'); ylabel('Elevation (m)');      
            xlim([AxMin_X AxMax_X]);
            ylim([AxMin_Y AxMax_Y]);
            
            plot([0 0],[AxMin_Y AxMax_Y],'-c','LineWidth',2);
            plot([RedProfDist2 RedProfDist2],  [-AxMin_Y AxMax_Y],'--r');
            plot([BlueProfDist2 BlueProfDist2],[-AxMin_Y AxMax_Y],'--b');
            plot(app.BLUELongitudePoints(:,4),app.BLUELongitudePoints(:,3),'.b');
            plot(app.REDLongitudePoints(:,4), app.REDLongitudePoints(:,3), '.r');
            %-------------------------------------------
            print(FigNum2, '-r300', '-djpeg', SaveNameB); 
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            %set(gcf,'position',[440 378 560 420]); this is for resetting
            figure(FigNumXcor);        set(gcf,'position',[440 378 560 420]);
            
            subplot(3,3,1:3);hold on  
            set(gca,'XTickLabelMode','auto');           set(gca,'YTickLabelMode','auto');   
            subplot(3,3,4:6);hold on  
            set(gca,'XTickLabelMode','auto');           set(gca,'YTickLabelMode','auto'); 
            subplot(3,3,7);hold on  
            set(gca,'XTickLabelMode','auto');           set(gca,'YTickLabelMode','auto');
            subplot(3,3,8);hold on  
            set(gca,'XTickLabelMode','auto');           set(gca,'YTickLabel','');
            subplot(3,3,9);hold on  
            set(gca,'XTickLabelMode','auto');           set(gca,'YTickLabel','');
            %---------------
            SaveNameC = strcat(CompletePath,'_OffsetCalculation.jpg');
            print(FigNumXcor, '-r300', '-djpeg', SaveNameC); 
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            figure(FigNumVertBS); close(gcf);   figure(FigNumVertBS);    hold on;
            SaveNameD      = strcat(CompletePath,'_LongiBS_with_',OffVal2,'mVert.jpg');
            
            xlabel('Distance along longitudinal profile (m)'); ylabel('Elevation (m)');      
            xlim([AxMin_X AxMax_X]);
            ylim([AxMin_Y AxMax_Y]);
            
            plot([0 0],[AxMin_Y AxMax_Y],'-c','LineWidth',2);
            plot([RedProfDist2 RedProfDist2],  [-AxMin_Y AxMax_Y],'--r');
            plot([BlueProfDist2 BlueProfDist2],[-AxMin_Y AxMax_Y],'--b');
            plot(app.BLUELongitudePoints(:,4),app.BLUELongitudePoints(:,3),'.b');
            plot(app.REDLongitudePoints(:,4),app.REDLongitudePoints(:,3)+VertBS_opt,'.r');
            
            print(FigNumVertBS, '-r300', '-djpeg', SaveNameD); 
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            SaveNameA1   = strrep(SaveNameA1,DataPath,'');                SaveNameA2 = strrep(SaveNameA2,DataPath,'');        
            SaveNameB    = strrep(SaveNameB,DataPath,'');                 SaveNameC  = strrep(SaveNameC,DataPath,'');        
            SaveNameD    = strrep(SaveNameD,DataPath,'');  
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            for iOffs = 1 : 3 %for min/opt/max displacement
                %--------------------- 
                if     iOffs == 1,   OffsetValue    =HorzBS_min;            
                elseif iOffs == 2,   OffsetValue    =HorzBS_opt;       
                elseif iOffs == 3,   OffsetValue    =HorzBS_max;    
                end
                %------------------------------------------
                app.BackSlip_HorzSlip_EditField.Value = OffsetValue;
                app.BackSlip_VertSlip_EditField.Value = VertBS_opt;
                %------------------------------------------
                BackslipDEM_ButtonPushed(app);
                %---------------------------------------------------------------
                if (SlipDir  == 1),  OffVal = strcat('RL_',num2str(abs(OffsetValue)));
                else                 OffVal = strcat('LL_',num2str(abs(OffsetValue)));
                end
                if (VertBS_opt < 0),OffVal2 = strcat('DN_',num2str(abs(VertBS_opt)));
                else                OffVal2 = strcat('UP_',num2str(abs(VertBS_opt)));
                end
                %------------------------------------------   
                SaveName  = strcat(CompletePath,'_with_',OffVal,'mHorz_and_',OffVal2,'mVert.jpg');
                if     iOffs == 1, SaveNameF = strrep(SaveName,DataPath,'');
                elseif iOffs == 2, SaveNameG = strrep(SaveName,DataPath,'');
                elseif iOffs == 3, SaveNameH = strrep(SaveName,DataPath,'');
                end
                %------------------------------------------   
                figure(FigNumHorzBS);
                print(FigNumHorzBS, '-r300', '-djpeg', SaveName); 
                %---------------------------------------------------------------   
            end
            %------------------------------------------%------------------------------------------
            %------------------------------------------%------------------------------------------
            %pick the first fault point; from UpTracPoints and DnTracPoints and determine their distance...that's the offset purely from tracing piercing lines
            FltPntBLUE = app.BLUETracePoints(app.BLUETracePoints(:,3) == 1, :);         FltPntBLUE = FltPntBLUE(1,:);
            FltPntRED  = app.REDTracePoints( app.REDTracePoints(:,3)  == 1, :);         FltPntRED  = FltPntRED(1,:);
            Midx       = (FltPntRED(1,1)+FltPntBLUE(1,1))/2;                            Midy       = (FltPntRED(1,2)+FltPntBLUE(1,2))/2;
            [lati,long]= GetGeoCoordinates(app, Midx,Midy,UTMZone,UTMHemi); %offset position in lat/long -for kmz file
            %--------------------------------------------------------

            imwidth        = 560;                           
            imhight        = 420;
            %---------------------------
            imwrite(app.Symbol,'A00000.png','png');

            fid  = fopen('doc.kml','w');
            if (fid == -1),         errordlg('The entered filename 1 has not been found. Change filename.');        return;         end
            
            fprintf(fid, '<?xml version="1.0" encoding="UTF-8"?> \n');
            fprintf(fid, '<kml xmlns="http://www.opengis.net/kml/2.2" \n');
            fprintf(fid, 'xmlns:gx="http://www.google.com/kml/ext/2.2"> \n');
            fprintf(fid, '<Document> \n');
            fprintf(fid, '<name>%s location</name> \n',DataName);
            fprintf(fid, '<Placemark> \n');
            fprintf(fid, '<name>%s location</name> \n',DataName);
            fprintf(fid, '<styleUrl>#IconStyle00</styleUrl> \n');
            
            fprintf(fid, '<description> \n');
            fprintf(fid, '<![CDATA[ \n');
            fprintf(fid, '<h4>Offset location and measurement</h4>\n');
            fprintf(fid,'<table border=1 cellpadding="10"> \n');
            fprintf(fid,'<tr> \n');
            fprintf(fid,'<th>Latitude</th> \n');
            fprintf(fid,'<th>Longitude</th> \n');
            fprintf(fid,'<th>Dist. to Ref. (km)</th> \n');
            fprintf(fid,'<th>Best Horz. Offset (m)</th> \n');
            fprintf(fid,'<th> Horz. Offset Range (m)</th> \n');
            fprintf(fid,'<th>Best Vert. Offset (m)</th> \n');
            fprintf(fid,'<th> Vert. Offset Range (m)</th> \n');
            fprintf(fid,'<th>Comment</th> \n');
            fprintf(fid,'</tr> \n');
            fprintf(fid,'<center> \n');
            fprintf(fid,'<tr> \n');
            fprintf(fid,'<td align="center"> %3.5f</td> \n',lati);
            fprintf(fid,'<td align="center"> %3.5f</td> \n',long);
            fprintf(fid,'<td align="center">%3.2f km</td> \n',Dist2Ref);
            fprintf(fid,'<td align="center">%3.1f</td> \n',HorzBS_opt);
            fprintf(fid,'<td align="center">%3.1f - %3.1f</td> \n',HorzBS_min,HorzBS_max);
            fprintf(fid,'<td align="center">%3.1f</td> \n',VertBS_opt);
            fprintf(fid,'<td align="center">%3.1f - %3.1f</td> \n',VertBS_min,VertBS_max);
            fprintf(fid,'<td align="center">%s</td> \n',Comments);
            fprintf(fid,'</tr> \n');
            fprintf(fid,'</center> \n');
            fprintf(fid,'</table> \n');           
            fprintf(fid,'%s%s%s\n',app.bracket1,app.bracket1,app.bracket2);
            fprintf(fid, '</description>\n');
            %------------------------------------------------
            fprintf(fid, '<Point>\n');
            fprintf(fid, '<extrude>1</extrude> \n');
            fprintf(fid, '<altitudeMode>relativeToGround</altitudeMode> \n');
            fprintf(fid, '<coordinates>%3.8f,%3.8f,0.000000 </coordinates>\n',long,lati);
            fprintf(fid, '</Point> \n');
            fprintf(fid, '</Placemark> \n');
            fprintf(fid, '<Style id="IconStyle00"> \n');
            fprintf(fid, '<IconStyle> \n');
            fprintf(fid, '<Icon><href>A00000.png</href></Icon> \n');
            fprintf(fid, '<scale>0.300000</scale> \n');
            fprintf(fid, '</IconStyle> \n');
            fprintf(fid, '<LabelStyle> \n');
            fprintf(fid, '<color>gg000000</color> \n');
            fprintf(fid, '<scale>1.00000</scale> \n');
            fprintf(fid, '</LabelStyle> \n');
            fprintf(fid, '</Style> \n');
            fprintf(fid, '</Document> \n');
            fprintf(fid, '</kml>');
            %------------------------------------------------
            fclose(fid);
            %0000000000000000000000000000000000000000000000000 
            DatNme = strcat(DataName,'_location');
            zip(DatNme,{'A00000.png','doc.kml'});
            str = computer;
            if ~isempty(strfind(str,'MAC')) 
                fid      = fopen('MakeKMZ.sh','w');
                fprintf(fid,'mv %s.zip %s.kmz \n',DatNme, DatNme);
                fprintf(fid,'rm doc.kml \n');
                fprintf(fid,'rm A00000.png \n');
                fclose(fid);
                !chmod 755 MakeKMZ.sh
                !./MakeKMZ.sh
                !rm MakeKMZ.sh
            elseif ~isempty(strfind(str,'WIN'))
                fid      = fopen('MakeKMZ.bat','w');
                fprintf(fid,'rename %s.zip  %s.kmz \n',DatNme, DatNme);
                fprintf(fid,'del doc.kml \n');
                fprintf(fid,'del A00000.png \n');
                fclose(fid);
                !./MakeKMZ.bat 
                !del MakeKMZ.bat
            end
            %0000000000000000000000000000000000000000000000000 
            SaveName2 = strcat(DataName,'.html');
            fid  = fopen(SaveName2,'w');
            if (fid == -1),     errordlg('The entered filename 1 has not been found. Change filename.');        return;     end
            
            fprintf(fid, '<!DOCTYPE html PUBLIC "-//W3C//DTD XHTML 1.0 Transitional//EN" "http://www.w3.org/TR/xhtml1/DTD/xhtml1-transitional.dtd">  \n');
            fprintf(fid, '<html xmlns="http://www.w3.org/1999/xhtml">  \n');
            fprintf(fid, '<head> \n');
            fprintf(fid, '<meta http-equiv="Content-Type" content="text/html; charset=utf-8" /> \n');
            fprintf(fid, '<title>%s, Offset Measurements</title> \n',DataName);
            fprintf(fid, '<style type="text/css">  \n');
            fprintf(fid, '</style></head> \n');
            fprintf(fid, '<body> \n');
            
            fprintf(fid, '<h4>Offset location and measurement</h4>\n');
            fprintf(fid,'<table border=1 cellpadding="10"> \n');
            fprintf(fid,'<tr> \n');
            fprintf(fid,'<th>Latitude</th> \n');
            fprintf(fid,'<th>Longitude</th> \n');
            fprintf(fid,'<th>Dist. to Ref. (km)</th> \n');
            fprintf(fid,'<th>Best Horz. Offset (m)</th> \n');
            fprintf(fid,'<th> Horz. Offset Range (m)</th> \n');
            fprintf(fid,'<th>Best Vert. Offset (m)</th> \n');
            fprintf(fid,'<th> Vert. Offset Range (m)</th> \n');
            fprintf(fid,'<th>Comment</th> \n');
            fprintf(fid,'</tr> \n');
            fprintf(fid,'<center> \n');
            fprintf(fid,'<tr> \n');
            fprintf(fid,'<td align="center"> %3.5f</td> \n',lati);
            fprintf(fid,'<td align="center"> %3.5f</td> \n',long);
            fprintf(fid,'<td align="center">%3.2f km</td> \n',Dist2Ref);
            fprintf(fid,'<td align="center">%3.1f</td> \n',HorzBS_opt);
            fprintf(fid,'<td align="center">%3.1f - %3.1f</td> \n',HorzBS_min,HorzBS_max);
            fprintf(fid,'<td align="center">%3.1f</td> \n',VertBS_opt);
            fprintf(fid,'<td align="center">%3.1f - %3.1f</td> \n',VertBS_min,VertBS_max);
            fprintf(fid,'<td align="center">%s</td> \n',Comments);
            fprintf(fid,'</tr> \n');
            fprintf(fid,'</center> \n');
            fprintf(fid,'</table> \n');
            %------------------------------------------------
            fprintf(fid,'<h4>Figures of current and back-slipped topography</h4>\n');
            fprintf(fid,'<table border=1 cellpadding="10">\n');
            fprintf(fid,'<tr>\n');
            fprintf(fid,'<td> Basemap without line work</td>\n');
            fprintf(fid,'<td> Basemap with line work </td>\n');
            fprintf(fid,'<td> Longitudinal profile</td>\n');
            fprintf(fid,'</tr>\n');
            fprintf(fid,'<tr>\n');
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameA1,imwidth,imhight);
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameA2,imwidth,imhight);
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameB,imwidth,imhight);
            fprintf(fid,'</tr>\n');
            fprintf(fid,'<tr>\n');
            fprintf(fid,'<td> Basemap plot with minimum horz. back-slip and opt. vert. back-slip</td>\n');
            fprintf(fid,'<td> Basemap plot with optimal horz. back-slip and opt. vert. back-slip</td>\n');
            fprintf(fid,'<td> Basemap plot with maximum horz. back-slip and opt. vert. back-slip</td>\n');
            fprintf(fid,'</tr>\n');
            fprintf(fid,'<tr>\n'); 
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameF,imwidth,imhight);
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameG,imwidth,imhight);
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameH,imwidth,imhight);
            fprintf(fid,'</tr>\n');
            fprintf(fid,'<tr>\n');
            fprintf(fid,'<td> Cross-sectional profiles and fitting result </td>\n');
            fprintf(fid,'<td> Back-slipped longitudinal profile</td>\n');
            fprintf(fid,'<td> ');
            fprintf(fid,'</tr>\n');
            fprintf(fid,'<tr>\n'); 
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameC,imwidth,imhight);
            fprintf(fid,'<td><img border="0" src="%s" width="%3.0f"height="%3.0f"></td>\n',SaveNameD,imwidth,imhight);
            fprintf(fid,'<td>');
            fprintf(fid,'</tr>\n');
            fprintf(fid,'</table> \n');
            fclose(fid);
            %0000000000000000000000000000000000000000000000000
            cd (currDir);     
        end

        % Button pushed function: RotateTrace_Button
        function RotateTrace_ButtonPushed(app, event)
 
            CleanLines(app);   
            if isempty(app.CutElev),  app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            RotatVal = app.Rotateby_EditField.Value;
            if (RotatVal == 0),                      return;         end  
            CleanLineWork(app);
            FigNum         = app.PlotZoom_FigNum_EditField.Value;
            %---------------------------------------
            figure(FigNum); hold on;
            %---------------------------------------

            faultlinevectold = [(app.FaultLinePts(2,1)-app.FaultLinePts(1,1));(app.FaultLinePts(2,2)-app.FaultLinePts(1,2))];
            faultangleold    = atan2(faultlinevectold(1,1),faultlinevectold(2,1));
            faultanglenew    = faultangleold +RotatVal*pi/180;
            
            faultcenter     = [(sum(app.FaultLinePts(:,1))/2),(sum(app.FaultLinePts(:,2))/2)];
            vectlength      = sqrt(sum(faultlinevectold.^2));
            deltx           = sin(faultanglenew)*vectlength/2;      delty           = cos(faultanglenew)*vectlength/2;
            xStart          = faultcenter(1,1)-deltx;               xEnd            = faultcenter(1,1)+deltx;
            yStart          = faultcenter(1,2)-delty;               yEnd            = faultcenter(1,2)+delty;
            
            app.FaultLinePts   = [xStart,yStart;xEnd,yEnd];
            app.FaultLine      = plot([xStart xEnd],[yStart yEnd],'cyan','linewidth',2);
            app.FaultLineDot1  = plot(xStart,yStart,'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);
            app.FaultLineDot2  = plot(xEnd,yEnd,'o','MarkerEdgeColor','k','MarkerFaceColor','cyan','MarkerSize',6);        
        end

        % Value changed function: RedProfDistance_EditField
        function RedProfDistance_EditFieldValueChanged(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";           return;     end
            %-------------------------------------------------
            ProfDist  = app.RedProfDistance_EditField.Value;
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %-------------------------------------------------
            if ~isempty(app.REDprofile),       delete(app.REDprofile);          app.REDprofile    = [];  end
            if ~isempty(app.REDdot),           delete(app.REDdot);              app.REDdot        = [];  end 
            if ~isempty(app.CropDot1RED),      delete(app.CropDot1RED);         app.CropDot1RED   = [];  end
            if ~isempty(app.CropDot2RED),      delete(app.CropDot2RED);         app.CropDot2RED   = [];  end
            if ~isempty(app.REDBox),           delete(app.REDBox);              app.REDBox        = [];  end 
            if ~isempty(app.REDpts),           app.REDpts        = [];  end 
            %-------------------------------------------------
            FaultStart   = app.FaultLinePts(1,:);               FaultEnd     = app.FaultLinePts(2,:);      
            FaultVect    = FaultEnd-FaultStart;                 FaultTrend   = atan2(FaultVect(1,1),FaultVect(1,2));           

            delta_x      = ProfDist.*cos(FaultTrend);           delta_y      = ProfDist.*-sin(FaultTrend);
            
            DownStart      = [(FaultStart(1,1)+delta_x*app.BlindFactor); (FaultStart(1,2)+delta_y*app.BlindFactor)];
            DownEnd        = [(FaultEnd(1,1)  +delta_x*app.BlindFactor); (FaultEnd(1,2)  +delta_y*app.BlindFactor)];
            
            app.REDdot     = plot(DownStart(1,1),DownStart(2,1),'O','MarkerEdgeColor','k','MarkerFaceColor','red','MarkerSize',8);
            app.REDprofile = plot([DownStart(1,1) DownEnd(1,1)],[DownStart(2,1) DownEnd(2,1)],'red','linewidth',2);
            app.REDpts     = [DownStart(1,1), DownStart(2,1);DownEnd(1,1), DownEnd(2,1)];
            %-------------------------------------------------   
        end

        % Value changed function: BlueProfDistance_EditField
        function BlueProfDistance_EditFieldValueChanged(app, event)
            CleanLines(app);   
            if isempty(app.CutElev),       app.ScreenOut_Line1_EditField.Value = "DEM was not loaded or zoom-box not defined";       return;     end
            if isempty(app.FaultLinePts),  app.ScreenOut_Line1_EditField.Value = "Fault trace not defined; do that first";           return;     end
            %-------------------------------------------------
            ProfDist  = app.BlueProfDistance_EditField.Value;
            FigNum    = app.PlotZoom_FigNum_EditField.Value;
            figure(FigNum); hold on;
            %-------------------------------------------------
            if ~isempty(app.BLUEprofile),       delete(app.BLUEprofile);          app.BLUEprofile    = [];  end
            if ~isempty(app.BLUEdot),           delete(app.BLUEdot);              app.BLUEdot        = [];  end 
            if ~isempty(app.BLUEBox),           delete(app.BLUEBox);              app.BLUEBox        = [];  end 
            if ~isempty(app.BLUEpts),           app.BLUEpts        = [];  end 
            %-------------------------------------------------
            FaultStart   = app.FaultLinePts(1,:);               FaultEnd     = app.FaultLinePts(2,:);      
            FaultVect    = FaultEnd-FaultStart;                 FaultTrend   = atan2(FaultVect(1,1),FaultVect(1,2));           

            delta_x      = ProfDist.*-cos(FaultTrend);           delta_y      = ProfDist.*sin(FaultTrend);
            
            DownStart       = [(FaultStart(1,1)+delta_x*app.BlindFactor); (FaultStart(1,2)+delta_y*app.BlindFactor)];
            DownEnd         = [(FaultEnd(1,1)  +delta_x*app.BlindFactor); (FaultEnd(1,2)  +delta_y*app.BlindFactor)];

            app.BLUEdot     = plot(DownStart(1,1),DownStart(2,1),'O','MarkerEdgeColor','k','MarkerFaceColor','blue','MarkerSize',8);
            app.BLUEprofile = plot([DownStart(1,1) DownEnd(1,1)],[DownStart(2,1) DownEnd(2,1)],'blue','linewidth',2);
            app.BLUEpts     = [DownStart(1,1), DownStart(2,1);DownEnd(1,1), DownEnd(2,1)];
            %------------------------------------------------- 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.datainputPanel = uipanel(app.UIFigure);
            app.datainputPanel.Title = '1) data input';
            app.LoadDEM_Button = uibutton(app.datainputPanel, 'push');
            app.LoadDEM_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadDEM_ButtonPushed, true);
            app.LoadDEM_Button.Text = 'a) Load DEM';
            app.smoothdatawboxcaraverageLabel = uilabel(app.datainputPanel);
            app.smoothdatawboxcaraverageLabel.Text = 'smooth data w/ box-car average';
            app.UseBoxcarAveraging_DropDown = uidropdown(app.datainputPanel);
            app.UseBoxcarAveraging_DropDown.Items = {'no', '3x3', '5x5', '7x7', '9x9', '11x11'};
            app.UseBoxcarAveraging_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.UseBoxcarAveraging_DropDown.Value = '1';
            app.PlotOverviewhillshade_Button = uibutton(app.datainputPanel, 'push');
            app.PlotOverviewhillshade_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotOverviewhillshade_ButtonPushed, true);
            app.PlotOverviewhillshade_Button.Text = 'b) Plot overview hillshade';
            app.FigEditFieldLabel = uilabel(app.datainputPanel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.OverviewFigNum_EditField = uieditfield(app.datainputPanel, 'numeric');
            app.OverviewFigNum_EditField.Limits = [0 Inf];
            app.OverviewFigNum_EditField.RoundFractionalValues = 'on';
            app.OverviewFigNum_EditField.ValueDisplayFormat = '%d';
            app.OverviewFigNum_EditField.Value = 2;
            app.LoadPrevMeasurement_Button = uibutton(app.datainputPanel, 'push');
            app.LoadPrevMeasurement_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPrevMeasurement_ButtonPushed, true);
            app.LoadPrevMeasurement_Button.Enable = 'off';
            app.LoadPrevMeasurement_Button.Visible = 'off';
            app.LoadPrevMeasurement_Button.Text = 'Load prev. measurement';
            app.azimuthEditFieldLabel = uilabel(app.datainputPanel);
            app.azimuthEditFieldLabel.Text = 'azimuth';
            app.OverviewAzimuth_EditField = uieditfield(app.datainputPanel, 'numeric');
            app.OverviewAzimuth_EditField.Limits = [0 360];
            app.OverviewAzimuth_EditField.RoundFractionalValues = 'on';
            app.OverviewAzimuth_EditField.ValueDisplayFormat = '%d';
            app.OverviewAzimuth_EditField.Value = 140;
            app.zenithEditFieldLabel = uilabel(app.datainputPanel);
            app.zenithEditFieldLabel.Text = 'zenith';
            app.OverviewZenith_EditField = uieditfield(app.datainputPanel, 'numeric');
            app.OverviewZenith_EditField.Limits = [0 90];
            app.OverviewZenith_EditField.RoundFractionalValues = 'on';
            app.OverviewZenith_EditField.ValueDisplayFormat = '%d';
            app.OverviewZenith_EditField.Value = 30;
            app.zfactEditFieldLabel = uilabel(app.datainputPanel);
            app.zfactEditFieldLabel.Text = 'z-fact.';
            app.OverviewZfact_EditField = uieditfield(app.datainputPanel, 'numeric');
            app.OverviewZfact_EditField.ValueDisplayFormat = '%4.2f';
            app.OverviewZfact_EditField.Value = 1;
            app.ImportCoordType_DropDown = uidropdown(app.datainputPanel);
            app.ImportCoordType_DropDown.Items = {'UTM', 'DD'};
            app.ImportCoordType_DropDown.ItemsData = {'1', '2'};
            app.ImportCoordType_DropDown.Value = '1';
            app.datadisplayoptionsPanel = uipanel(app.UIFigure);
            app.datadisplayoptionsPanel.Title = '2) data display options';
            app.DefineZoombox_Button = uibutton(app.datadisplayoptionsPanel, 'push');
            app.DefineZoombox_Button.ButtonPushedFcn = createCallbackFcn(app, @DefineZoombox_ButtonPushed, true);
            app.DefineZoombox_Button.Text = 'a) Define zoom box';
            app.Drape_CheckBox = uicheckbox(app.datadisplayoptionsPanel);
            app.Drape_CheckBox.Text = 'drape';
            app.Drape_CheckBox.Value = true;
            app.withLabel = uilabel(app.datadisplayoptionsPanel);
            app.withLabel.Text = 'with';
            app.ZoomBoxUpperDataSet_DropDown = uidropdown(app.datadisplayoptionsPanel);
            app.ZoomBoxUpperDataSet_DropDown.Items = {'topography', 'hillshade', 'slope', 'aspect', 'slope+aspect'};
            app.ZoomBoxUpperDataSet_DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ZoomBoxUpperDataSet_DropDown.Value = '5';
            app.transparencyandLabel = uilabel(app.datadisplayoptionsPanel);
            app.transparencyandLabel.Text = 'transparency and';
            app.ZoomBoxUpperTransp_EditField = uieditfield(app.datadisplayoptionsPanel, 'numeric');
            app.ZoomBoxUpperTransp_EditField.Limits = [0 1];
            app.ZoomBoxUpperTransp_EditField.ValueDisplayFormat = '%3.2f';
            app.ZoomBoxUpperTransp_EditField.Value = 0.7;
            app.colormapLabel = uilabel(app.datadisplayoptionsPanel);
            app.colormapLabel.Text = 'color-map';
            app.ZoomBoxUpper_Colormap_DropDown = uidropdown(app.datadisplayoptionsPanel);
            app.ZoomBoxUpper_Colormap_DropDown.Items = {'topo', 'blue-yellow-red', 'gray', 'parula', 'jet', 'hsv'};
            app.ZoomBoxUpper_Colormap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ZoomBoxUpper_Colormap_DropDown.Value = '6';
            app.overLabel = uilabel(app.datadisplayoptionsPanel);
            app.overLabel.Text = 'over';
            app.withLabel_2 = uilabel(app.datadisplayoptionsPanel);
            app.withLabel_2.Text = 'with';
            app.ZoomBoxLowerDataSet_DropDown = uidropdown(app.datadisplayoptionsPanel);
            app.ZoomBoxLowerDataSet_DropDown.Items = {'topography', 'hillshade', 'slope', 'aspect', 'slope+aspect'};
            app.ZoomBoxLowerDataSet_DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ZoomBoxLowerDataSet_DropDown.Value = '2';
            app.transparencyandLabel_2 = uilabel(app.datadisplayoptionsPanel);
            app.transparencyandLabel_2.Text = 'transparency and';
            app.ZoomBoxLowerTransp_EditField = uieditfield(app.datadisplayoptionsPanel, 'numeric');
            app.ZoomBoxLowerTransp_EditField.Limits = [0 1];
            app.ZoomBoxLowerTransp_EditField.ValueDisplayFormat = '%3.2f';
            app.ZoomBoxLowerTransp_EditField.Value = 1;
            app.colormapLabel_2 = uilabel(app.datadisplayoptionsPanel);
            app.colormapLabel_2.Text = 'color-map';
            app.ZoomBoxLower_Colormap_DropDown = uidropdown(app.datadisplayoptionsPanel);
            app.ZoomBoxLower_Colormap_DropDown.Items = {'topo', 'blue-yellow-red', 'gray', 'parula', 'jet', 'hsv'};
            app.ZoomBoxLower_Colormap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ZoomBoxLower_Colormap_DropDown.Value = '3';
            app.ZoomBoxInvertUpper_CheckBox = uicheckbox(app.datadisplayoptionsPanel);
            app.ZoomBoxInvertUpper_CheckBox.Text = 'invert upper c-map';
            app.ZoomBoxInvertLower_CheckBox = uicheckbox(app.datadisplayoptionsPanel);
            app.ZoomBoxInvertLower_CheckBox.Text = 'invert lower c-map';
            app.hillshadeoptionsPanel = uipanel(app.datadisplayoptionsPanel);
            app.hillshadeoptionsPanel.Title = 'hillshade options';
            app.azimuthEditField_2Label = uilabel(app.hillshadeoptionsPanel);
            app.azimuthEditField_2Label.Text = 'azimuth';
            app.ZoomBox_azimuth_EditField = uieditfield(app.hillshadeoptionsPanel, 'numeric');
            app.ZoomBox_azimuth_EditField.Limits = [0 360];
            app.ZoomBox_azimuth_EditField.RoundFractionalValues = 'on';
            app.ZoomBox_azimuth_EditField.ValueDisplayFormat = '%d';
            app.ZoomBox_azimuth_EditField.Value = 140;
            app.zenithEditField_2Label = uilabel(app.hillshadeoptionsPanel);
            app.zenithEditField_2Label.Text = 'zenith';
            app.ZoomBox_zenith_EditField = uieditfield(app.hillshadeoptionsPanel, 'numeric');
            app.ZoomBox_zenith_EditField.Limits = [0 90];
            app.ZoomBox_zenith_EditField.RoundFractionalValues = 'on';
            app.ZoomBox_zenith_EditField.ValueDisplayFormat = '%d';
            app.ZoomBox_zenith_EditField.Value = 30;
            app.zfactEditField_2Label = uilabel(app.hillshadeoptionsPanel);
            app.zfactEditField_2Label.Text = 'z-fact.';
            app.ZoomBox_zfact_EditField = uieditfield(app.hillshadeoptionsPanel, 'numeric');
            app.ZoomBox_zfact_EditField.ValueDisplayFormat = '%4.2f';
            app.ZoomBox_zfact_EditField.Value = 1;
            app.slopeoptionsPanel = uipanel(app.datadisplayoptionsPanel);
            app.slopeoptionsPanel.Title = 'slope options';
            app.maxslopeLabel = uilabel(app.slopeoptionsPanel);
            app.maxslopeLabel.Text = 'max. slope';
            app.ZoomBox_maxslope_EditField = uieditfield(app.slopeoptionsPanel, 'numeric');
            app.ZoomBox_maxslope_EditField.Limits = [0 90];
            app.ZoomBox_maxslope_EditField.ValueDisplayFormat = '%4.2f';
            app.minslopeLabel = uilabel(app.slopeoptionsPanel);
            app.minslopeLabel.Text = 'min. slope';
            app.ZoomBox_minslope_EditField = uieditfield(app.slopeoptionsPanel, 'numeric');
            app.ZoomBox_minslope_EditField.Limits = [0 90];
            app.ZoomBox_minslope_EditField.ValueDisplayFormat = '%4.2f';
            app.contouroptionsPanel = uipanel(app.datadisplayoptionsPanel);
            app.contouroptionsPanel.Title = 'contour options';
            app.minelevLabel = uilabel(app.contouroptionsPanel);
            app.minelevLabel.Text = 'min. elev.';
            app.ZoomBox_minelevation_EditField = uieditfield(app.contouroptionsPanel, 'numeric');
            app.ZoomBox_minelevation_EditField.ValueDisplayFormat = '%5.2f';
            app.maxelevLabel = uilabel(app.contouroptionsPanel);
            app.maxelevLabel.Text = 'max. elev.';
            app.ZoomBox_maxelevation_EditField = uieditfield(app.contouroptionsPanel, 'numeric');
            app.ZoomBox_maxelevation_EditField.ValueDisplayFormat = '%5.2f';
            app.deltaelevLabel = uilabel(app.contouroptionsPanel);
            app.deltaelevLabel.Text = 'delta elev.';
            app.ZoomBox_contourInterval_EditField = uieditfield(app.contouroptionsPanel, 'numeric');
            app.ZoomBox_contourInterval_EditField.Limits = [0 Inf];
            app.ZoomBox_contourInterval_EditField.ValueDisplayFormat = '%4.2f';
            app.Plotzoom_Button = uibutton(app.datadisplayoptionsPanel, 'push');
            app.Plotzoom_Button.ButtonPushedFcn = createCallbackFcn(app, @Plotzoom_ButtonPushed, true);
            app.Plotzoom_Button.Text = 'b) Plot zoom';
            app.FigEditField_2Label = uilabel(app.datadisplayoptionsPanel);
            app.FigEditField_2Label.Text = 'Fig #';
            app.PlotZoom_FigNum_EditField = uieditfield(app.datadisplayoptionsPanel, 'numeric');
            app.PlotZoom_FigNum_EditField.Limits = [0 Inf];
            app.PlotZoom_FigNum_EditField.RoundFractionalValues = 'on';
            app.PlotZoom_FigNum_EditField.ValueDisplayFormat = '%d';
            app.PlotZoom_FigNum_EditField.Value = 3;
            app.UseBlindMeasurement_CheckBox = uicheckbox(app.datadisplayoptionsPanel);
            app.UseBlindMeasurement_CheckBox.Tooltip = {'the random length scaling factor is changed whenever the zoom box is define!'};
            app.UseBlindMeasurement_CheckBox.Text = 'BLIND MEASUREMENT';
            app.ShowHistogram_Button = uibutton(app.datadisplayoptionsPanel, 'push');
            app.ShowHistogram_Button.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogram_ButtonPushed, true);
            app.ShowHistogram_Button.Text = 'Show data histogram of "lower" data set';
            app.definefaulttraceandcropredprofilePanel = uipanel(app.UIFigure);
            app.definefaulttraceandcropredprofilePanel.Title = '3) define fault trace and crop red profile';
            app.TraceFault_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.TraceFault_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceFault_ButtonPushed, true);
            app.TraceFault_Button.Text = 'a) Trace fault';
            app.shiftbyEditFieldLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.shiftbyEditFieldLabel.Text = 'shift by';
            app.Shiftby_EditField = uieditfield(app.definefaulttraceandcropredprofilePanel, 'numeric');
            app.Shiftby_EditField.ValueDisplayFormat = '%4.2f';
            app.mLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.mLabel.Text = 'm';
            app.Right_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.Right_Button.ButtonPushedFcn = createCallbackFcn(app, @Right_ButtonPushed, true);
            app.Right_Button.Text = 'Right';
            app.Left_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.Left_Button.ButtonPushedFcn = createCallbackFcn(app, @Left_ButtonPushed, true);
            app.Left_Button.Text = 'Left';
            app.Up_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.Up_Button.ButtonPushedFcn = createCallbackFcn(app, @Up_ButtonPushed, true);
            app.Up_Button.Text = 'Up';
            app.Down_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.Down_Button.ButtonPushedFcn = createCallbackFcn(app, @Down_ButtonPushed, true);
            app.Down_Button.Text = 'Down';
            app.Rotateby_EditField = uieditfield(app.definefaulttraceandcropredprofilePanel, 'numeric');
            app.Rotateby_EditField.ValueDisplayFormat = '%3.1f';
            app.degposvalueCWLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.degposvalueCWLabel.Text = 'deg (pos. value == CW)';
            app.bplacefaultparallelprofilesLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.bplacefaultparallelprofilesLabel.Text = 'b) place fault-parallel profiles';
            app.mdistancetofaulttraceREDprofileLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.mdistancetofaulttraceREDprofileLabel.Text = 'm distance to fault trace (RED profile)';
            app.RedProfDistance_EditField = uieditfield(app.definefaulttraceandcropredprofilePanel, 'numeric');
            app.RedProfDistance_EditField.Limits = [0 Inf];
            app.RedProfDistance_EditField.ValueDisplayFormat = '%4.2f';
            app.RedProfDistance_EditField.ValueChangedFcn = createCallbackFcn(app, @RedProfDistance_EditFieldValueChanged, true);
            app.mdistancetofaulttraceBLUEprofileLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.mdistancetofaulttraceBLUEprofileLabel.Text = 'm distance to fault trace (BLUE profile)';
            app.BlueProfDistance_EditField = uieditfield(app.definefaulttraceandcropredprofilePanel, 'numeric');
            app.BlueProfDistance_EditField.Limits = [0 Inf];
            app.BlueProfDistance_EditField.ValueDisplayFormat = '%4.2f';
            app.BlueProfDistance_EditField.ValueChangedFcn = createCallbackFcn(app, @BlueProfDistance_EditFieldValueChanged, true);
            app.withLabel_3 = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.withLabel_3.Text = 'with';
            app.mwideswatharoundprofilelinesLabel = uilabel(app.definefaulttraceandcropredprofilePanel);
            app.mwideswatharoundprofilelinesLabel.Text = 'm wide swath around profile lines';
            app.ProfileSwatwidth_EditField = uieditfield(app.definefaulttraceandcropredprofilePanel, 'numeric');
            app.ProfileSwatwidth_EditField.Limits = [0 Inf];
            app.ProfileSwatwidth_EditField.ValueDisplayFormat = '%4.2f';
            app.ProfileSwatwidth_EditField.ValueChangedFcn = createCallbackFcn(app, @ProfileSwatwidth_EditFieldValueChanged, true);
            app.ProfileSwatwidth_EditField.Value = 2;
            app.CropRedprofile_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.CropRedprofile_Button.ButtonPushedFcn = createCallbackFcn(app, @CropRedprofile_ButtonPushed, true);
            app.CropRedprofile_Button.Text = 'c) Crop red';
            app.RotateTrace_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.RotateTrace_Button.ButtonPushedFcn = createCallbackFcn(app, @RotateTrace_ButtonPushed, true);
            app.RotateTrace_Button.Text = 'Rotate';
            app.PlotProfiles_Button = uibutton(app.definefaulttraceandcropredprofilePanel, 'push');
            app.PlotProfiles_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotProfiles_ButtonPushed, true);
            app.PlotProfiles_Button.Text = 'd) Plot profiles';
            app.tracelongitudinalprofilesPanel = uipanel(app.UIFigure);
            app.tracelongitudinalprofilesPanel.Title = '4) trace longitudinal profiles';
            app.TraceRedFeature_Button = uibutton(app.tracelongitudinalprofilesPanel, 'push');
            app.TraceRedFeature_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceRedFeature_ButtonPushed, true);
            app.TraceRedFeature_Button.Text = 'a) Trace feature under RED profile on map';
            app.TraceBlueFeature_Button = uibutton(app.tracelongitudinalprofilesPanel, 'push');
            app.TraceBlueFeature_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceBlueFeature_ButtonPushed, true);
            app.TraceBlueFeature_Button.Text = 'b) Trace feature under BLUE profile on map';
            app.mwideswatharoundfeaturesLabel = uilabel(app.tracelongitudinalprofilesPanel);
            app.mwideswatharoundfeaturesLabel.Text = 'm wide swath around features';
            app.FeatureSwathwidth_EditField = uieditfield(app.tracelongitudinalprofilesPanel, 'numeric');
            app.FeatureSwathwidth_EditField.Limits = [0 90];
            app.FeatureSwathwidth_EditField.ValueDisplayFormat = '%4.2f';
            app.FeatureSwathwidth_EditField.ValueChangedFcn = createCallbackFcn(app, @FeatureSwathwidth_EditFieldValueChanged, true);
            app.FeatureSwathwidth_EditField.Value = 2;
            app.withLabel_4 = uilabel(app.tracelongitudinalprofilesPanel);
            app.withLabel_4.Text = 'with';
            app.FlipLongProfiles_CheckBox = uicheckbox(app.tracelongitudinalprofilesPanel);
            app.FlipLongProfiles_CheckBox.Text = 'flip feature profiles horizontally';
            app.Plot_LongProfiles_Button = uibutton(app.tracelongitudinalprofilesPanel, 'push');
            app.Plot_LongProfiles_Button.ButtonPushedFcn = createCallbackFcn(app, @Plot_LongProfiles_ButtonPushed, true);
            app.Plot_LongProfiles_Button.Text = 'c) Plot longitudinal profiles';
            app.TraceRedLongProfile_Button = uibutton(app.tracelongitudinalprofilesPanel, 'push');
            app.TraceRedLongProfile_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceRedLongProfile_ButtonPushed, true);
            app.TraceRedLongProfile_Button.Text = 'd) Trace RED feature in longitud. profile';
            app.TraceBlueLongProfile_Button = uibutton(app.tracelongitudinalprofilesPanel, 'push');
            app.TraceBlueLongProfile_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceBlueLongProfile_ButtonPushed, true);
            app.TraceBlueLongProfile_Button.Text = 'e) Trace BLUE feature in longitud. profile';
            app.findoptimaloffsetviagridsearchPanel = uipanel(app.UIFigure);
            app.findoptimaloffsetviagridsearchPanel.Title = '5) find optimal offset via grid search';
            app.Findoffsets_Button = uibutton(app.findoptimaloffsetviagridsearchPanel, 'push');
            app.Findoffsets_Button.ButtonPushedFcn = createCallbackFcn(app, @Findoffsets_ButtonPushed, true);
            app.Findoffsets_Button.Text = 'a) Find offsets';
            app.FigEditField_2Label_2 = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.FigEditField_2Label_2.Text = 'Fig #';
            app.FindOffsetFigNum_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.FindOffsetFigNum_EditField.Limits = [0 Inf];
            app.FindOffsetFigNum_EditField.RoundFractionalValues = 'on';
            app.FindOffsetFigNum_EditField.ValueDisplayFormat = '%d';
            app.FindOffsetFigNum_EditField.Value = 4;
            app.horizontalshiftfromLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.horizontalshiftfromLabel.Text = 'horizontal shift from';
            app.HorzShiftMin_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.HorzShiftMin_EditField.ValueDisplayFormat = '%5.2f';
            app.toLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.toLabel.Text = 'to';
            app.HorzShiftMax_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.HorzShiftMax_EditField.ValueDisplayFormat = '%5.2f';
            app.mwithdxLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.mwithdxLabel.Text = 'm, with dx';
            app.HorzShiftDlt_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.HorzShiftDlt_EditField.ValueDisplayFormat = '%5.2f';
            app.verticalshiftfromLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.verticalshiftfromLabel.Text = 'vertical shift from';
            app.VertShiftMin_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertShiftMin_EditField.ValueDisplayFormat = '%5.2f';
            app.toLabel_2 = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.toLabel_2.Text = 'to';
            app.VertShiftMax_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertShiftMax_EditField.ValueDisplayFormat = '%5.2f';
            app.mwithdzLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.mwithdzLabel.Text = 'm, with dz';
            app.VertShiftDlt_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertShiftDlt_EditField.ValueDisplayFormat = '%5.2f';
            app.verticalstretchfromLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.verticalstretchfromLabel.Text = 'vertical stretch from';
            app.VertStretchMin_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertStretchMin_EditField.ValueDisplayFormat = '%5.2f';
            app.VertStretchMin_EditField.Value = 0.5;
            app.toLabel_3 = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.toLabel_3.Text = 'to';
            app.VertStretchMax_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertStretchMax_EditField.ValueDisplayFormat = '%5.2f';
            app.VertStretchMax_EditField.Value = 2;
            app.mwithdLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.mwithdLabel.Text = 'm, with d';
            app.VertStretchDlt_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.VertStretchDlt_EditField.ValueDisplayFormat = '%5.2f';
            app.VertStretchDlt_EditField.Value = 0.1;
            app.horziontalslipLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.horziontalslipLabel.Text = 'horziontal slip';
            app.CalcHorzDisplace_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.CalcHorzDisplace_EditField.ValueDisplayFormat = '%5.2f';
            app.CalcHorzDisplace_EditField.Editable = 'off';
            app.verticalslipLabel_2 = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.verticalslipLabel_2.Text = 'vertical slip';
            app.CalcVertDisplace_EditField = uieditfield(app.findoptimaloffsetviagridsearchPanel, 'numeric');
            app.CalcVertDisplace_EditField.ValueDisplayFormat = '%5.2f';
            app.CalcVertDisplace_EditField.Editable = 'off';
            app.slipdirLabel = uilabel(app.findoptimaloffsetviagridsearchPanel);
            app.slipdirLabel.Text = 'slip dir.';
            app.BackSlip_direct_DropDown = uidropdown(app.findoptimaloffsetviagridsearchPanel);
            app.BackSlip_direct_DropDown.Items = {'right-lateral', 'left-lateral'};
            app.BackSlip_direct_DropDown.ItemsData = {'1', '2'};
            app.BackSlip_direct_DropDown.Value = '1';
            app.backslipDEMalongfaulttracePanel = uipanel(app.UIFigure);
            app.backslipDEMalongfaulttracePanel.Title = '6) back-slip DEM along fault trace';
            app.BackslipDEM_Button = uibutton(app.backslipDEMalongfaulttracePanel, 'push');
            app.BackslipDEM_Button.ButtonPushedFcn = createCallbackFcn(app, @BackslipDEM_ButtonPushed, true);
            app.BackslipDEM_Button.Text = 'a) Back-slip DEM';
            app.horizontalslipLabel = uilabel(app.backslipDEMalongfaulttracePanel);
            app.horizontalslipLabel.Text = 'horizontal slip';
            app.BackSlip_HorzSlip_EditField = uieditfield(app.backslipDEMalongfaulttracePanel, 'numeric');
            app.BackSlip_HorzSlip_EditField.ValueDisplayFormat = '%5.2f';
            app.verticalslipLabel = uilabel(app.backslipDEMalongfaulttracePanel);
            app.verticalslipLabel.Text = 'vertical slip';
            app.BackSlip_VertSlip_EditField = uieditfield(app.backslipDEMalongfaulttracePanel, 'numeric');
            app.BackSlip_VertSlip_EditField.ValueDisplayFormat = '%5.2f';
            app.inDropDownLabel = uilabel(app.backslipDEMalongfaulttracePanel);
            app.inDropDownLabel.Text = 'in';
            app.BackSlip_2D3D_DropDown = uidropdown(app.backslipDEMalongfaulttracePanel);
            app.BackSlip_2D3D_DropDown.Items = {'2-D', '3-D'};
            app.BackSlip_2D3D_DropDown.ItemsData = {'1', '2'};
            app.BackSlip_2D3D_DropDown.Value = '1';
            app.viewaxisEditFieldLabel = uilabel(app.backslipDEMalongfaulttracePanel);
            app.viewaxisEditFieldLabel.Text = 'view axis';
            app.BackSlip_ViewAxis_EditField = uieditfield(app.backslipDEMalongfaulttracePanel, 'text');
            app.BackSlip_ViewAxis_EditField.Value = '40 20';
            app.FigEditField_2Label_3 = uilabel(app.backslipDEMalongfaulttracePanel);
            app.FigEditField_2Label_3.Text = 'Fig #';
            app.BackSlip_FigNum_EditField = uieditfield(app.backslipDEMalongfaulttracePanel, 'numeric');
            app.BackSlip_FigNum_EditField.Limits = [0 Inf];
            app.BackSlip_FigNum_EditField.RoundFractionalValues = 'on';
            app.BackSlip_FigNum_EditField.ValueDisplayFormat = '%d';
            app.BackSlip_FigNum_EditField.Value = 5;
            app.BackSlip_CleanBS_CheckBox = uicheckbox(app.backslipDEMalongfaulttracePanel);
            app.BackSlip_CleanBS_CheckBox.Tooltip = {'this will backslip not only the zoom box but the entire DEM that was imported; because of a regridding step within the back-slipping function, doing this for the entire data set can be slow'; ''};
            app.BackSlip_CleanBS_CheckBox.Text = '"clean" way (slow)';
            app.measurementresultsandsavingPanel = uipanel(app.UIFigure);
            app.measurementresultsandsavingPanel.Title = '7) measurement results and saving';
            app.horzLabel = uilabel(app.measurementresultsandsavingPanel);
            app.horzLabel.Text = 'horz.';
            app.Result_MinHorz_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_MinHorz_EditField.ValueDisplayFormat = '%5.2f';
            app.vertLabel = uilabel(app.measurementresultsandsavingPanel);
            app.vertLabel.Text = 'vert.';
            app.Result_MinVert_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_MinVert_EditField.ValueDisplayFormat = '%5.2f';
            app.Result_OptHorz_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_OptHorz_EditField.ValueDisplayFormat = '%5.2f';
            app.Result_OptVert_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_OptVert_EditField.ValueDisplayFormat = '%5.2f';
            app.Result_MaxHorz_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_MaxHorz_EditField.ValueDisplayFormat = '%5.2f';
            app.Result_MaxVert_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_MaxVert_EditField.ValueDisplayFormat = '%5.2f';
            app.minLabel = uilabel(app.measurementresultsandsavingPanel);
            app.minLabel.Text = 'min.';
            app.optLabel = uilabel(app.measurementresultsandsavingPanel);
            app.optLabel.Text = 'opt.';
            app.maxLabel = uilabel(app.measurementresultsandsavingPanel);
            app.maxLabel.Text = 'max.';
            app.qualityratingDropDownLabel = uilabel(app.measurementresultsandsavingPanel);
            app.qualityratingDropDownLabel.Text = 'quality rating';
            app.Result_QualRate_DropDown = uidropdown(app.measurementresultsandsavingPanel);
            app.Result_QualRate_DropDown.Items = {'high', 'high-moderate', 'moderate', 'moderate-low', 'low'};
            app.Result_QualRate_DropDown.Value = 'high';
            app.UTMzoneLabel = uilabel(app.measurementresultsandsavingPanel);
            app.UTMzoneLabel.Text = 'UTM zone';
            app.Result_UTMzone_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_UTMzone_EditField.Limits = [1 60];
            app.Result_UTMzone_EditField.RoundFractionalValues = 'on';
            app.Result_UTMzone_EditField.ValueDisplayFormat = '%d';
            app.Result_UTMzone_EditField.Value = 1;
            app.Result_UTMhemi_DropDown = uidropdown(app.measurementresultsandsavingPanel);
            app.Result_UTMhemi_DropDown.Items = {'North', 'South'};
            app.Result_UTMhemi_DropDown.ItemsData = {'1', '2'};
            app.Result_UTMhemi_DropDown.Value = '1';
            app.distancetorefLabel = uilabel(app.measurementresultsandsavingPanel);
            app.distancetorefLabel.Text = 'distance to ref.';
            app.Result_Dist2Ref_EditField = uieditfield(app.measurementresultsandsavingPanel, 'numeric');
            app.Result_Dist2Ref_EditField.ValueDisplayFormat = '%5.2f';
            app.commentsEditFieldLabel = uilabel(app.measurementresultsandsavingPanel);
            app.commentsEditFieldLabel.Text = 'comments';
            app.Result_Comment_EditField = uieditfield(app.measurementresultsandsavingPanel, 'text');
            app.Result_Saveresults_Button = uibutton(app.measurementresultsandsavingPanel, 'push');
            app.Result_Saveresults_Button.ButtonPushedFcn = createCallbackFcn(app, @Result_Saveresults_ButtonPushed, true);
            app.Result_Saveresults_Button.Text = 'a) Save results';
            app.ScreenOut_Line1_EditField = uieditfield(app.UIFigure, 'text');
            app.ScreenOut_Line2_EditField = uieditfield(app.UIFigure, 'text');
            app.ScreenOut_Line3_EditField = uieditfield(app.UIFigure, 'text');
            app.ScreenOut_Line4_EditField = uieditfield(app.UIFigure, 'text');
            app.ScreenOut_Line5_EditField = uieditfield(app.UIFigure, 'text');
            app.ScreenOut_Line6_EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaDiCaoz_2v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end