classdef LaDiCaoz_2v2_ShowResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DistancealongfaultPanel         matlab.ui.container.Panel
        minLabel                        matlab.ui.control.Label
        DistanceData_minEditField       matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        DistanceData_maxEditField       matlab.ui.control.NumericEditField
        inimporteddataLabel             matlab.ui.control.Label
        toplotLabel                     matlab.ui.control.Label
        minEditField_2Label             matlab.ui.control.Label
        DistancePlot_minEditField       matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        DistancePlot_maxEditField       matlab.ui.control.NumericEditField
        HorizontaldisplacementPanel     matlab.ui.container.Panel
        minEditField_3Label             matlab.ui.control.Label
        HorzOffsData_minEditField       matlab.ui.control.NumericEditField
        maxEditField_3Label             matlab.ui.control.Label
        HorzOffsData_maxEditField       matlab.ui.control.NumericEditField
        inimporteddataLabel_2           matlab.ui.control.Label
        toplotLabel_2                   matlab.ui.control.Label
        minEditField_4Label             matlab.ui.control.Label
        HorzOffsPlot_minEditField       matlab.ui.control.NumericEditField
        maxEditField_4Label             matlab.ui.control.Label
        HorzOffsPlot_maxEditField       matlab.ui.control.NumericEditField
        interpolationincrementEditFieldLabel  matlab.ui.control.Label
        HorzOffs_InterpolIncrEditField  matlab.ui.control.NumericEditField
        scalefactorEditFieldLabel       matlab.ui.control.Label
        HorzOffsScalefactorEditField    matlab.ui.control.NumericEditField
        ResultbinningPanel              matlab.ui.container.Panel
        windowsizeEditFieldLabel        matlab.ui.control.Label
        BinningWindowsizeEditField      matlab.ui.control.NumericEditField
        stepsizeEditFieldLabel          matlab.ui.control.Label
        BinningStepsizeEditField        matlab.ui.control.NumericEditField
        VerticaldisplacementPanel       matlab.ui.container.Panel
        minEditField_5Label             matlab.ui.control.Label
        VertOffsData_minEditField       matlab.ui.control.NumericEditField
        maxEditField_5Label             matlab.ui.control.Label
        VertOffsData_maxEditField       matlab.ui.control.NumericEditField
        inimporteddataLabel_3           matlab.ui.control.Label
        toplotLabel_3                   matlab.ui.control.Label
        minEditField_6Label             matlab.ui.control.Label
        VertOffsPlot_minEditField       matlab.ui.control.NumericEditField
        maxEditField_6Label             matlab.ui.control.Label
        VertOffsPlot_maxEditField       matlab.ui.control.NumericEditField
        interpolationincrementEditField_2Label  matlab.ui.control.Label
        VertOffs_InterpolIncrEditField  matlab.ui.control.NumericEditField
        scalefactorEditField_2Label     matlab.ui.control.Label
        VertOffsScalefactorEditField    matlab.ui.control.NumericEditField
        PlotfeaturesbyratingPanel       matlab.ui.container.Panel
        high1CheckBox                   matlab.ui.control.CheckBox
        moderatehigh2CheckBox           matlab.ui.control.CheckBox
        moderate3CheckBox               matlab.ui.control.CheckBox
        lowmoderate4CheckBox            matlab.ui.control.CheckBox
        low5CheckBox                    matlab.ui.control.CheckBox
        LoadResultfilestxtButton        matlab.ui.control.Button
        PlotalongdistanceButton         matlab.ui.control.Button
        FigEditFieldLabel               matlab.ui.control.Label
        FigEditField                    matlab.ui.control.NumericEditField
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        ScalewratingCheckBox            matlab.ui.control.CheckBox
        holdonCheckBox                  matlab.ui.control.CheckBox
        RGBcolorLabel                   matlab.ui.control.Label
        RGBcolorEditField               matlab.ui.control.EditField
        PatchtransparencyLabel          matlab.ui.control.Label
        PatchTransparencyEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Offsets = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DistancePlot_minEditField
        function DistancePlot_minEditFieldValueChanged(app, event)
            value  = app.DistancePlot_minEditField.Value;
            value2 = app.DistanceData_minEditField.Value;
            if (value < value2)
                app.DistancePlot_minEditField.Value = app.DistanceData_minEditField.Value;     
            end      
        end

        % Value changed function: DistancePlot_maxEditField
        function DistancePlot_maxEditFieldValueChanged(app, event)
            value  = app.DistancePlot_maxEditField.Value;
            value2 = app.DistanceData_maxEditField.Value;
            if (value > value2)
                app.DistancePlot_maxEditField.Value = app.DistanceData_maxEditField.Value;     
            end    
        end

        % Value changed function: HorzOffsPlot_minEditField
        function HorzOffsPlot_minEditFieldValueChanged(app, event)
            value  = app.HorzOffsPlot_minEditField.Value;
            value2 = app.HorzOffsData_minEditField.Value;
            if (value < value2)
                app.HorzOffsPlot_minEditField.Value = app.HorzOffsData_minEditField.Value;     
            end        
        end

        % Value changed function: HorzOffsPlot_maxEditField
        function HorzOffsPlot_maxEditFieldValueChanged(app, event)
           value  = app.HorzOffsPlot_maxEditField.Value;
            value2 = app.HorzOffsData_maxEditField.Value;
            if (value > value2)
                app.HorzOffsPlot_maxEditField.Value = app.HorzOffsData_maxEditField.Value;     
            end  
            
        end

        % Value changed function: VertOffsPlot_minEditField
        function VertOffsPlot_minEditFieldValueChanged(app, event)
            value  = app.VertOffsPlot_minEditField.Value;
            value2 = app.VertOffsData_minEditField.Value;
            if (value < value2)
                app.VertOffsPlot_minEditField.Value = app.VertOffsData_minEditField.Value;     
            end          
        end

        % Value changed function: VertOffsPlot_maxEditField
        function VertOffsPlot_maxEditFieldValueChanged(app, event)
            value  = app.VertOffsPlot_maxEditField.Value;
            value2 = app.VertOffsData_maxEditField.Value;
            if (value > value2)
                app.VertOffsPlot_maxEditField.Value = app.VertOffsData_maxEditField.Value;     
            end    
        end

        % Button pushed function: LoadResultfilestxtButton
        function LoadResultfilestxtButtonPushed(app, event)
            
            FolderName     = uigetdir('Select Folder containing the offset observation files (*.txt files)');
            if (FolderName==0),                     return;             end
            FilesInFolder  = dir(FolderName);
            counter        = 0;
            app.Offsets    = struct();
            %---------------------------------------------------
            for i = 1 : size(FilesInFolder,1)
                if ~isempty(strfind(FilesInFolder(i,1).name,'.txt'))
                    counter     = counter +1;
                end
            end
            %---------------------------------------------------
            app.Offsets(counter) = struct();
            counter = 0;
            MinDist = +inf;                MaxDist = -inf;
            MinHorz = +inf;                MaxHorz = -inf;
            MinVert = +inf;                MaxVert = -inf;
            %--------------------------------------------------- 
            for (i = 1 : size(FilesInFolder,1))
                CurrPath = FolderName;
                if ~isempty(strfind(FilesInFolder(i,1).name,'.txt'))
                    counter      = counter +1;
                    CompletePath = strcat(CurrPath,'/',FilesInFolder(i,1).name);
                    fid          = fopen(CompletePath,'r');             if (fid == -1), errordlg('The entered filename has not been found.');    return;   end
                    %--------------
                    disp(FilesInFolder(i,1).name);

                    for (ii = 1 : 7),                   nextline      = fgetl(fid);             end  
                    nextline  = fgetl(fid);             MinHorzBackSl = sscanf(nextline,'%*s %f');       
                    nextline  = fgetl(fid);             MaxHorzBackSl = sscanf(nextline,'%*s %f');
                    nextline  = fgetl(fid); 
                    nextline  = fgetl(fid);             MinVertBackSl = sscanf(nextline,'%*s %f');       
                    nextline  = fgetl(fid);             MaxVertBackSl = sscanf(nextline,'%*s %f');  
                    nextline  = fgetl(fid);             Quality_rating = sscanf(nextline,'%*s %d');  
  
                    for (ii = 14 : 17),                 nextline      = fgetl(fid);             end
                    nextline  = fgetl(fid);             Dist2Ref      = sscanf(nextline,'%f');  
                    
                    for (ii = 19 : 39),                 nextline      = fgetl(fid);             end  
                    nextline  = fgetl(fid);             Prof_dxdz     = sscanf(nextline,'%f %f'); 
                    nextline  = fgetl(fid);               
                    
                    nextline  = fgetl(fid);             HorzShift     = sscanf(nextline,'%f')';
                    nextline  = fgetl(fid);             HorzGoF       = sscanf(nextline,'%f')';
                    nextline  = fgetl(fid);             VertShift     = sscanf(nextline,'%f')';
                    nextline  = fgetl(fid);             VertGoF       = sscanf(nextline,'%f')';
                     
                    if (Dist2Ref < MinDist),            MinDist = Dist2Ref;                 end
                    if (Dist2Ref > MaxDist),            MaxDist = Dist2Ref;                 end
                    if (MinHorzBackSl < MinHorz),       MinHorz = MinHorzBackSl;            end
                    if (MaxHorzBackSl > MaxHorz),       MaxHorz = MaxHorzBackSl;            end
                    if (MinVertBackSl < MinVert),       MinVert = MinVertBackSl;            end
                    if (MaxVertBackSl > MaxVert),       MaxVert = MaxVertBackSl;            end
                    
                    app.Offsets(counter).DistAlong = Dist2Ref;
                    app.Offsets(counter).MinHorzBS = MinHorzBackSl;
                    app.Offsets(counter).MaxHorzBS = MaxHorzBackSl;
                    app.Offsets(counter).MinVertBS = MinVertBackSl;
                    app.Offsets(counter).MaxVertBS = MaxVertBackSl;
                    app.Offsets(counter).QualRate  = Quality_rating;
                    app.Offsets(counter).dxdz      = Prof_dxdz;
                    app.Offsets(counter).HorzShift = HorzShift;
                    app.Offsets(counter).HorzGoF   = HorzGoF;
                    app.Offsets(counter).VertShift = VertShift;
                    app.Offsets(counter).VertGoF   = VertGoF;
                    %--------------
                    fclose(fid);
                end
            end
            %------------------------------------%
            app.DistanceData_minEditField.Value = MinDist;
            app.DistanceData_maxEditField.Value = MaxDist;
            app.HorzOffsData_minEditField.Value = MinHorz;
            app.HorzOffsData_maxEditField.Value = MaxHorz;
            app.VertOffsData_minEditField.Value = MinVert;
            app.VertOffsData_maxEditField.Value = MaxVert;
            %------------------------------------%
            app.DistancePlot_minEditField.Value = MinDist;
            app.DistancePlot_maxEditField.Value = MaxDist;
            app.HorzOffsPlot_minEditField.Value = MinHorz;
            app.HorzOffsPlot_maxEditField.Value = MaxHorz;
            app.VertOffsPlot_minEditField.Value = MinVert;
            app.VertOffsPlot_maxEditField.Value = MaxVert;
            
            app.NotesTextArea.Value = strcat("imported txt files: ",num2str(size(app.Offsets,2)));
        end

        % Button pushed function: PlotalongdistanceButton
        function PlotalongdistanceButtonPushed(app, event)
            PlotThese      = zeros(1,5);
            PlotThese(1,1) = app.high1CheckBox.Value;
            PlotThese(1,2) = app.moderatehigh2CheckBox.Value;
            PlotThese(1,3) = app.moderate3CheckBox.Value;
            PlotThese(1,4) = app.lowmoderate4CheckBox.Value;
            PlotThese(1,5) = app.low5CheckBox.Value;
            if (sum(PlotThese) == 0),       return;                     end
            %------------------------------------%
            ScaleWQualRate = app.ScalewratingCheckBox.Value;
            
            FigNum       = app.FigEditField.Value;
            %------------------------------------%
            RGBvals      = str2num(app.RGBcolorEditField.Value);        R = RGBvals(1,1);       G = RGBvals(1,2);       B = RGBvals(1,3);
            BinSize      = app.BinningStepsizeEditField.Value;
            WindowSize   = app.BinningWindowsizeEditField.Value;
            %------------------------------------%
            MinDist2Plot = app.DistancePlot_minEditField.Value;
            MaxDist2Plot = app.DistancePlot_maxEditField.Value;
           
            HorzIncrem   = app.HorzOffs_InterpolIncrEditField.Value;
            HorzScaleFct = app.HorzOffsScalefactorEditField.Value;
            VertIncrem   = app.VertOffs_InterpolIncrEditField.Value;
            VertScaleFct = app.VertOffsScalefactorEditField.Value;
           
            HorzShiftIntp= (floor(app.HorzOffsData_minEditField.Value/HorzIncrem)*HorzIncrem) : HorzIncrem : (ceil(app.HorzOffsData_maxEditField.Value/HorzIncrem)*HorzIncrem);
            VertShiftIntp= (floor(app.VertOffsData_minEditField.Value/VertIncrem)*VertIncrem) : VertIncrem : (ceil(app.VertOffsData_maxEditField.Value/VertIncrem)*VertIncrem);
            COPDhorz     = zeros(size(HorzShiftIntp));
            COPDvert     = zeros(size(VertShiftIntp));
            %------------------------------------%
            for i = 1 : size(app.Offsets,2)
      
                Pos1 = (HorzShiftIntp >= app.Offsets(i).MinHorzBS);           Pos1 = find(Pos1,1,'first');
                Pos2 = (HorzShiftIntp <= app.Offsets(i).MaxHorzBS);           Pos2 = find(Pos2,1,'last');
                Pos3 = (VertShiftIntp >= app.Offsets(i).MinVertBS);           Pos3 = find(Pos3,1,'first');
                Pos4 = (VertShiftIntp <= app.Offsets(i).MaxVertBS);           Pos4 = find(Pos4,1,'last');
                
                app.Offsets(i).HorzShiftInterp = HorzShiftIntp(Pos1:Pos2);
                app.Offsets(i).VertShiftInterp = VertShiftIntp(Pos3:Pos4);
                app.Offsets(i).HorzGoFInterp   = interp1(app.Offsets(i).HorzShift, app.Offsets(i).HorzGoF, app.Offsets(i).HorzShiftInterp);   
                app.Offsets(i).VertGoFInterp   = interp1(app.Offsets(i).VertShift, app.Offsets(i).VertGoF, app.Offsets(i).VertShiftInterp);  
            end
            figure(FigNum); if (app.holdonCheckBox.Value == 0),    clf;            end,       hold on;
            %------------------------------------%    
            for i = 1 : size(app.Offsets,2)     
                if ((PlotThese(1,app.Offsets(i).QualRate) == 1) && (app.Offsets(i).DistAlong <= MaxDist2Plot) && (app.Offsets(i).DistAlong >= MinDist2Plot) ) 
                    %----------------------------------------------
                    MinHorz2Plot = max([app.HorzOffsPlot_minEditField.Value app.Offsets(i).MinHorzBS]);
                    MaxHorz2Plot = min([app.HorzOffsPlot_maxEditField.Value app.Offsets(i).MaxHorzBS]);
                    %----------------------
                    Pos1 =   (MinHorz2Plot <= app.Offsets(i).HorzShiftInterp);      
                    if (~isempty(Pos1)),                Pos1 = find(Pos1,1,'first');
                    else,                               Pos1 = 0;
                    end
                    %----------------------
                    Pos2 =   (MaxHorz2Plot >= app.Offsets(i).HorzShiftInterp);      
                    if (~isempty(Pos2)),                Pos2= find(Pos2,1,'last');
                    else,                               Pos2 = 0;
                    end
                    %----------------------
                    if (Pos1*Pos2 ~= 0)
                        CutHorzShift = app.Offsets(i).HorzShiftInterp(Pos1:Pos2);
                        CutHorzGoF   = app.Offsets(i).HorzGoFInterp(Pos1:Pos2);
                        CutHorzGoF   = CutHorzGoF./sum(CutHorzGoF(:))./HorzIncrem;
                        CutHorzGoF   = -CutHorzGoF - min(-CutHorzGoF);
                        if (ScaleWQualRate == 1)
                            CutHorzGoF = CutHorzGoF.*1/app.Offsets(i).QualRate;     
                        end
                        Pos3 = (MinHorz2Plot <= HorzShiftIntp);         Pos3 = find(Pos3,1,'first');   
                        Pos4 = (MaxHorz2Plot >= HorzShiftIntp);         Pos4 = find(Pos4,1,'last'); 
                        COPDhorz(Pos3:Pos4) = COPDhorz(Pos3:Pos4) +CutHorzGoF;
                        
                        CutHorzGoF  = [       0,        CutHorzGoF,        0           ,          0     ];
                        CutHorzShift= [CutHorzShift(1), CutHorzShift, CutHorzShift(end), CutHorzShift(1)];
                        
                        subplot(2,5,1:4);     
                        patch((CutHorzGoF*HorzScaleFct+app.Offsets(i).DistAlong), CutHorzShift,'k','facecolor',[R G B],'facealpha',app.PatchTransparencyEditField.Value);  
                    end
                    %----------------------------------------------
                    MinVert2Plot = max([app.VertOffsPlot_minEditField.Value app.Offsets(i).MinVertBS]);
                    MaxVert2Plot = min([app.VertOffsPlot_maxEditField.Value app.Offsets(i).MaxVertBS]);
                    %----------------------
                    Pos1 =   (MinVert2Plot <= app.Offsets(i).VertShiftInterp);        
                    if (~isempty(Pos1)),                Pos1 = find(Pos1,1,'first');
                    else,                               Pos1 = 0;
                    end
                    %----------------------
                    Pos2 =   (MaxVert2Plot >= app.Offsets(i).VertShiftInterp);        
                    if (~isempty(Pos2)),                Pos2= find(Pos2,1,'last');
                    else,                               Pos2 = 0;
                    end
                    
                    %----------------------
                    if (Pos1*Pos2 ~= 0)
                        CutVertShift = app.Offsets(i).VertShiftInterp(Pos1:Pos2);
                        CutVertGoF   = app.Offsets(i).VertGoFInterp(Pos1:Pos2);
                        CutVertGoF   = CutVertGoF./sum(CutVertGoF(:))./VertIncrem;
                        CutVertGoF   = -CutVertGoF - min(-CutVertGoF);
                        if (ScaleWQualRate == 1)
                            CutVertGoF = CutVertGoF.*1/app.Offsets(i).QualRate;     
                        end
                        Pos3 = (MinVert2Plot <= VertShiftIntp);         Pos3 = find(Pos3,1,'first');   
                        Pos4 = (MaxVert2Plot >= VertShiftIntp);         Pos4 = find(Pos4,1,'last'); 
                        COPDvert(Pos3:Pos4) = COPDvert(Pos3:Pos4) +CutVertGoF;
                        
                        CutVertGoF   = [       0,        CutVertGoF,        0           ,          0      ];
                        CutVertShift = [CutVertShift(1), CutVertShift, CutVertShift(end),  CutVertShift(1)];
                        
                        subplot(2,5,6:9);       
                        patch((CutVertGoF*VertScaleFct+app.Offsets(i).DistAlong), CutVertShift,'k','facecolor',[R G B],'facealpha',app.PatchTransparencyEditField.Value);
                    end 
                end
            end
            subplot(2,5,5);
            COPDhorz     = [    0,            COPDhorz,      0    ,          0      ];
            CutHorzShift = [HorzShiftIntp(1), HorzShiftIntp, HorzShiftIntp(end),  HorzShiftIntp(1)];
            patch(COPDhorz, CutHorzShift,'k','facecolor',[R G B],'facealpha',app.PatchTransparencyEditField.Value);
            subplot(2,5,10);
            COPDvert     = [    0,            COPDvert,      0    ,          0      ];
            CutVertShift = [VertShiftIntp(1), VertShiftIntp, VertShiftIntp(end),  VertShiftIntp(1)];
            patch(COPDvert, CutVertShift,'k','facecolor',[R G B],'facealpha',app.PatchTransparencyEditField.Value);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DistancealongfaultPanel = uipanel(app.UIFigure);
            app.DistancealongfaultPanel.Title = 'Distance along fault';
            app.minLabel = uilabel(app.DistancealongfaultPanel);
            app.minLabel.Text = 'min';
            app.DistanceData_minEditField = uieditfield(app.DistancealongfaultPanel, 'numeric');
            app.DistanceData_minEditField.ValueDisplayFormat = '%5.2f';
            app.DistanceData_minEditField.Editable = 'off';
            app.maxEditFieldLabel = uilabel(app.DistancealongfaultPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.DistanceData_maxEditField = uieditfield(app.DistancealongfaultPanel, 'numeric');
            app.DistanceData_maxEditField.ValueDisplayFormat = '%5.2f';
            app.DistanceData_maxEditField.Editable = 'off';
            app.inimporteddataLabel = uilabel(app.DistancealongfaultPanel);
            app.inimporteddataLabel.Text = 'in imported data';
            app.toplotLabel = uilabel(app.DistancealongfaultPanel);
            app.toplotLabel.Text = 'to plot';
            app.minEditField_2Label = uilabel(app.DistancealongfaultPanel);
            app.minEditField_2Label.Text = 'min';
            app.DistancePlot_minEditField = uieditfield(app.DistancealongfaultPanel, 'numeric');
            app.DistancePlot_minEditField.ValueDisplayFormat = '%5.2f';
            app.DistancePlot_minEditField.ValueChangedFcn = createCallbackFcn(app, @DistancePlot_minEditFieldValueChanged, true);
            app.maxEditField_2Label = uilabel(app.DistancealongfaultPanel);
            app.maxEditField_2Label.Text = 'max';
            app.DistancePlot_maxEditField = uieditfield(app.DistancealongfaultPanel, 'numeric');
            app.DistancePlot_maxEditField.ValueDisplayFormat = '%5.2f';
            app.DistancePlot_maxEditField.ValueChangedFcn = createCallbackFcn(app, @DistancePlot_maxEditFieldValueChanged, true);
            app.HorizontaldisplacementPanel = uipanel(app.UIFigure);
            app.HorizontaldisplacementPanel.Title = 'Horizontal displacement';
            app.minEditField_3Label = uilabel(app.HorizontaldisplacementPanel);
            app.minEditField_3Label.Text = 'min';
            app.HorzOffsData_minEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffsData_minEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffsData_minEditField.Editable = 'off';
            app.maxEditField_3Label = uilabel(app.HorizontaldisplacementPanel);
            app.maxEditField_3Label.Text = 'max';
            app.HorzOffsData_maxEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffsData_maxEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffsData_maxEditField.Editable = 'off';
            app.inimporteddataLabel_2 = uilabel(app.HorizontaldisplacementPanel);
            app.inimporteddataLabel_2.Text = 'in imported data';
            app.toplotLabel_2 = uilabel(app.HorizontaldisplacementPanel);
            app.toplotLabel_2.Text = 'to plot';
            app.minEditField_4Label = uilabel(app.HorizontaldisplacementPanel);
            app.minEditField_4Label.Text = 'min';
            app.HorzOffsPlot_minEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffsPlot_minEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffsPlot_minEditField.ValueChangedFcn = createCallbackFcn(app, @HorzOffsPlot_minEditFieldValueChanged, true);
            app.maxEditField_4Label = uilabel(app.HorizontaldisplacementPanel);
            app.maxEditField_4Label.Text = 'max';
            app.HorzOffsPlot_maxEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffsPlot_maxEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffsPlot_maxEditField.ValueChangedFcn = createCallbackFcn(app, @HorzOffsPlot_maxEditFieldValueChanged, true);
            app.interpolationincrementEditFieldLabel = uilabel(app.HorizontaldisplacementPanel);
            app.interpolationincrementEditFieldLabel.Text = 'interpolation increment';
            app.HorzOffs_InterpolIncrEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffs_InterpolIncrEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffs_InterpolIncrEditField.Value = 0.25;
            app.scalefactorEditFieldLabel = uilabel(app.HorizontaldisplacementPanel);
            app.scalefactorEditFieldLabel.Text = 'scale factor';
            app.HorzOffsScalefactorEditField = uieditfield(app.HorizontaldisplacementPanel, 'numeric');
            app.HorzOffsScalefactorEditField.ValueDisplayFormat = '%5.2f';
            app.HorzOffsScalefactorEditField.Value = 1;
            app.ResultbinningPanel = uipanel(app.UIFigure);
            app.ResultbinningPanel.Title = 'Result binning';
            app.windowsizeEditFieldLabel = uilabel(app.ResultbinningPanel);
            app.windowsizeEditFieldLabel.Text = 'window size';
            app.BinningWindowsizeEditField = uieditfield(app.ResultbinningPanel, 'numeric');
            app.BinningWindowsizeEditField.Limits = [0 Inf];
            app.BinningWindowsizeEditField.ValueDisplayFormat = '%5.2f';
            app.stepsizeEditFieldLabel = uilabel(app.ResultbinningPanel);
            app.stepsizeEditFieldLabel.Text = 'step size';
            app.BinningStepsizeEditField = uieditfield(app.ResultbinningPanel, 'numeric');
            app.BinningStepsizeEditField.Limits = [0 Inf];
            app.BinningStepsizeEditField.ValueDisplayFormat = '%5.2f';
            app.VerticaldisplacementPanel = uipanel(app.UIFigure);
            app.VerticaldisplacementPanel.Title = 'Vertical displacement';
            app.minEditField_5Label = uilabel(app.VerticaldisplacementPanel);
            app.minEditField_5Label.Text = 'min';
            app.VertOffsData_minEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffsData_minEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffsData_minEditField.Editable = 'off';
            app.maxEditField_5Label = uilabel(app.VerticaldisplacementPanel);
            app.maxEditField_5Label.Text = 'max';
            app.VertOffsData_maxEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffsData_maxEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffsData_maxEditField.Editable = 'off';
            app.inimporteddataLabel_3 = uilabel(app.VerticaldisplacementPanel);
            app.inimporteddataLabel_3.Text = 'in imported data';
            app.toplotLabel_3 = uilabel(app.VerticaldisplacementPanel);
            app.toplotLabel_3.Text = 'to plot';
            app.minEditField_6Label = uilabel(app.VerticaldisplacementPanel);
            app.minEditField_6Label.Text = 'min';
            app.VertOffsPlot_minEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffsPlot_minEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffsPlot_minEditField.ValueChangedFcn = createCallbackFcn(app, @VertOffsPlot_minEditFieldValueChanged, true);
            app.maxEditField_6Label = uilabel(app.VerticaldisplacementPanel);
            app.maxEditField_6Label.Text = 'max';
            app.VertOffsPlot_maxEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffsPlot_maxEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffsPlot_maxEditField.ValueChangedFcn = createCallbackFcn(app, @VertOffsPlot_maxEditFieldValueChanged, true);
            app.interpolationincrementEditField_2Label = uilabel(app.VerticaldisplacementPanel);
            app.interpolationincrementEditField_2Label.Text = 'interpolation increment';
            app.VertOffs_InterpolIncrEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffs_InterpolIncrEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffs_InterpolIncrEditField.Value = 0.25;
            app.scalefactorEditField_2Label = uilabel(app.VerticaldisplacementPanel);
            app.scalefactorEditField_2Label.Text = 'scale factor';
            app.VertOffsScalefactorEditField = uieditfield(app.VerticaldisplacementPanel, 'numeric');
            app.VertOffsScalefactorEditField.ValueDisplayFormat = '%5.2f';
            app.VertOffsScalefactorEditField.Value = 1;
            app.PlotfeaturesbyratingPanel = uipanel(app.UIFigure);
            app.PlotfeaturesbyratingPanel.Title = 'Plot features by rating';
            app.high1CheckBox = uicheckbox(app.PlotfeaturesbyratingPanel);
            app.high1CheckBox.Text = 'high (1)';
            app.high1CheckBox.Value = true;
            app.moderatehigh2CheckBox = uicheckbox(app.PlotfeaturesbyratingPanel);
            app.moderatehigh2CheckBox.Text = 'moderate-high (2)';
            app.moderatehigh2CheckBox.Value = true;
            app.moderate3CheckBox = uicheckbox(app.PlotfeaturesbyratingPanel);
            app.moderate3CheckBox.Text = 'moderate (3)';
            app.moderate3CheckBox.Value = true;
            app.lowmoderate4CheckBox = uicheckbox(app.PlotfeaturesbyratingPanel);
            app.lowmoderate4CheckBox.Text = 'low-moderate (4)';
            app.lowmoderate4CheckBox.Value = true;
            app.low5CheckBox = uicheckbox(app.PlotfeaturesbyratingPanel);
            app.low5CheckBox.Text = 'low (5)';
            app.low5CheckBox.Value = true;
            app.LoadResultfilestxtButton = uibutton(app.UIFigure, 'push');
            app.LoadResultfilestxtButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultfilestxtButtonPushed, true);
            app.LoadResultfilestxtButton.Text = 'Load Result files (*.txt)';
            app.PlotalongdistanceButton = uibutton(app.UIFigure, 'push');
            app.PlotalongdistanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotalongdistanceButtonPushed, true);
            app.PlotalongdistanceButton.Text = 'Plot along distance';
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigEditField.Limits = [1 Inf];
            app.FigEditField.RoundFractionalValues = 'on';
            app.FigEditField.ValueDisplayFormat = '%d';
            app.FigEditField.Value = 2;
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.ScalewratingCheckBox = uicheckbox(app.UIFigure);
            app.ScalewratingCheckBox.Text = 'Scale w/ rating';
            app.holdonCheckBox = uicheckbox(app.UIFigure);
            app.holdonCheckBox.Text = 'hold on';
            app.RGBcolorLabel = uilabel(app.UIFigure);
            app.RGBcolorLabel.Text = 'R/G/B color';
            app.RGBcolorEditField = uieditfield(app.UIFigure, 'text');
            app.RGBcolorEditField.Value = '0.3 0.3 0.3';
            app.PatchtransparencyLabel = uilabel(app.UIFigure);
            app.PatchtransparencyLabel.Text = 'Patch transparency';
            app.PatchTransparencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatchTransparencyEditField.Limits = [0 1];
            app.PatchTransparencyEditField.ValueDisplayFormat = '%1.2f';
            app.PatchTransparencyEditField.Editable = 'off';
            app.PatchTransparencyEditField.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaDiCaoz_2v2_ShowResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end